classdef Interfaz_EEGEMG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RegresarButton                  matlab.ui.control.Button
        BCIEEGEMGUVGLabel               matlab.ui.control.Label
        PaneldeControl                  matlab.ui.container.Panel
        OndaaFiltrarDropDownLabel       matlab.ui.control.Label
        OndaaFiltrarDropDown            matlab.ui.control.DropDown
        TiempodemuestraatomarEditFieldLabel  matlab.ui.control.Label
        TiempodemuestraatomarEditField  matlab.ui.control.NumericEditField
        ConexinRobotPanel               matlab.ui.container.Panel
        ButtonsPanel                    matlab.ui.container.Panel
        STARTButton                     matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        LampGreen                       matlab.ui.control.Lamp
        LampRed                         matlab.ui.control.Lamp
        ParametersPanel                 matlab.ui.container.Panel
        SPEEDEditFieldLabel             matlab.ui.control.Label
        SPEEDEditField                  matlab.ui.control.NumericEditField
        COMDropDownLabel                matlab.ui.control.Label
        COMDropDown                     matlab.ui.control.DropDown
        IniciarButton                   matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        ConexinaDispositivosPanel       matlab.ui.container.Panel
        Lamp                            matlab.ui.control.Lamp
        ConectaraElectrocapSwitchLabel  matlab.ui.control.Label
        ConectaraElectrocapSwitch       matlab.ui.control.Switch
        Lamp_2                          matlab.ui.control.Lamp
        ConectarBitalinoSwitchLabel     matlab.ui.control.Label
        ConectarBitalinoSwitch          matlab.ui.control.Switch
        NombreBluetoothBitalinoEditFieldLabel  matlab.ui.control.Label
        NombreBluetoothBitalinoEditField  matlab.ui.control.EditField
        Lamp_3                          matlab.ui.control.Lamp
        EmpezarStreamingSwitchLabel     matlab.ui.control.Label
        EmpezarStreamingSwitch          matlab.ui.control.Switch
        EscogerEntrenamientoButton      matlab.ui.control.Button
        ThEMGSpinnerLabel               matlab.ui.control.Label
        ThEMGSpinner                    matlab.ui.control.Spinner
        ThHjorActiviySpinnerLabel       matlab.ui.control.Label
        ThHjorActiviySpinner            matlab.ui.control.Spinner
        Image                           matlab.ui.control.Image
        ClaseLabel                      matlab.ui.control.Label
        DesconectadoLampLabel           matlab.ui.control.Label
        DesconectadoLamp                matlab.ui.control.Lamp
        TabGroup                        matlab.ui.container.TabGroup
        GraficasCanalesTab              matlab.ui.container.Tab
        figFpz                          matlab.ui.control.UIAxes
        figFpz_2                        matlab.ui.control.UIAxes
        CaracteristicasSealesTab        matlab.ui.container.Tab
        HjorthActivityEditField_2Label  matlab.ui.control.Label
        HjorthActivityEditField_2       matlab.ui.control.NumericEditField
        HjorthComplexityEditField_2Label  matlab.ui.control.Label
        HjorthComplexityEditField_2     matlab.ui.control.NumericEditField
        HjorthMobilityEditField_2Label  matlab.ui.control.Label
        HjorthMobilityEditField_2       matlab.ui.control.NumericEditField
        NormalizedFirstDifferenceEditField_2Label  matlab.ui.control.Label
        NormalizedFirstDifferenceEditField_2  matlab.ui.control.NumericEditField
        KurtosisEditField_2Label        matlab.ui.control.Label
        KurtosisEditField_2             matlab.ui.control.NumericEditField
        MeanEnergyEditField_2Label      matlab.ui.control.Label
        MeanEnergyEditField_2           matlab.ui.control.NumericEditField
        SkewnessEditField_2Label        matlab.ui.control.Label
        SkewnessEditField_2             matlab.ui.control.NumericEditField
        ShannonEntropyEditField_2Label  matlab.ui.control.Label
        ShannonEntropyEditField_2       matlab.ui.control.NumericEditField
        PCALatentEditField_2Label       matlab.ui.control.Label
        PCALatentEditField_2            matlab.ui.control.NumericEditField
        PCAMuEditField_2Label           matlab.ui.control.Label
        PCAMuEditField_2                matlab.ui.control.NumericEditField
        CanalEEGLabel                   matlab.ui.control.Label
        ZeroCrossingEditFieldLabel      matlab.ui.control.Label
        ZeroCrossingEditField           matlab.ui.control.NumericEditField
        MAVEditFieldLabel               matlab.ui.control.Label
        MAVEditField                    matlab.ui.control.NumericEditField
        KurtossisEditFieldLabel         matlab.ui.control.Label
        KurtossisEditField              matlab.ui.control.NumericEditField
        AverageEnergyEditFieldLabel     matlab.ui.control.Label
        AverageEnergyEditField          matlab.ui.control.NumericEditField
        VarianzaEditFieldLabel          matlab.ui.control.Label
        VarianzaEditField               matlab.ui.control.NumericEditField
        WilsonAmplitudEditFieldLabel    matlab.ui.control.Label
        WilsonAmplitudEditField         matlab.ui.control.NumericEditField
        MediaCuadraticaEditFieldLabel   matlab.ui.control.Label
        MediaCuadraticaEditField        matlab.ui.control.NumericEditField
        CanalEMGLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        Bit=0 % Description
        flag_recibir1 =0
        flag_recibir2=0 % Description
        Onda_Low = 7;
        Onda_High = 14;
        T = table()
        Fs = 44100;
        y2 = audioread('GiroIzquierda.wav');
        y3 = audioread('InclinaDerecha.wav');
        y4 = audioread('PalmaArriba.wav');
        y5 = audioread('PuñoArriba.wav');
        A=0; 
        F_pb = filtro_pasa_banda(1000,20,450);    %Diseñar filtro pasa banda
        F_notch = filtro_rechaza_banda(1000);     %Diseñar filtro rechaza banda
        Pausa=0;
        
        data_n = zeros(1,5000)
        EMG_RAW=0;
        NombreBit
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Lamp.Enable = "off";
            app.Lamp_2.Enable = "off";
           
            app.Lamp_3.Enable = "off";
            app.LampGreen.Enable = 'off';
            app.LampRed.Enable = 'off';
            app.STOPButton.Enable = 'off';
            
            
            app.T.HjorthActivityEEG = zeros(0);
            app.T.NormalizedFirstDifferenceEEG = zeros(0);
            app.T.HjorthComplexityEEG = zeros(0);
            app.T.KurtosisEEG = zeros(0);
            app.T.MeanEnergyEEG = zeros(0);
            app.T.SkewnessEEG = zeros(0);
            app.T.ShannonEntropyEEG = zeros(0);
            app.T.HjorthMobilityEEG = zeros(0);
            app.T.PCALatentEEG = zeros(0);
            app.T.PCAMuEEG = zeros(0);
            app.T.ZCEMG = zeros(0);
            app.T.MAVEMG = zeros(0);
            app.T.KurtosisEMG = zeros(0);
            app.T.AverageEEMG = zeros(0);
            app.T.VarianzaEMG = zeros(0);
            app.T.AmplitudWilsonEMG = zeros(0);
            app.T.MediaCuadraticaEMG = zeros(0);


        end

        % Value changed function: ConectarBitalinoSwitch
        function ConectarBitalinoSwitchValueChanged(app, event)
            value = app.ConectarBitalinoSwitch.Value;
            app.Lamp.Enable = 'off';
            
            if strcmp( value,'On')
                app.NombreBit = app.NombreBluetoothBitalinoEditField.Value;
                app.Bit = Bitalino(char(app.NombreBit)); %Creamos la conexion con el dispositivo bitalino y esa conexion la volvemos un objeto.
                app.Lamp_2.Enable="on";
            else
                app.Lamp_2.Enable="off";
                app.flag_recibir1 =0;
                clear all
                
            end
        end

        % Value changed function: EmpezarStreamingSwitch
        function EmpezarStreamingSwitchValueChanged(app, event)
            value = app.EmpezarStreamingSwitch.Value;
            if strcmp( value,'On')
                app.flag_recibir2 =1;
                startBackground(app.Bit)
                app.Lamp_3.Enable="on";
            else
                app.flag_recibir2 =0;
                stopBackground(app.Bit)
                app.Lamp_3.Enable="off";
            end
        end

        % Value changed function: ConectaraElectrocapSwitch
        function ConectaraElectrocapSwitchValueChanged(app, event)
            value = app.ConectaraElectrocapSwitch.Value;
            
            if strcmp( value,'On')
                app.flag_recibir1 =1;
                app.Lamp.Enable = "on";
            else
                app.flag_recibir1 = 0;
                app.Lamp.Enable = "off";
            end
        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            %%  Establecer la longitud de las epocas que se desea e inicializacion de variables


            app.Pausa = 0;
            Epocas_Length = app.TiempodemuestraatomarEditField.Value; % Duracion en segundos de cada epoca
            Canales = 8;        % Numero de canales que se desea usar (1 a 8)
            itr = 1;
            t=1:1000;
            vproyecto = zeros(1000,8);
            i = 1;
            Banderita=0;
            
            ctrl2 = 0;
            fs =250;
            M = 2;
            %% Se definen los parametros para los filtros
            fs_Hz = 250;
            little_buff = zeros(1,10);
            display_buff = zeros(1,5000);
            cont = 1;
            
            bpf = [app.Onda_Low, app.Onda_High];
            [b,a] = butter(2,bpf/(fs_Hz / 2), 'bandpass');
            
            notch = [59, 61];
            [b2, a2] = butter(2,notch/(fs_Hz / 2), 'stop');
            
            %%  Reservar memoria para vector de datos raw y para filtrar los datos
            Col = 8;
            Row = 50000000;
            Offset = zeros(1,8);
            Live_Data1 = zeros(Row,Col);
            Live_Data_test = [];
            
            Live_Data_test2 = zeros(Epocas_Length*250,8);
            Fixed_Vector = zeros(Epocas_Length*250,8);
            Full_Fill_Vec = zeros(2*Epocas_Length*250,8);
            Temp_Raw_Vector = zeros(2*Epocas_Length*100,8);
            index1 = 1;
            cont = 1;
            cont2 = Epocas_Length*250;
            cont3 = Epocas_Length*250;
            tiempos = zeros(Epocas_Length*250,1);
            tiempos2 = zeros(Epocas_Length*1000,1);
            
            mitimer  = tic;
            n = 1;
            Epocas = Epocas_Length*250  ; %se toma el tamano del buffer obtenido
            L = Epocas; %longitud del vector epocas
            t=linspace(0, Epocas/250, L);
            
            Epocas2 = Epocas_Length*1000;
            L2 = Epocas2;
            t2 = linspace(0,Epocas2/1000,L2);
            %% instantiate the library
            disp('Loading the library...');
            lib = lsl_loadlib();
            % resolve a stream...
            disp('Resolving an EEG stream...');
            result = {};
            while isempty(result)
                result = lsl_resolve_byprop(lib,'type','EEG');
            end
            
            % create a new inlet
            disp('Opening an inlet...');
            inlet = lsl_inlet(result{1});
            disp('Now receiving data...');
            
            h1= plot(app.figFpz,  t, tiempos)
            title('Ch1')
            ylabel('uV')
            xlabel('tiempo (s)')
            
            h2= plot(app.figFpz_2,  t2, tiempos2)
            title('Ch1')
            ylabel('uV')
            xlabel('tiempo (s)')
            
            FactorEscalador = (4500000)/24/(2^23-1);
            %app.Bit.startBackground
            while (app.flag_recibir1 ==1)&&(app.flag_recibir2 ==1)
                
                [vec,ts] = inlet.pull_sample();                         % Se realiza la lectura de los 8 canales
                tiempos(n) = toc(mitimer);
                mitimer  = tic;
                
                Live_Data_test2(index1,:) =  (vec)*FactorEscalador;                       % Se ingresa los datos leidos a un vector temporal
                
                %     h1.YData(i) = (vec(1, 1))/1000;                       %Actualizar gráfica
                %
                %     drawnow limitrate
                n = n +1;
                
                i=i+1;
                if(mod(i,Epocas_Length*250)==0)                       % Cada vez que pase cierta cantidad de tiempo se hace la separacion de epocas
                    i=1;
                    data = read(app.Bit);% tomamos los ultimos valores en el buffer.\
                    assignin('base', 'matrizData', data);
                    if (min(Live_Data_test2)==0)
                        [M,I] = find(Live_Data_test2 == 0);
                        Live_Data_test2(M,I) = Offset;
                        fprintf('/');
                    end
                    Fixed_Vector = (Live_Data_test2 - (Offset));
                    display_buff_filt = filter(b2,a2,Fixed_Vector);  % Se realiza el friltrado del los datos
                    display_buff_filt = filter(b,a,display_buff_filt);
                    Offset = [mean(Live_Data_test2(:,1)),mean(Live_Data_test2(:,2)),mean(Live_Data_test2(:,3)),mean(Live_Data_test2(:,4)),mean(Live_Data_test2(:,5)),mean(Live_Data_test2(:,6)),mean(Live_Data_test2(:,7)),mean(Live_Data_test2(:,8))];
                    Full_Fill_Vec(cont:cont3 ,:) = Live_Data_test2;     % Se almacenan los datos de cada epoca de los valores en bruto
                    Temp_Raw_Vector(cont:cont3,:) = display_buff_filt;  % Se almacenan los datos de cada epoca de los valores filtrados
                    cont = cont + cont2;                                % Contador para la primera posicion de la proxima epoca a guardar
                    cont3 = cont3 + cont2;                              % Contador de la ultima posicion de la proxima epoca a guardar
                    index1 = 1;
                    flag = true;                                        % cuando se cumpla esta condicion se levanta una bandera para poder sacarle las caracterisiticas a dicha epoca
                    
                    %n=n+1%contador para ingresar los datos a la tabla.
                    app.EMG_RAW = data(:,6); %se toma solo la columna de la senal EMG
                    assignin('base', 'EMGRaw', app.EMG_RAW);
                    EMG = app.EMG_RAW*(3.3/1024);%se convierte la senal a la escala de 3.3 V
                    assignin('base', 'data_nI', app.EMG_RAW);
                    data_f = filter(app.F_pb, EMG');                          %Aplicar filtro pasa bandas
                    assignin('base', 'data_filter', data_f);
                    app.data_n = filter(app.F_notch, data_f);                       %Aplicar filtro notch
                    assignin('base', 'data_nI', app.data_n);
                   
                    wt = cwt(app.data_n(300:end));
                    Actividad = max(max(abs(wt)));
                else
                    flag = false;
                    index1 = index1 + 1;
                end


                if flag == true
                    Banderita=Banderita+1;
                    %Extraer caracteristicas
                    Chn1 = display_buff_filt(: , 1);
                    ylim(app.figFpz_2, [min(app.data_n) max(app.data_n)]);
                    Epocas2 = size(app.data_n,2) ; %se toma el tamano del buffer obtenido
                    L2 = Epocas2; %longitud del vector epocas
                    
                    t2=linspace(0, Epocas2/1000, L2);
                    plot(app.figFpz_2, t2, app.data_n, 'Color','r') %se plotea esa muestra
                    
                    %                     plot( app.figFpz_2,t2, app.data_n)
                    %                     ylim( app.figFpz_2, [min(app.data_n) max(app.data_n)]);
                    
                    
                    plot( app.figFpz,t, Chn1)
                    ylim( app.figFpz, [min(Chn1) max(Chn1)]);
                    
                    drawnow limitrate
                    
                    
                    app.HjorthActivityEditField_2.Value = jHjorthActivity(Chn1);
                    ThActivity= jHjorthActivity(Chn1);
                    app.NormalizedFirstDifferenceEditField_2.Value = jNormalizedFirstDifference(Chn1);
                    app.HjorthComplexityEditField_2.Value = jHjorthComplexity(Chn1);
                    app.KurtosisEditField_2.Value = jKurtosis(Chn1);
                    app.MeanEnergyEditField_2.Value = jMeanTeagerEnergy(Chn1);
                    app.SkewnessEditField_2.Value = jSkewness(Chn1);
                    app.ShannonEntropyEditField_2.Value = jShannonEntropy(Chn1);
                    app.HjorthMobilityEditField_2.Value = jHjorthMobility(Chn1);
                    [~,~,latent,~,~,mu] = pca(Chn1);
                    app.PCALatentEditField_2.Value = latent;
                    app.PCAMuEditField_2.Value = mu;
                    % Caracteristicas EMG
                    app.ZeroCrossingEditField.Value = jZeroCrossing(app.data_n,0.1);
                    app.MAVEditField.Value = jMeanAbsoluteDeviation(app.data_n);
                    app.KurtossisEditField.Value = jKurtosis(app.data_n);
                    app.AverageEnergyEditField.Value = jAverageEnergy(app.data_n);
                    app.VarianzaEditField.Value = jVariance(app.data_n);
                    app.WilsonAmplitudEditField.Value = jWillisonAmplitude(app.data_n,0.01);
                    app.MediaCuadraticaEditField.Value = jRootMeanSquare(app.data_n);
                    drawnow limitrate
                    
                    if (ThActivity > app.ThHjorActiviySpinner.Value) || (Actividad > app.ThEMGSpinner.Value)  &&  (Activity == 0)
                      Activity =1;
                    end
                    
%                     if (Actividad > Th)  &&  (Activity == 0)
%                         Activity =1;
%                     end
                    
                    if (app.Pausa == 0) && (Activity == 1)
                        Activity =0;


                        %                         Data.AverageE(1) = jAverageEnergy(app.data_n);
                        %                         Data.Varianza(1) = jVariance(app.data_n);
                        %                         Data.AmplitudWilson(1) = jWillisonAmplitude(app.data_n,0.01);
                        %                         Data.MediaCuadratica(1) = jRootMeanSquare(app.data_n);
                        %
                        
                        app.T.HjorthActivityEEG(1) =jHjorthActivity(Chn1);
                        app.T.NormalizedFirstDifferenceEEG(1) = jNormalizedFirstDifference(Chn1);
                        app.T.HjorthComplexityEEG(1) = jHjorthComplexity(Chn1);
                        app.T.KurtosisEEG(1) = jKurtosis(Chn1);
                        app.T.MeanEnergyEEG(1) = jMeanTeagerEnergy(Chn1);
                        app.T.SkewnessEEG(1) = jSkewness(Chn1);
                        app.T.ShannonEntropyEEG(1) = jShannonEntropy(Chn1);
                        app.T.HjorthMobilityEEG(1) = jHjorthMobility(Chn1);
                        [~,~,latent,~,~,mu] = pca(Chn1);
                        app.T.PCALatentEEG(1) = latent;
                        app.T.PCAMuEEG(1) = mu;
                        app.T.ZCEMG(1) = jZeroCrossing(app.data_n,0.1);
                        app.T.MAVEMG(1) = jMeanAbsoluteDeviation(app.data_n);
                        app.T.KurtosisEMG(1) = jKurtosis(app.data_n);
                        app.T.AverageEEMG(1) = jAverageEnergy(app.data_n);
                        app.T.VarianzaEMG(1) = jVariance(app.data_n);
                        app.T.AmplitudWilsonEMG(1) = jWillisonAmplitude(app.data_n,0.01);;
                        app.T.MediaCuadraticaEMG(1) =  jRootMeanSquare(app.data_n);
                        
                        yfit = app.A.predictFcn(app.T)
                        switch yfit
                            case 'HeadRight'
                                app.Image.ImageSource = imread("Cabeza_Derecha.png");
                                drawnow limitrate
                                soundsc(app.y3,app.Fs);
                                %                                 ContGiro = ContGiro + 1;
                                %                                 if ContGiro == 17
                                %                                     ContGiro =1 ;
                                %                                 end
                                %                                 giro = Array_giro(ContGiro) ;
                                %                                 q2 = [Metros; deg2rad(giro);0; 0;0; 0];
                                %                                 R17fKine(R17, q2);
                                %
                                %                                 app.ClaseLabel.Text = "WristUp"
                                
                            case 'Head-Left'
                                app.Image.ImageSource = imread("HeadTurnLeft.jpg");
                                drawnow limitrate
                                soundsc(app.y2,app.Fs);
                                %                                 ContMetros = ContMetros +1;
                                %                                 if ContMetros == 12
                                %                                     ContMetros =1;
                                %                                 end
                                %                                 Metros = Metros_CAREER(ContMetros);
                                %                                 q2 = [Metros; deg2rad(giro);0; 0; 0; 0];
                                %                                 R17fKine(R17, q2);
                                %                                 app.ClaseLabel.Text = "HandUp"
                            case 'Head-Right'
                                app.Image.ImageSource = imread("Cabeza_Derecha.png");
                                drawnow limitrate
                                soundsc(app.y3,app.Fs);
                                %                                 ContGiro = ContGiro + 1;
                                %                                 if ContGiro == 17
                                %                                     ContGiro =1 ;
                                %                                 end
                                %                                 giro = Array_giro(ContGiro) ;
                                %                                 q2 = [Metros; deg2rad(giro);0; 0;0; 0];
                                %                                 R17fKine(R17, q2);
                                %
                                %                                 app.ClaseLabel.Text = "WristUp"
                                
                            case 'HeadTurnLeft'
                                app.Image.ImageSource = imread("HeadTurnLeft.jpg");
                                drawnow limitrate
                                soundsc(app.y2,app.Fs);
                                %                                 ContMetros = ContMetros +1;
                                %                                 if ContMetros == 12
                                %                                     ContMetros =1;
                                %                                 end
                                %                                 Metros = Metros_CAREER(ContMetros);
                                %                                 q2 = [Metros; deg2rad(giro);0; 0; 0; 0];
                                %                                 R17fKine(R17, q2);
                                %                                 app.ClaseLabel.Text = "HandUp"
                            case 'HandUp'
                                app.Image.ImageSource = imread("manita.jfif");
                                drawnow limitrate
                                soundsc(app.y4,app.Fs);
                                %                                 ContMetros = ContMetros +1;
                                %                                 if ContMetros == 12
                                %                                     ContMetros =1;
                                %                                 end
                                %                                 Metros = Metros_CAREER(ContMetros);
                                %                                 q2 = [Metros; deg2rad(giro);0; 0; 0; 0];
                                %                                 R17fKine(R17, q2);
                                %                                 app.ClaseLabel.Text = "HandUp"
                            case 'WristUp'
                                app.Image.ImageSource = imread("WristUp.jpg");
                                drawnow limitrate
                                soundsc(app.y5,app.Fs);
                                %                                 ContMetros = ContMetros +1;
                                %                                 if ContMetros == 12
                                %                                     ContMetros =1;
                                %                                 end
                                %                                 Metros = Metros_CAREER(ContMetros);
                                %                                 q2 = [Metros; deg2rad(giro);0; 0; 0; 0];
                                %                                 R17fKine(R17, q2);
                                %                                 app.ClaseLabel.Text = "HandUp"
                                
                        end


                    end


                end
            end
        end

        % Button pushed function: EscogerEntrenamientoButton
        function EscogerEntrenamientoButtonPushed(app, event)
            [file,folder]=uigetfile('*.mat');
            filename=fullfile(folder,file);
            s=load(filename);
            C= struct2cell(s);
            app.A= cell2mat(C);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RegresarButton = uibutton(app.UIFigure, 'push');
            app.RegresarButton.Icon = 'back.png';
            app.RegresarButton.Text = 'Regresar';
            app.BCIEEGEMGUVGLabel = uilabel(app.UIFigure);
            app.BCIEEGEMGUVGLabel.Text = 'BCI EEG EMG - UVG';
            app.PaneldeControl = uipanel(app.UIFigure);
            app.PaneldeControl.Title = 'Panel de Control';
            app.OndaaFiltrarDropDownLabel = uilabel(app.PaneldeControl);
            app.OndaaFiltrarDropDownLabel.Text = 'Onda a Filtrar';
            app.OndaaFiltrarDropDown = uidropdown(app.PaneldeControl);
            app.OndaaFiltrarDropDown.Items = {'-Seleccionar-', 'Beta', 'Alpha', 'Theta', 'Delta'};
            app.OndaaFiltrarDropDown.Value = '-Seleccionar-';
            app.TiempodemuestraatomarEditFieldLabel = uilabel(app.PaneldeControl);
            app.TiempodemuestraatomarEditFieldLabel.Text = 'Tiempo de muestra a tomar';
            app.TiempodemuestraatomarEditField = uieditfield(app.PaneldeControl, 'numeric');
            app.TiempodemuestraatomarEditField.Limits = [0 25];
            app.TiempodemuestraatomarEditField.RoundFractionalValues = 'on';
            app.TiempodemuestraatomarEditField.Value = 5;
            app.ConexinRobotPanel = uipanel(app.PaneldeControl);
            app.ConexinRobotPanel.Title = 'Conexión Robot';
            app.ButtonsPanel = uipanel(app.ConexinRobotPanel);
            app.ButtonsPanel.Title = 'Buttons Panel';
            app.STARTButton = uibutton(app.ButtonsPanel, 'push');
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.ButtonsPanel, 'push');
            app.STOPButton.Text = 'STOP';
            app.LampGreen = uilamp(app.ButtonsPanel);
            app.LampRed = uilamp(app.ButtonsPanel);
            app.ParametersPanel = uipanel(app.ConexinRobotPanel);
            app.ParametersPanel.Title = 'Parameters Panel';
            app.SPEEDEditFieldLabel = uilabel(app.ParametersPanel);
            app.SPEEDEditFieldLabel.Text = 'SPEED';
            app.SPEEDEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SPEEDEditField.Limits = [5000 25000];
            app.SPEEDEditField.RoundFractionalValues = 'on';
            app.SPEEDEditField.Value = 10000;
            app.COMDropDownLabel = uilabel(app.ParametersPanel);
            app.COMDropDownLabel.Text = 'COM';
            app.COMDropDown = uidropdown(app.ParametersPanel);
            app.COMDropDown.Items = {'-Seleccionar-', 'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9'};
            app.COMDropDown.Value = '-Seleccionar-';
            app.IniciarButton = uibutton(app.PaneldeControl, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar ';
            app.Button = uibutton(app.PaneldeControl, 'push');
            app.Button.Icon = 'BotonPausa.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.PaneldeControl, 'push');
            app.Button_2.Icon = 'play.png';
            app.Button_2.Text = '';
            app.ConexinaDispositivosPanel = uipanel(app.PaneldeControl);
            app.ConexinaDispositivosPanel.Title = 'Conexión a Dispositivos';
            app.Lamp = uilamp(app.ConexinaDispositivosPanel);
            app.ConectaraElectrocapSwitchLabel = uilabel(app.ConexinaDispositivosPanel);
            app.ConectaraElectrocapSwitchLabel.Text = 'Conectar a Electrocap';
            app.ConectaraElectrocapSwitch = uiswitch(app.ConexinaDispositivosPanel, 'slider');
            app.ConectaraElectrocapSwitch.ValueChangedFcn = createCallbackFcn(app, @ConectaraElectrocapSwitchValueChanged, true);
            app.Lamp_2 = uilamp(app.ConexinaDispositivosPanel);
            app.ConectarBitalinoSwitchLabel = uilabel(app.ConexinaDispositivosPanel);
            app.ConectarBitalinoSwitchLabel.Text = 'Conectar Bitalino';
            app.ConectarBitalinoSwitch = uiswitch(app.ConexinaDispositivosPanel, 'slider');
            app.ConectarBitalinoSwitch.ValueChangedFcn = createCallbackFcn(app, @ConectarBitalinoSwitchValueChanged, true);
            app.NombreBluetoothBitalinoEditFieldLabel = uilabel(app.ConexinaDispositivosPanel);
            app.NombreBluetoothBitalinoEditFieldLabel.Text = 'Nombre Bluetooth Bitalino';
            app.NombreBluetoothBitalinoEditField = uieditfield(app.ConexinaDispositivosPanel, 'text');
            app.NombreBluetoothBitalinoEditField.Value = 'BITalino-00-97';
            app.Lamp_3 = uilamp(app.ConexinaDispositivosPanel);
            app.EmpezarStreamingSwitchLabel = uilabel(app.ConexinaDispositivosPanel);
            app.EmpezarStreamingSwitchLabel.Text = 'Empezar Streaming';
            app.EmpezarStreamingSwitch = uiswitch(app.ConexinaDispositivosPanel, 'slider');
            app.EmpezarStreamingSwitch.ValueChangedFcn = createCallbackFcn(app, @EmpezarStreamingSwitchValueChanged, true);
            app.EscogerEntrenamientoButton = uibutton(app.PaneldeControl, 'push');
            app.EscogerEntrenamientoButton.ButtonPushedFcn = createCallbackFcn(app, @EscogerEntrenamientoButtonPushed, true);
            app.EscogerEntrenamientoButton.Text = 'Escoger Entrenamiento';
            app.ThEMGSpinnerLabel = uilabel(app.PaneldeControl);
            app.ThEMGSpinnerLabel.Text = 'ThEMG';
            app.ThEMGSpinner = uispinner(app.PaneldeControl);
            app.ThEMGSpinner.Step = 0.01;
            app.ThEMGSpinner.Limits = [0 0.5];
            app.ThEMGSpinner.Value = 0.2;
            app.ThHjorActiviySpinnerLabel = uilabel(app.PaneldeControl);
            app.ThHjorActiviySpinnerLabel.Text = 'Th HjorActiviy';
            app.ThHjorActiviySpinner = uispinner(app.PaneldeControl);
            app.ThHjorActiviySpinner.Step = 0.1;
            app.ThHjorActiviySpinner.Limits = [0 5];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Cabeza_Derecha.png';
            app.ClaseLabel = uilabel(app.UIFigure);
            app.ClaseLabel.Text = 'Clase';
            app.DesconectadoLampLabel = uilabel(app.UIFigure);
            app.DesconectadoLampLabel.Enable = 'off';
            app.DesconectadoLampLabel.Text = 'Desconectado';
            app.DesconectadoLamp = uilamp(app.UIFigure);
            app.DesconectadoLamp.Enable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GraficasCanalesTab = uitab(app.TabGroup);
            app.GraficasCanalesTab.Title = 'Graficas Canales';
            app.figFpz = uiaxes(app.GraficasCanalesTab);
            title(app.figFpz, {'Canal EEG '; ''})
            xlabel(app.figFpz, 't (s)')
            ylabel(app.figFpz, 'uV')
            app.figFpz_2 = uiaxes(app.GraficasCanalesTab);
            title(app.figFpz_2, {'Canal EMG '; ''})
            xlabel(app.figFpz_2, 't (s)')
            ylabel(app.figFpz_2, 'uV')
            app.CaracteristicasSealesTab = uitab(app.TabGroup);
            app.CaracteristicasSealesTab.Title = 'Caracteristicas Señales';
            app.HjorthActivityEditField_2Label = uilabel(app.CaracteristicasSealesTab);
            app.HjorthActivityEditField_2Label.Text = 'HjorthActivity ';
            app.HjorthActivityEditField_2 = uieditfield(app.CaracteristicasSealesTab, 'numeric');
            app.HjorthComplexityEditField_2Label = uilabel(app.CaracteristicasSealesTab);
            app.HjorthComplexityEditField_2Label.Text = 'HjorthComplexity';
            app.HjorthComplexityEditField_2 = uieditfield(app.CaracteristicasSealesTab, 'numeric');
            app.HjorthMobilityEditField_2Label = uilabel(app.CaracteristicasSealesTab);
            app.HjorthMobilityEditField_2Label.Text = 'HjorthMobility';
            app.HjorthMobilityEditField_2 = uieditfield(app.CaracteristicasSealesTab, 'numeric');
            app.NormalizedFirstDifferenceEditField_2Label = uilabel(app.CaracteristicasSealesTab);
            app.NormalizedFirstDifferenceEditField_2Label.Text = 'NormalizedFirstDifference';
            app.NormalizedFirstDifferenceEditField_2 = uieditfield(app.CaracteristicasSealesTab, 'numeric');
            app.KurtosisEditField_2Label = uilabel(app.CaracteristicasSealesTab);
            app.KurtosisEditField_2Label.Text = 'Kurtosis';
            app.KurtosisEditField_2 = uieditfield(app.CaracteristicasSealesTab, 'numeric');
            app.MeanEnergyEditField_2Label = uilabel(app.CaracteristicasSealesTab);
            app.MeanEnergyEditField_2Label.Text = 'MeanEnergy';
            app.MeanEnergyEditField_2 = uieditfield(app.CaracteristicasSealesTab, 'numeric');
            app.SkewnessEditField_2Label = uilabel(app.CaracteristicasSealesTab);
            app.SkewnessEditField_2Label.Text = 'Skewness';
            app.SkewnessEditField_2 = uieditfield(app.CaracteristicasSealesTab, 'numeric');
            app.ShannonEntropyEditField_2Label = uilabel(app.CaracteristicasSealesTab);
            app.ShannonEntropyEditField_2Label.Text = 'ShannonEntropy';
            app.ShannonEntropyEditField_2 = uieditfield(app.CaracteristicasSealesTab, 'numeric');
            app.PCALatentEditField_2Label = uilabel(app.CaracteristicasSealesTab);
            app.PCALatentEditField_2Label.Text = 'PCALatent';
            app.PCALatentEditField_2 = uieditfield(app.CaracteristicasSealesTab, 'numeric');
            app.PCAMuEditField_2Label = uilabel(app.CaracteristicasSealesTab);
            app.PCAMuEditField_2Label.Text = 'PCAMu';
            app.PCAMuEditField_2 = uieditfield(app.CaracteristicasSealesTab, 'numeric');
            app.CanalEEGLabel = uilabel(app.CaracteristicasSealesTab);
            app.CanalEEGLabel.Text = 'Canal EEG';
            app.ZeroCrossingEditFieldLabel = uilabel(app.CaracteristicasSealesTab);
            app.ZeroCrossingEditFieldLabel.Text = 'Zero Crossing';
            app.ZeroCrossingEditField = uieditfield(app.CaracteristicasSealesTab, 'numeric');
            app.MAVEditFieldLabel = uilabel(app.CaracteristicasSealesTab);
            app.MAVEditFieldLabel.Text = 'MAV';
            app.MAVEditField = uieditfield(app.CaracteristicasSealesTab, 'numeric');
            app.KurtossisEditFieldLabel = uilabel(app.CaracteristicasSealesTab);
            app.KurtossisEditFieldLabel.Text = 'Kurtossis';
            app.KurtossisEditField = uieditfield(app.CaracteristicasSealesTab, 'numeric');
            app.AverageEnergyEditFieldLabel = uilabel(app.CaracteristicasSealesTab);
            app.AverageEnergyEditFieldLabel.Text = 'Average Energy';
            app.AverageEnergyEditField = uieditfield(app.CaracteristicasSealesTab, 'numeric');
            app.VarianzaEditFieldLabel = uilabel(app.CaracteristicasSealesTab);
            app.VarianzaEditFieldLabel.Text = 'Varianza';
            app.VarianzaEditField = uieditfield(app.CaracteristicasSealesTab, 'numeric');
            app.WilsonAmplitudEditFieldLabel = uilabel(app.CaracteristicasSealesTab);
            app.WilsonAmplitudEditFieldLabel.Text = 'Wilson Amplitud';
            app.WilsonAmplitudEditField = uieditfield(app.CaracteristicasSealesTab, 'numeric');
            app.MediaCuadraticaEditFieldLabel = uilabel(app.CaracteristicasSealesTab);
            app.MediaCuadraticaEditFieldLabel.Text = 'Media Cuadratica';
            app.MediaCuadraticaEditField = uieditfield(app.CaracteristicasSealesTab, 'numeric');
            app.CanalEMGLabel = uilabel(app.CaracteristicasSealesTab);
            app.CanalEMGLabel.Text = 'Canal EMG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_EEGEMG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end