classdef RecoleccionDeDatosEEGEMG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        RecoleccindeDatosEEGyEMGUVGLabel  matlab.ui.control.Label
        RegresarButton                  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        InstruccionesTab                matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        InstruccionesparaelusodelaInterfazLabel  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        PaneldecontrolTab               matlab.ui.container.Tab
        CaracteristicasSeleccionadasPanel  matlab.ui.container.Panel
        HjorthActivityCheckBox          matlab.ui.control.CheckBox
        NormalizedFirstDifferenceCheckBox  matlab.ui.control.CheckBox
        HjorthComplexityCheckBox        matlab.ui.control.CheckBox
        KurtosisCheckBox                matlab.ui.control.CheckBox
        MeanEnergyCheckBox              matlab.ui.control.CheckBox
        CrearTablaButton                matlab.ui.control.Button
        ConectaraElectrocapSwitchLabel  matlab.ui.control.Label
        ConectaraElectrocapSwitch       matlab.ui.control.Switch
        SkewnessCheckBox                matlab.ui.control.CheckBox
        ShannonEntropyCheckBox          matlab.ui.control.CheckBox
        HjorthMobilityCheckBox          matlab.ui.control.CheckBox
        SeleccionartodoCheckBox         matlab.ui.control.CheckBox
        PCACheckBox                     matlab.ui.control.CheckBox
        ZeroCrossingCheckBox            matlab.ui.control.CheckBox
        MAVCheckBox                     matlab.ui.control.CheckBox
        KurtosisCheckBox_2              matlab.ui.control.CheckBox
        PromediodeEnergaCheckBox        matlab.ui.control.CheckBox
        VarianzaCheckBox                matlab.ui.control.CheckBox
        AmplituddeWilsonCheckBox        matlab.ui.control.CheckBox
        MediaCuadraticaCheckBox         matlab.ui.control.CheckBox
        CaractersticassealesEEGLabel    matlab.ui.control.Label
        CaractersticassealesEMGLabel    matlab.ui.control.Label
        ConectarBitalinoSwitchLabel     matlab.ui.control.Label
        ConectarBitalinoSwitch          matlab.ui.control.Switch
        EmpezarStreamingSwitchLabel     matlab.ui.control.Label
        EmpezarStreamingSwitch          matlab.ui.control.Switch
        Lamp                            matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        NombreBluetoothBitalinoEditFieldLabel  matlab.ui.control.Label
        NombreBluetoothBitalinoEditField  matlab.ui.control.EditField
        GrabacinPanel                   matlab.ui.container.Panel
        GuardarArchivoButton            matlab.ui.control.Button
        TiempodemuestraatomarEditFieldLabel  matlab.ui.control.Label
        TiempodemuestraatomarEditField  matlab.ui.control.NumericEditField
        NombreDelArchivoEditFieldLabel  matlab.ui.control.Label
        NombreDelArchivoEditField       matlab.ui.control.EditField
        ClaseagrabarDropDownLabel       matlab.ui.control.Label
        ClaseagrabarDropDown            matlab.ui.control.DropDown
        EmpezaraGrabarButton            matlab.ui.control.Button
        NmerodemuestrastomadasLabel     matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        PausaReiniciarGrabacinLabel     matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        OndaEEGaFiltrarDropDownLabel    matlab.ui.control.Label
        OndaEEGaFiltrarDropDown         matlab.ui.control.DropDown
        LarecoleccinestenPausaLabel     matlab.ui.control.Label
        figFpz                          matlab.ui.control.UIAxes
        figFpz_2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        flag_recibir1 =0;
        flag_recibir2 =0;
        Bit = 0;
        F_pb = filtro_pasa_banda(1000,20,450);    %Diseñar filtro pasa banda
        F_notch = filtro_rechaza_banda(1000);     %Diseñar filtro rechaza banda
        Onda_Low = 7;
        Onda_High = 14;
        DataEnBrutoEEG = cell(0,0)
        DataEnBrutoEMG = cell(0,0)
        T = table()
        Clase_Value=0;
        Pausa=0;
        ctrl=0;
        data_n = zeros(1,5000)
        EMG_RAW=0;
        NombreBit
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Lamp.Enable = "off";
            app.Lamp_2.Enable = "off";
            app.Lamp_4.Enable = "off";
            
        end

        % Size changed function: CaracteristicasSeleccionadasPanel
        function CaracteristicasSeleccionadasPanelSizeChanged(app, event)
            position = app.CaracteristicasSeleccionadasPanel.Position;
            
        end

        % Value changed function: ConectaraElectrocapSwitch
        function ConectaraElectrocapSwitchValueChanged(app, event)
            value = app.ConectaraElectrocapSwitch.Value;
            if strcmp( value,'On')
                
                
                app.flag_recibir1 = 1;
                
                app.Lamp.Enable = "on";
                
                
            else
                
                app.flag_recibir1 = 0;
                
                app.Lamp.Enable = "off";
                
            end
        end

        % Value changed function: ConectarBitalinoSwitch
        function ConectarBitalinoSwitchValueChanged(app, event)
            
            value = app.ConectarBitalinoSwitch.Value;
            
            app.Lamp.Enable = 'off';
            
            if strcmp( value,'On')
                app.NombreBit = app.NombreBluetoothBitalinoEditField.Value
                
                app.Bit = Bitalino(char(app.NombreBit)); %Creamos la conexion con el dispositivo bitalino y esa conexion la volvemos un objeto.


                app.Lamp_2.Enable="on"
                
                
            else
                
                app.Lamp_2.Enable="off"
                app.flag_recibir1 =0;
                clear all
                delete(app.Bit)
                
            end
        end

        % Value changed function: EmpezarStreamingSwitch
        function EmpezarStreamingSwitchValueChanged(app, event)
            value = app.EmpezarStreamingSwitch.Value;
            if strcmp( value,'On')
                
                app.flag_recibir2 = 1;
                 startBackground(app.Bit)
                app.Lamp_4.Enable="on"
              
                
            else
                app.flag_recibir2 =0;
                stopBackground(app.Bit)
                app.Lamp_4.Enable="off"
            end
            
        end

        % Button pushed function: CrearTablaButton
        function CrearTablaButtonPushed(app, event)
            app.ctrl = 0;
            app.DataEnBrutoEEG = cell(0,0);
            app.DataEnBrutoEMG = cell(0,0);
            app.T = table();
            if app.HjorthActivityCheckBox.Value == 1
                app.T.HjorthActivityEEG = zeros(0)
            end
            if app.NormalizedFirstDifferenceCheckBox.Value == 1
                app.T.NormalizedFirstDifferenceEEG = zeros(0)
            end
            if app.HjorthComplexityCheckBox.Value == 1
                app.T.HjorthComplexityEEG = zeros(0)
            end
            
            if app.KurtosisCheckBox.Value == 1
                app.T.KurtosisEEG = zeros(0)
            end
            
            if app.MeanEnergyCheckBox.Value == 1
                app.T.MeanEnergyEEG = zeros(0)
            end
            if app.SkewnessCheckBox.Value == 1
                app.T.SkewnessEEG = zeros(0)
            end
            if app.ShannonEntropyCheckBox.Value == 1
                app.T.ShannonEntropyEEG = zeros(0)
            end
            if app.HjorthMobilityCheckBox.Value == 1
                app.T.HjorthMobilityEEG = zeros(0)
            end
            if app.PCACheckBox.Value == 1
                %coeff,score,latent,tsquared,explained,mu
                
                app.T.PCALatentEEG = zeros(0);
                app.T.PCAMuEEG = zeros(0);
            end
            if app.ZeroCrossingCheckBox.Value == 1
                app.T.ZCEMG = zeros(0)
            end
            if app.MAVCheckBox.Value == 1
                app.T.MAVEMG = zeros(0)
            end
            if app.KurtosisCheckBox.Value == 1
                app.T.KurtosisEMG = zeros(0)
            end
            if app.PromediodeEnergaCheckBox.Value == 1
                app.T.AverageEEMG = zeros(0)
            end
            
            if app.VarianzaCheckBox.Value == 1
                app.T.VarianzaEMG = zeros(0)
            end
            if app.AmplituddeWilsonCheckBox.Value == 1
                app.T.AmplitudWilsonEMG = zeros(0)
            end
            if app.MediaCuadraticaCheckBox.Value == 1
                app.T.MediaCuadraticaEMG = zeros(0)
            end
        end

        % Value changed function: OndaEEGaFiltrarDropDown
        function OndaEEGaFiltrarDropDownValueChanged(app, event)
            value = app.OndaEEGaFiltrarDropDown.Value;


            switch value
                case 'Alpha'
                    app.Onda_Low = 7;
                    app.Onda_High = 14;
                case 'Beta'
                    app.Onda_Low = 14;
                    app.Onda_High = 21;
                case 'Theta'
                    app.Onda_Low = 4;
                    app.Onda_High = 7;
                case 'Delta'
                    app.Onda_Low = 0;
                    app.Onda_High = 4;
                    
            end
        end

        % Value changed function: SeleccionartodoCheckBox
        function SeleccionartodoCheckBoxValueChanged(app, event)
            value = app.SeleccionartodoCheckBox.Value;
            if app.SeleccionartodoCheckBox.Value ==1
                
                app.HjorthActivityCheckBox.Value =1;
                app.NormalizedFirstDifferenceCheckBox.Value =1;
                app.HjorthComplexityCheckBox.Value =1;
                app.KurtosisCheckBox.Value =1;
                app.MeanEnergyCheckBox.Value = 1;
                app.SkewnessCheckBox.Value=1;
                app.ShannonEntropyCheckBox.Value=1;
                app.HjorthMobilityCheckBox.Value=1;
                app.PCACheckBox.Value=1;
                app.ZeroCrossingCheckBox.Value=1;
                app.MAVCheckBox.Value=1;
                app.KurtosisCheckBox_2.Value=1;
                app.PromediodeEnergaCheckBox.Value=1;
                app.VarianzaCheckBox.Value=1;
                app.AmplituddeWilsonCheckBox.Value=1;
                app.MediaCuadraticaCheckBox.Value=1;
            end
            if app.SeleccionartodoCheckBox.Value ==0
                
                app.HjorthActivityCheckBox.Value =0;
                app.NormalizedFirstDifferenceCheckBox.Value =0;
                app.HjorthComplexityCheckBox.Value =0;
                app.KurtosisCheckBox.Value =0;
                app.MeanEnergyCheckBox.Value = 0;
                app.SkewnessCheckBox.Value=0;
                app.ShannonEntropyCheckBox.Value=0 ;
                app.HjorthMobilityCheckBox.Value=0;
                app.PCACheckBox.Value=0;
                app.ZeroCrossingCheckBox.Value=0;
                app.MAVCheckBox.Value=0;
                app.KurtosisCheckBox_2.Value=0;
                app.PromediodeEnergaCheckBox.Value=0;
                app.VarianzaCheckBox.Value=0;
                app.AmplituddeWilsonCheckBox.Value=0;
                app.MediaCuadraticaCheckBox.Value=0;
            end
        end

        % Value changed function: ClaseagrabarDropDown
        function ClaseagrabarDropDownValueChanged(app, event)
            
            
            app.Clase_Value = app.ClaseagrabarDropDown.Value;
            
            switch app.Clase_Value
                case 'Head-Right'
                    app.Image.ImageSource = imread("Cabeza_Derecha.png");
                case 'Head-Left'
                    app.Image.ImageSource = imread("Cabeza_Izquierda.png");
                case 'Head-Turn-Left'
                    app.Image.ImageSource = imread("HeadTurnLeft.jpg");
                case 'WristUp'
                    app.Image.ImageSource = imread("WristUp.jpg");
                case 'HandUp'
                    app.Image.ImageSource = imread("manita.jfif");
                case 'Relax'
                    app.Image.ImageSource = imread("Relax.png");
                    
            end
        end

        % Button pushed function: EmpezaraGrabarButton
        function EmpezaraGrabarButtonPushed(app, event)
            %%  Establecer la longitud de las epocas que se desea e inicializacion de variables


            app.Pausa = 0;
            Epocas_Length = app.TiempodemuestraatomarEditField.Value; % Duracion en segundos de cada epoca
            Canales = 8;        % Numero de canales que se desea usar (1 a 8)
            itr = 1;
            t=1:1000;
            vproyecto = zeros(1000,8);
            i = 1;
            Banderita=0;
            
            ctrl2 = 0;
            fs =250;
            M = 2;
            %% Se definen los parametros para los filtros
            fs_Hz = 250;
            little_buff = zeros(1,10);
            display_buff = zeros(1,5000);
            cont = 1;
            
            bpf = [app.Onda_Low, app.Onda_High];
            [b,a] = butter(2,bpf/(fs_Hz / 2), 'bandpass');
            
            notch = [59, 61];
            [b2, a2] = butter(2,notch/(fs_Hz / 2), 'stop');
            
            %%  Reservar memoria para vector de datos raw y para filtrar los datos
            Col = 8;
            Row = 50000000;
            Offset = zeros(1,8);
            Live_Data1 = zeros(Row,Col);
            Live_Data_test = [];
            
            Live_Data_test2 = zeros(Epocas_Length*250,8);
            Fixed_Vector = zeros(Epocas_Length*250,8);
            Full_Fill_Vec = zeros(2*Epocas_Length*250,8);
            Temp_Raw_Vector = zeros(2*Epocas_Length*100,8);
            index1 = 1;
            cont = 1;
            cont2 = Epocas_Length*250;
            cont3 = Epocas_Length*250;
            tiempos = zeros(Epocas_Length*250,1);
            tiempos2 = zeros(Epocas_Length*1000,1);
            
            mitimer  = tic;
            n = 1;
            Epocas = Epocas_Length*250  ; %se toma el tamano del buffer obtenido
            L = Epocas; %longitud del vector epocas
            t=linspace(0, Epocas/250, L);
            
            Epocas2 = Epocas_Length*1000;
            L2 = Epocas2;
            t2 = linspace(0,Epocas2/1000,L2);
            %% instantiate the library
            disp('Loading the library...');
            lib = lsl_loadlib();
            % resolve a stream...
            disp('Resolving an EEG stream...');
            result = {};
            while isempty(result)
                result = lsl_resolve_byprop(lib,'type','EEG');
            end
            
            % create a new inlet
            disp('Opening an inlet...');
            inlet = lsl_inlet(result{1});
            disp('Now receiving data...');
            
            h1= plot(app.figFpz,  t, tiempos)
            title('Ch1')
            ylabel('uV')
            xlabel('tiempo (s)')
            
            h2= plot(app.figFpz_2,  t2, tiempos2)
            title('Ch1')
            ylabel('uV')
            xlabel('tiempo (s)')
            
            FactorEscalador = (4500000)/24/(2^23-1);
            %app.Bit.startBackground
            while (app.flag_recibir1 ==1)&&(app.flag_recibir2 ==1)
                
                [vec,ts] = inlet.pull_sample();                         % Se realiza la lectura de los 8 canales
                tiempos(n) = toc(mitimer);
                mitimer  = tic;
                
                Live_Data_test2(index1,:) =  (vec)*FactorEscalador;                       % Se ingresa los datos leidos a un vector temporal
                
                %     h1.YData(i) = (vec(1, 1))/1000;                       %Actualizar gráfica
                %
                %     drawnow limitrate
                n = n +1;
                
                i=i+1;
                if(mod(i,Epocas_Length*250)==0)                       % Cada vez que pase cierta cantidad de tiempo se hace la separacion de epocas
                    i=1;
                    data = read(app.Bit);% tomamos los ultimos valores en el buffer.\
                    assignin('base', 'matrizData', data);
                    if (min(Live_Data_test2)==0)
                        [M,I] = find(Live_Data_test2 == 0);
                        Live_Data_test2(M,I) = Offset;
                        fprintf('/');
                    end
                    Fixed_Vector = (Live_Data_test2 - (Offset));
                    display_buff_filt = filter(b2,a2,Fixed_Vector);  % Se realiza el friltrado del los datos
                    display_buff_filt = filter(b,a,display_buff_filt);
                    Offset = [mean(Live_Data_test2(:,1)),mean(Live_Data_test2(:,2)),mean(Live_Data_test2(:,3)),mean(Live_Data_test2(:,4)),mean(Live_Data_test2(:,5)),mean(Live_Data_test2(:,6)),mean(Live_Data_test2(:,7)),mean(Live_Data_test2(:,8))];
                    Full_Fill_Vec(cont:cont3 ,:) = Live_Data_test2;     % Se almacenan los datos de cada epoca de los valores en bruto
                    Temp_Raw_Vector(cont:cont3,:) = display_buff_filt;  % Se almacenan los datos de cada epoca de los valores filtrados
                    cont = cont + cont2;                                % Contador para la primera posicion de la proxima epoca a guardar
                    cont3 = cont3 + cont2;                              % Contador de la ultima posicion de la proxima epoca a guardar
                    index1 = 1;
                    flag = true;                                        % cuando se cumpla esta condicion se levanta una bandera para poder sacarle las caracterisiticas a dicha epoca
                    
                    %n=n+1%contador para ingresar los datos a la tabla.
                    app.EMG_RAW = data(:,6); %se toma solo la columna de la senal EMG
                    assignin('base', 'EMGRaw', app.EMG_RAW);
                    EMG = app.EMG_RAW*(3.3/1024);%se convierte la senal a la escala de 3.3 V
                    assignin('base', 'data_nI', app.EMG_RAW);
                    data_f = filter(app.F_pb, EMG');                          %Aplicar filtro pasa bandas
                    assignin('base', 'data_filter', data_f);
                    app.data_n = filter(app.F_notch, data_f);                       %Aplicar filtro notch
                    assignin('base', 'data_nI', app.data_n);
                    assignin('base', 'BrutoEMG', app.DataEnBrutoEEG);
                else
                    flag = false;
                    index1 = index1 + 1;
                end


                if flag == true
                    Banderita=Banderita+1;
                    %Extraer caracteristicas
                    Chn1 = display_buff_filt(: , 1);
                    ylim(app.figFpz_2, [min(app.data_n) max(app.data_n)]);
                    Epocas2 = size(app.data_n,2) ; %se toma el tamano del buffer obtenido
                    L2 = Epocas2; %longitud del vector epocas
                    
                    t2=linspace(0, Epocas2/1000, L2);
                    plot(app.figFpz_2, t2, app.data_n, 'Color','r') %se plotea esa muestra
                    
                    %                     plot( app.figFpz_2,t2, app.data_n)
                    %                     ylim( app.figFpz_2, [min(app.data_n) max(app.data_n)]);
                    
                    
                    plot( app.figFpz,t, Chn1)
                    ylim( app.figFpz, [min(Chn1) max(Chn1)]);
                    
                    drawnow limitrate


                    %h1 = ylim([min(Chn1) max(Chn1)]);
                    if (app.Pausa == 0)&&(Banderita>2)
                        
                        ctrl2 = ctrl2 + 1;
                     
                        
                        app.DataEnBrutoEMG(:,ctrl2)= num2cell(app.data_n(1:4500)); %En esta celda guardamos cada corrida o cada iteracion
                        itr = 0;
                        fprintf('Done - %d \n',app.ctrl);
                        app.ctrl = app.ctrl + 1; % variable de control de duracion de la simulacion
                        app.Label_8.Text = string(app.ctrl);
                        app.DataEnBrutoEEG(:,ctrl2) = num2cell(Chn1);
                        
                        
                        if app.HjorthActivityCheckBox.Value == 1
                            app.T.HjorthActivityEEG(ctrl2) = jHjorthActivity(Chn1)
                        end
                        if app.NormalizedFirstDifferenceCheckBox.Value == 1
                            app.T.NormalizedFirstDifferenceEEG(ctrl2) = jNormalizedFirstDifference(Chn1)
                        end
                        if app.HjorthComplexityCheckBox.Value == 1
                            app.T.HjorthComplexityEEG(ctrl2) = jHjorthComplexity(Chn1)
                        end
                        
                        if app.KurtosisCheckBox.Value == 1
                            app.T.KurtosisEEG(ctrl2) = jKurtosis(Chn1)
                        end
                        
                        if app.MeanEnergyCheckBox.Value == 1
                            app.T.MeanEnergyEEG(ctrl2) = jMeanTeagerEnergy(Chn1)
                        end
                        if app.SkewnessCheckBox.Value == 1
                            app.T.SkewnessEEG(ctrl2) = jSkewness(Chn1)
                        end
                        if app.ShannonEntropyCheckBox.Value == 1
                            app.T.ShannonEntropyEEG(ctrl2) = jShannonEntropy(Chn1)
                        end
                        if app.HjorthMobilityCheckBox.Value == 1
                            app.T.HjorthMobilityEEG(ctrl2) = jHjorthMobility(Chn1)
                        end
                        if app.PCACheckBox.Value == 1
                            [~,~,latent,~,~,mu] = pca(Chn1);
                            app.T.PCALatentEEG(ctrl2) = latent;
                            app.T.PCAMuEEG(ctrl2) = mu;
                        end
                        if app.ZeroCrossingCheckBox.Value == 1
                            app.T.ZCEMG(ctrl2) = jZeroCrossing(app.data_n,0.34);
                            
                        end
                        if app.MAVCheckBox.Value == 1
                            app.T.MAVEMG(ctrl2) = jMeanAbsoluteDeviation(app.data_n);
                            
                        end
                        if app.KurtosisCheckBox.Value == 1
                            app.T.KurtosisEMG(ctrl2) = jKurtosis(app.data_n);
                            
                        end
                        if app.PromediodeEnergaCheckBox.Value == 1
                            app.T.AverageEEMG(ctrl2) = jAverageEnergy(app.data_n);
                            
                        end
                        
                        if app.VarianzaCheckBox.Value == 1
                            app.T.VarianzaEMG(ctrl2) = jVariance(app.data_n);
                            
                        end
                        if app.AmplituddeWilsonCheckBox.Value == 1
                            app.T.AmplitudWilsonEMG(ctrl2) = jWillisonAmplitude(app.data_n,0.1);
                            
                        end
                        if app.MediaCuadraticaCheckBox.Value == 1
                            app.T.MediaCuadraticaEMG(ctrl2) = jRootMeanSquare(app.data_n);
                            
                        end


                        app.LarecoleccinestenPausaLabel.Visible = 'off';
                    end
                    if app.Pausa == 1
                        app.LarecoleccinestenPausaLabel.Visible = 'on';
                    end
                    
                    
                end
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Pausa=1;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Pausa=0;
        end

        % Button pushed function: GuardarArchivoButton
        function GuardarArchivoButtonPushed(app, event)


            %             save('PruebaT.mat', 'T')
            %             save('PruebaT2.mat', 'DataEnBruto')
            NombreA = app.NombreDelArchivoEditField.Value
            writetable(app.T,string(NombreA)+"_"+string(app.Clase_Value)+"."+'xls')
            writecell(app.DataEnBrutoEEG,string(NombreA)+"_"+string(app.Clase_Value)+"EnBrutoEEG"+"."+'xls')
            writecell(app.DataEnBrutoEMG,string(NombreA)+"_"+string(app.Clase_Value)+"EnBrutoEMG"+"."+'xls')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.RecoleccindeDatosEEGyEMGUVGLabel = uilabel(app.Panel);
            app.RecoleccindeDatosEEGyEMGUVGLabel.Text = 'Recolección de Datos  EEG y EMG - UVG';
            app.RegresarButton = uibutton(app.UIFigure, 'push');
            app.RegresarButton.Icon = 'back.png';
            app.RegresarButton.Text = 'Regresar';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InstruccionesTab = uitab(app.TabGroup);
            app.InstruccionesTab.Title = 'Instrucciones';
            app.Label = uilabel(app.InstruccionesTab);
            app.Label.Text = '1. Se tienen que seleccionar las caracteristicas que se quieren incluir en los vectores de caracteristicas. ';
            app.Label_2 = uilabel(app.InstruccionesTab);
            app.Label_2.Text = '2. Al tener las características seleccionadas se debe crear la tabla donde se guardaran estos vectores. ';
            app.InstruccionesparaelusodelaInterfazLabel = uilabel(app.InstruccionesTab);
            app.InstruccionesparaelusodelaInterfazLabel.Text = 'Instrucciones para el uso de la Interfaz';
            app.Label_3 = uilabel(app.InstruccionesTab);
            app.Label_3.Text = '5. Se debe de conectar al electrocap y bitalino y luego empezar a recolectar datos empezando el streaming.  ';
            app.Label_4 = uilabel(app.InstruccionesTab);
            app.Label_4.Text = '3. Se debe de seleccionar la ventana de tiempo de las muestras que se tomarán.';
            app.Label_5 = uilabel(app.InstruccionesTab);
            app.Label_5.Text = '6. Para parar de recolectar data, se debe de apagar el streaming. ';
            app.Label_6 = uilabel(app.InstruccionesTab);
            app.Label_6.Text = '4. Se debe de seleccionar que clase se grabará. ';
            app.Label_7 = uilabel(app.InstruccionesTab);
            app.Label_7.Text = '7. Guardar la tabla, se recomienda colocar el nombre que identifique la clase y datos sobre el ambiente. ';
            app.PaneldecontrolTab = uitab(app.TabGroup);
            app.PaneldecontrolTab.Title = 'Panel de control ';
            app.CaracteristicasSeleccionadasPanel = uipanel(app.PaneldecontrolTab);
            app.CaracteristicasSeleccionadasPanel.Title = 'Caracteristicas Seleccionadas ';
            app.CaracteristicasSeleccionadasPanel.SizeChangedFcn = createCallbackFcn(app, @CaracteristicasSeleccionadasPanelSizeChanged, true);
            app.HjorthActivityCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.HjorthActivityCheckBox.Text = 'Hjorth Activity';
            app.NormalizedFirstDifferenceCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.NormalizedFirstDifferenceCheckBox.Text = 'Normalized First Difference';
            app.HjorthComplexityCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.HjorthComplexityCheckBox.Text = 'Hjorth Complexity';
            app.KurtosisCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.KurtosisCheckBox.Text = 'Kurtosis';
            app.MeanEnergyCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.MeanEnergyCheckBox.Text = 'Mean Energy';
            app.CrearTablaButton = uibutton(app.CaracteristicasSeleccionadasPanel, 'push');
            app.CrearTablaButton.ButtonPushedFcn = createCallbackFcn(app, @CrearTablaButtonPushed, true);
            app.CrearTablaButton.Text = 'Crear Tabla';
            app.ConectaraElectrocapSwitchLabel = uilabel(app.CaracteristicasSeleccionadasPanel);
            app.ConectaraElectrocapSwitchLabel.Text = 'Conectar a Electrocap';
            app.ConectaraElectrocapSwitch = uiswitch(app.CaracteristicasSeleccionadasPanel, 'slider');
            app.ConectaraElectrocapSwitch.ValueChangedFcn = createCallbackFcn(app, @ConectaraElectrocapSwitchValueChanged, true);
            app.SkewnessCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.SkewnessCheckBox.Text = 'Skewness';
            app.ShannonEntropyCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.ShannonEntropyCheckBox.Text = 'Shannon Entropy';
            app.HjorthMobilityCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.HjorthMobilityCheckBox.Text = 'Hjorth Mobility';
            app.SeleccionartodoCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.SeleccionartodoCheckBox.ValueChangedFcn = createCallbackFcn(app, @SeleccionartodoCheckBoxValueChanged, true);
            app.SeleccionartodoCheckBox.Text = 'Seleccionar todo';
            app.PCACheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.PCACheckBox.Text = 'PCA';
            app.ZeroCrossingCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.ZeroCrossingCheckBox.Text = 'Zero Crossing';
            app.MAVCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.MAVCheckBox.Text = 'MAV';
            app.KurtosisCheckBox_2 = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.KurtosisCheckBox_2.Text = 'Kurtosis';
            app.PromediodeEnergaCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.PromediodeEnergaCheckBox.Text = 'Promedio de Energía';
            app.VarianzaCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.VarianzaCheckBox.Text = 'Varianza';
            app.AmplituddeWilsonCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.AmplituddeWilsonCheckBox.Text = 'Amplitud de Wilson';
            app.MediaCuadraticaCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.MediaCuadraticaCheckBox.Text = 'Media Cuadratica ';
            app.CaractersticassealesEEGLabel = uilabel(app.CaracteristicasSeleccionadasPanel);
            app.CaractersticassealesEEGLabel.Text = 'Características señales EEG';
            app.CaractersticassealesEMGLabel = uilabel(app.CaracteristicasSeleccionadasPanel);
            app.CaractersticassealesEMGLabel.Text = 'Características señales EMG';
            app.ConectarBitalinoSwitchLabel = uilabel(app.CaracteristicasSeleccionadasPanel);
            app.ConectarBitalinoSwitchLabel.Text = 'Conectar Bitalino';
            app.ConectarBitalinoSwitch = uiswitch(app.CaracteristicasSeleccionadasPanel, 'slider');
            app.ConectarBitalinoSwitch.ValueChangedFcn = createCallbackFcn(app, @ConectarBitalinoSwitchValueChanged, true);
            app.EmpezarStreamingSwitchLabel = uilabel(app.CaracteristicasSeleccionadasPanel);
            app.EmpezarStreamingSwitchLabel.Text = 'Empezar Streaming';
            app.EmpezarStreamingSwitch = uiswitch(app.CaracteristicasSeleccionadasPanel, 'slider');
            app.EmpezarStreamingSwitch.ValueChangedFcn = createCallbackFcn(app, @EmpezarStreamingSwitchValueChanged, true);
            app.Lamp = uilamp(app.CaracteristicasSeleccionadasPanel);
            app.Lamp_2 = uilamp(app.CaracteristicasSeleccionadasPanel);
            app.Lamp_4 = uilamp(app.CaracteristicasSeleccionadasPanel);
            app.NombreBluetoothBitalinoEditFieldLabel = uilabel(app.CaracteristicasSeleccionadasPanel);
            app.NombreBluetoothBitalinoEditFieldLabel.Text = 'Nombre Bluetooth Bitalino';
            app.NombreBluetoothBitalinoEditField = uieditfield(app.CaracteristicasSeleccionadasPanel, 'text');
            app.NombreBluetoothBitalinoEditField.Value = 'BITalino-00-97';
            app.GrabacinPanel = uipanel(app.PaneldecontrolTab);
            app.GrabacinPanel.Title = 'Grabación ';
            app.GuardarArchivoButton = uibutton(app.GrabacinPanel, 'push');
            app.GuardarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarArchivoButtonPushed, true);
            app.GuardarArchivoButton.Text = 'Guardar Archivo';
            app.TiempodemuestraatomarEditFieldLabel = uilabel(app.GrabacinPanel);
            app.TiempodemuestraatomarEditFieldLabel.Text = 'Tiempo de muestra a tomar';
            app.TiempodemuestraatomarEditField = uieditfield(app.GrabacinPanel, 'numeric');
            app.TiempodemuestraatomarEditField.Limits = [0 25];
            app.TiempodemuestraatomarEditField.RoundFractionalValues = 'on';
            app.TiempodemuestraatomarEditField.Value = 5;
            app.NombreDelArchivoEditFieldLabel = uilabel(app.GrabacinPanel);
            app.NombreDelArchivoEditFieldLabel.Text = 'Nombre Del Archivo';
            app.NombreDelArchivoEditField = uieditfield(app.GrabacinPanel, 'text');
            app.NombreDelArchivoEditField.Value = 'Sesion_1_EEGEMG';
            app.ClaseagrabarDropDownLabel = uilabel(app.GrabacinPanel);
            app.ClaseagrabarDropDownLabel.Text = 'Clase a grabar';
            app.ClaseagrabarDropDown = uidropdown(app.GrabacinPanel);
            app.ClaseagrabarDropDown.Items = {'-Seleccionar-', 'Head-Right', 'Head-Left', 'Head-Turn-Left', 'Relax', 'HandUp', 'WristUp'};
            app.ClaseagrabarDropDown.ValueChangedFcn = createCallbackFcn(app, @ClaseagrabarDropDownValueChanged, true);
            app.ClaseagrabarDropDown.Value = '-Seleccionar-';
            app.EmpezaraGrabarButton = uibutton(app.GrabacinPanel, 'push');
            app.EmpezaraGrabarButton.ButtonPushedFcn = createCallbackFcn(app, @EmpezaraGrabarButtonPushed, true);
            app.EmpezaraGrabarButton.Text = 'Empezar a Grabar';
            app.NmerodemuestrastomadasLabel = uilabel(app.GrabacinPanel);
            app.NmerodemuestrastomadasLabel.Text = 'Número de muestras tomadas:';
            app.Label_8 = uilabel(app.GrabacinPanel);
            app.Label_8.Text = '0';
            app.Button_2 = uibutton(app.GrabacinPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'BotonPausa.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GrabacinPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'play.png';
            app.Button_3.Text = '';
            app.PausaReiniciarGrabacinLabel = uilabel(app.GrabacinPanel);
            app.PausaReiniciarGrabacinLabel.Text = 'Pausa/Reiniciar Grabación';
            app.Image = uiimage(app.GrabacinPanel);
            app.Image.ImageSource = 'Cabeza_Derecha.png';
            app.OndaEEGaFiltrarDropDownLabel = uilabel(app.GrabacinPanel);
            app.OndaEEGaFiltrarDropDownLabel.Text = 'Onda EEG a Filtrar';
            app.OndaEEGaFiltrarDropDown = uidropdown(app.GrabacinPanel);
            app.OndaEEGaFiltrarDropDown.Items = {'-Seleccionar-', 'Beta', 'Alpha', 'Theta', 'Delta', ''};
            app.OndaEEGaFiltrarDropDown.ValueChangedFcn = createCallbackFcn(app, @OndaEEGaFiltrarDropDownValueChanged, true);
            app.OndaEEGaFiltrarDropDown.Value = '-Seleccionar-';
            app.LarecoleccinestenPausaLabel = uilabel(app.UIFigure);
            app.LarecoleccinestenPausaLabel.Visible = 'off';
            app.LarecoleccinestenPausaLabel.Text = 'La recolección está en Pausa';
            app.figFpz = uiaxes(app.UIFigure);
            title(app.figFpz, 'Muestra Tomada EEG')
            xlabel(app.figFpz, 't (s)')
            ylabel(app.figFpz, 'uV')
            app.figFpz_2 = uiaxes(app.UIFigure);
            title(app.figFpz_2, 'Muestra Tomada EMG')
            xlabel(app.figFpz_2, 't (s)')
            ylabel(app.figFpz_2, 'V')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RecoleccionDeDatosEEGEMG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end