classdef Interfaz_EEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RegresarButton                  matlab.ui.control.Button
        BCIEEGUVGLabel                  matlab.ui.control.Label
        PaneldeControl                  matlab.ui.container.Panel
        ConectarconElectrocapSwitchLabel  matlab.ui.control.Label
        ConectarconElectrocapSwitch     matlab.ui.control.Switch
        OndaaFiltrarDropDownLabel       matlab.ui.control.Label
        OndaaFiltrarDropDown            matlab.ui.control.DropDown
        TiempodemuestraatomarEditFieldLabel  matlab.ui.control.Label
        TiempodemuestraatomarEditField  matlab.ui.control.NumericEditField
        ConexinRobotPanel               matlab.ui.container.Panel
        ButtonsPanel                    matlab.ui.container.Panel
        STARTButton                     matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        LampGreen                       matlab.ui.control.Lamp
        LampRed                         matlab.ui.control.Lamp
        ParametersPanel                 matlab.ui.container.Panel
        SPEEDEditFieldLabel             matlab.ui.control.Label
        SPEEDEditField                  matlab.ui.control.NumericEditField
        COMDropDownLabel                matlab.ui.control.Label
        COMDropDown                     matlab.ui.control.DropDown
        IniciarButton                   matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        EscogerEntrenamientoButton      matlab.ui.control.Button
        ThHjorActiviySpinnerLabel       matlab.ui.control.Label
        ThHjorActiviySpinner            matlab.ui.control.Spinner
        SealesentiempoRealPanel         matlab.ui.container.Panel
        Canal1Label                     matlab.ui.control.Label
        HjorthActivityEditFieldLabel    matlab.ui.control.Label
        HjorthActivityEditField         matlab.ui.control.NumericEditField
        HjorthComplexityEditFieldLabel  matlab.ui.control.Label
        HjorthComplexityEditField       matlab.ui.control.NumericEditField
        HjorthMobilityEditFieldLabel    matlab.ui.control.Label
        HjorthMobilityEditField         matlab.ui.control.NumericEditField
        NormalizedFirstDifferenceEditFieldLabel  matlab.ui.control.Label
        NormalizedFirstDifferenceEditField  matlab.ui.control.NumericEditField
        KurtosisEditFieldLabel          matlab.ui.control.Label
        KurtosisEditField               matlab.ui.control.NumericEditField
        MeanEnergyEditFieldLabel        matlab.ui.control.Label
        MeanEnergyEditField             matlab.ui.control.NumericEditField
        SkewnessEditFieldLabel          matlab.ui.control.Label
        SkewnessEditField               matlab.ui.control.NumericEditField
        ShannonEntropyEditFieldLabel    matlab.ui.control.Label
        ShannonEntropyEditField         matlab.ui.control.NumericEditField
        PCALatentEditFieldLabel         matlab.ui.control.Label
        PCALatentEditField              matlab.ui.control.NumericEditField
        PCAMuEditFieldLabel             matlab.ui.control.Label
        PCAMuEditField                  matlab.ui.control.NumericEditField
        figFpz                          matlab.ui.control.UIAxes
        Image                           matlab.ui.control.Image
        ClaseLabel                      matlab.ui.control.Label
        DesconectadoLampLabel           matlab.ui.control.Label
        DesconectadoLamp                matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        R17 = 0;
        COM = 0;
        Pausa = 0;
        SPEED = 0;
        flag_recibir = 0;
        Fs = 44100;
        Onda_Low = 7;
        Onda_High = 14;
        y1 = audioread('Click.wav');
        y2 = audioread('Turn-Left.wav');
        y3 = audioread('Head-Right.wav');
        y4 = audioread('Relax.wav');
        T = table()
        A=0;
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LampGreen.Enable = 'off';
            app.LampRed.Enable = 'off';
            app.STOPButton.Enable = 'off';
            app.T.HjorthActivity = zeros(0)
            app.T.NormalizedFirstDifference = zeros(0)
            app.T.HjorthComplexity = zeros(0)
            app.T.Kurtosis = zeros(0)
            app.T.MeanEnergy = zeros(0)
            app.T.Skewness = zeros(0)
            app.T.ShannonEntropy = zeros(0)
            app.T.HjorthMobility = zeros(0)
            app.T.PCALatent = zeros(0);
            app.T.PCAMu = zeros(0);
            app.T.Clase = zeros(0);
            
            
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
            Interfaz_Inicio
            delete(app)
        end

        % Value changed function: ConectarconElectrocapSwitch
        function ConectarconElectrocapSwitchValueChanged(app, event)
            value = app.ConectarconElectrocapSwitch.Value;
            
            
            if strcmp( value,'On')
                
                
                app.flag_recibir =1;
                
                app.DesconectadoLamp.Enable = 'on';
                app.DesconectadoLampLabel.Text= 'Conectado';
                
                
            else
                
                app.flag_recibir = 0;
                
                app.DesconectadoLamp.Enable = 'off';
                app.DesconectadoLampLabel.Text= 'Desconectado';
            end
            
            
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            soundsc(app.y1,app.Fs);
            app.STARTButton.Enable = 'off';
            app.STOPButton.Enable ='on';
            app.LampGreen.Enable = 'on';
            app.LampRed.Enable = 'off';
            app.SPEED = app.SPEEDEditField.Value
            
            %app.R17 = R17Init(app.COM, app.SPEED);
            
        end

        % Value changed function: COMDropDown
        function COMDropDownValueChanged(app, event)
            
            app.COM = app.COMDropDown.Value;
            % Para visualizar en el workspace
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            soundsc(app.y1,app.Fs);
            app.LampGreen.Enable = 'off';
            app.LampRed.Enable = 'on';
            app.STOPButton.Enable = 'off';
            app.STARTButton.Enable = 'on';
            
            %             q = [0; 0; 0; 0; 0; 0];
            %             app.fK = R17fKine(app.R17, q);
            %             R17Command(app.R17, 'HOME');
            %             pause(10);
            %R17Close(app.R17);
        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            %%  Establecer la longitud de las epocas que se desea e inicializacion de variables
            soundsc(app.y1,app.Fs);
            
            
            %load('EEG2Clases_76.mat')


            Pausa = 0;
            Epocas_Length = app.TiempodemuestraatomarEditField.Value; % Duracion en segundos de cada epoca
            Canales = 8;        % Numero de canales que se desea usar (1 a 8)
            
            t=1:1000;
            vproyecto = zeros(1000,8);
            i = 1;
            
            
            fs =250;
            M = 2;
            %% Se definen los parametros para los filtros
            fs_Hz = 250;
            little_buff = zeros(1,10);
            display_buff = zeros(1,5000);
            cont = 1;
            
            bpf = [app.Onda_Low, app.Onda_High];
            [b,a] = butter(2,bpf/(fs_Hz / 2), 'bandpass');
            
            notch = [59, 61];
            [b2, a2] = butter(2,notch/(fs_Hz / 2), 'stop');
            
            %%  Reservar memoria para vector de datos raw y para filtrar los datos
            Col = 8;
            Row = 50000000;
            Offset = zeros(1,8);
            Live_Data1 = zeros(Row,Col);
            Live_Data_test = [];
            Feature1_Vec = zeros(M,8);
            Feature2_Vec = zeros(M,8);
            Feature3_Vec = zeros(M,8);
            Feature4_Vec = zeros(M,8);
            Feature5_Vec = zeros(M,8);
            Live_Data_test2 = zeros(Epocas_Length*250,8);
            Fixed_Vector = zeros(Epocas_Length*250,8);
            Full_Fill_Vec = zeros(2*Epocas_Length*250,8);
            Temp_Raw_Vector = zeros(2*Epocas_Length*100,8);
            index1 = 1;
            cont = 1;
            cont2 = Epocas_Length*250;
            cont3 = Epocas_Length*250;
            tiempos = zeros(Epocas_Length*250,1);
            
            mitimer  = tic;
            n = 1;
            Epocas = Epocas_Length*250  ; %se toma el tamano del buffer obtenido
            L = Epocas; %longitud del vector epocas
            t=linspace(0, Epocas/250, L);
            
            %% instantiate the library
            disp('Loading the library...');
            lib = lsl_loadlib();
            % resolve a stream...
            disp('Resolving an EEG stream...');
            result = {};
            while isempty(result)
                result = lsl_resolve_byprop(lib,'type','EEG');
            end
            
            % create a new inlet
            disp('Opening an inlet...');
            inlet = lsl_inlet(result{1});
            disp('Now receiving data...');
            
            h1= plot(app.figFpz,  t, tiempos)
            title('Ch1')
            ylabel('uV')
            xlabel('tiempo (s)')
            
            FactorEscalador = (4500000)/24/(2^23-1);
            
            while app.flag_recibir ==1
                
                [vec,ts] = inlet.pull_sample();                         % Se realiza la lectura de los 8 canales
                tiempos(n) = toc(mitimer);
                mitimer  = tic;
                
                Live_Data_test2(index1,:) =  (vec)*FactorEscalador;                       % Se ingresa los datos leidos a un vector temporal
                
                %     h1.YData(i) = (vec(1, 1))/1000;                       %Actualizar gráfica
                %
                %     drawnow limitrate
                n = n +1;
                
                i=i+1;
                if(mod(i,Epocas_Length*250)==0)                       % Cada vez que pase cierta cantidad de tiempo se hace la separacion de epocas
                    i=1;
                    
                    if (min(Live_Data_test2)==0)
                        [M,I] = find(Live_Data_test2 == 0);
                        Live_Data_test2(M,I) = Offset;
                        fprintf('/');
                    end
                    Fixed_Vector = (Live_Data_test2 - (Offset));
                    %         Fixed_Vector = Live_Data_test2 - Live_Data_test2(1,:);
                    display_buff_filt = filter(b2,a2,Fixed_Vector);  % Se realiza el friltrado del los datos
                    display_buff_filt = filter(b,a,display_buff_filt);
                    Offset = [mean(Live_Data_test2(:,1)),mean(Live_Data_test2(:,2)),mean(Live_Data_test2(:,3)),mean(Live_Data_test2(:,4)),mean(Live_Data_test2(:,5)),mean(Live_Data_test2(:,6)),mean(Live_Data_test2(:,7)),mean(Live_Data_test2(:,8))];
                    %         Offseet = mean(Live_Data_test2,1);
                    Full_Fill_Vec(cont:cont3 ,:) = Live_Data_test2;     % Se almacenan los datos de cada epoca de los valores en bruto
                    Temp_Raw_Vector(cont:cont3,:) = display_buff_filt;  % Se almacenan los datos de cada epoca de los valores filtrados
                    cont = cont + cont2;                                % Contador para la primera posicion de la proxima epoca a guardar
                    cont3 = cont3 + cont2;                              % Contador de la ultima posicion de la proxima epoca a guardar
                    index1 = 1;
                    flag = true;                                        % cuando se cumpla esta condicion se levanta una bandera para poder sacarle las caracterisiticas a dicha epoca
                else
                    flag = false;
                    index1 = index1 + 1;
                end


                if flag == true
                    %Extraer caracteristicas
                    Chn1 = display_buff_filt(: , 1);
                    
                    app.HjorthActivityEditField.Value = jHjorthActivity(Chn1)
                    app.NormalizedFirstDifferenceEditField.Value = jNormalizedFirstDifference(Chn1)
                    app.HjorthComplexityEditField.Value = jHjorthComplexity(Chn1)
                    app.KurtosisEditField.Value = jKurtosis(Chn1)
                    app.MeanEnergyEditField.Value = jMeanTeagerEnergy(Chn1)
                    app.SkewnessEditField.Value = jSkewness(Chn1)
                    app.ShannonEntropyEditField.Value = jShannonEntropy(Chn1)
                    app.HjorthMobilityEditField.Value = jHjorthMobility(Chn1)
                    [~,~,latent,~,explained,mu] = pca(Chn1);
                    app.PCALatentEditField.Value = latent;
                    app.PCAMuEditField.Value = mu;
                    drawnow limitrate
                    
                    h1 = plot( app.figFpz,t, Chn1)
                    ylim( app.figFpz, [min(Chn1) max(Chn1)]);
                    ACT=app.ThHjorActiviySpinner.Value;
                    drawnow limitrate
                    ThActivity = jHjorthActivity(Chn1);
                    if ThActivity > ACT
                        Activity =1;
                    end
                    %h1 = ylim([min(Chn1) max(Chn1)]);
                    if (app.Pausa == 0) && (Activity == 1)
                        Activity =0;


                        app.T.HjorthActivity(1) = jHjorthActivity(Chn1)
                        app.T.NormalizedFirstDifference(1) = jNormalizedFirstDifference(Chn1)
                        app.T.HjorthComplexity(1) = jHjorthComplexity(Chn1)
                        app.T.Kurtosis(1) = jKurtosis(Chn1)
                        app.T.MeanEnergy(1) = jMeanTeagerEnergy(Chn1)
                        app.T.Skewness(1) = jSkewness(Chn1)
                        app.T.ShannonEntropy(1) = jShannonEntropy(Chn1)
                        app.T.HjorthMobility(1) = jHjorthMobility(Chn1)
                        [~,~,latent,~,explained,mu] = pca(Chn1);
                        app.T.PCALatent(1) = latent;
                        app.T.PCAMu(1) = mu;
                        
                        yfit = app.A.predictFcn(app.T);
                        
                        switch yfit
                            case 'HeadRight'
                                app.Image.ImageSource = imread("Cabeza_Derecha.png");
                                drawnow limitrate
                                soundsc(app.y3,app.Fs);
                                %                                 ContGiro = ContGiro + 1;
                                %                                 if ContGiro == 17
                                %                                     ContGiro =1 ;
                                %                                 end
                                %                                 giro = Array_giro(ContGiro) ;
                                %                                 q2 = [Metros; deg2rad(giro);0; 0;0; 0];
                                %                                 R17fKine(R17, q2);
                                %
                                %                                 app.ClaseLabel.Text = "WristUp"
                                
                            case 'TurnLeft'
                                app.Image.ImageSource = imread("HeadTurnLeft.jpg");
                                drawnow limitrate
                                soundsc(app.y2,app.Fs);
                                %                                 ContMetros = ContMetros +1;
                                %                                 if ContMetros == 12
                                %                                     ContMetros =1;
                                %                                 end
                                %                                 Metros = Metros_CAREER(ContMetros);
                                %                                 q2 = [Metros; deg2rad(giro);0; 0; 0; 0];
                                %                                 R17fKine(R17, q2);
                                %                                 app.ClaseLabel.Text = "HandUp"
                            case 'Relax'
                                app.Image.ImageSource = imread("Relax.png");
                                drawnow limitrate
                                soundsc(app.y4,app.Fs);
                                %                                 ContMetros = ContMetros +1;
                                %                                 if ContMetros == 12
                                %                                     ContMetros =1;
                                %                                 end
                                %                                 Metros = Metros_CAREER(ContMetros);
                                %                                 q2 = [Metros; deg2rad(giro);0; 0; 0; 0];
                                %                                 R17fKine(R17, q2);
                                %                                 app.ClaseLabel.Text = "HandUp"
                                
                        end


                    end
                    
                    
                end
            end
        end

        % Value changed function: OndaaFiltrarDropDown
        function OndaaFiltrarDropDownValueChanged(app, event)
            
            value = app.OndaaFiltrarDropDown.Value;


            switch value
                case 'Alpha'
                    app.Onda_Low = 7;
                    app.Onda_High = 14;
                case 'Beta'
                    app.Onda_Low = 14;
                    app.Onda_High = 21;
                case 'Theta'
                    app.Onda_Low = 4;
                    app.Onda_High = 7;
                case 'Delta'
                    app.Onda_Low = 0;
                    app.Onda_High = 4;
                    
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
            soundsc(app.y1,app.F1);
            app.Pausa = 0;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            soundsc(app.y1,app.Fs);
            app.Pausa = 1;
        end

        % Button pushed function: EscogerEntrenamientoButton
        function EscogerEntrenamientoButtonPushed(app, event)
            [file,folder]=uigetfile('*.mat');
            filename=fullfile(folder,file);
            s=load(filename);
            C= struct2cell(s);
            app.A= cell2mat(C);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RegresarButton = uibutton(app.UIFigure, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Icon = 'back.png';
            app.RegresarButton.Text = 'Regresar';
            app.BCIEEGUVGLabel = uilabel(app.UIFigure);
            app.BCIEEGUVGLabel.Text = 'BCI EEG - UVG';
            app.PaneldeControl = uipanel(app.UIFigure);
            app.PaneldeControl.Title = 'Panel de Control';
            app.ConectarconElectrocapSwitchLabel = uilabel(app.PaneldeControl);
            app.ConectarconElectrocapSwitchLabel.Text = 'Conectar con Electrocap';
            app.ConectarconElectrocapSwitch = uiswitch(app.PaneldeControl, 'slider');
            app.ConectarconElectrocapSwitch.ValueChangedFcn = createCallbackFcn(app, @ConectarconElectrocapSwitchValueChanged, true);
            app.OndaaFiltrarDropDownLabel = uilabel(app.PaneldeControl);
            app.OndaaFiltrarDropDownLabel.Text = 'Onda a Filtrar';
            app.OndaaFiltrarDropDown = uidropdown(app.PaneldeControl);
            app.OndaaFiltrarDropDown.Items = {'-Seleccionar-', 'Beta', 'Alpha', 'Theta', 'Delta'};
            app.OndaaFiltrarDropDown.ValueChangedFcn = createCallbackFcn(app, @OndaaFiltrarDropDownValueChanged, true);
            app.OndaaFiltrarDropDown.Value = '-Seleccionar-';
            app.TiempodemuestraatomarEditFieldLabel = uilabel(app.PaneldeControl);
            app.TiempodemuestraatomarEditFieldLabel.Text = 'Tiempo de muestra a tomar';
            app.TiempodemuestraatomarEditField = uieditfield(app.PaneldeControl, 'numeric');
            app.TiempodemuestraatomarEditField.Limits = [0 25];
            app.TiempodemuestraatomarEditField.RoundFractionalValues = 'on';
            app.TiempodemuestraatomarEditField.Value = 5;
            app.ConexinRobotPanel = uipanel(app.PaneldeControl);
            app.ConexinRobotPanel.Title = 'Conexión Robot';
            app.ButtonsPanel = uipanel(app.ConexinRobotPanel);
            app.ButtonsPanel.Title = 'Buttons Panel';
            app.STARTButton = uibutton(app.ButtonsPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.ButtonsPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.LampGreen = uilamp(app.ButtonsPanel);
            app.LampRed = uilamp(app.ButtonsPanel);
            app.ParametersPanel = uipanel(app.ConexinRobotPanel);
            app.ParametersPanel.Title = 'Parameters Panel';
            app.SPEEDEditFieldLabel = uilabel(app.ParametersPanel);
            app.SPEEDEditFieldLabel.Text = 'SPEED';
            app.SPEEDEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SPEEDEditField.Limits = [5000 25000];
            app.SPEEDEditField.RoundFractionalValues = 'on';
            app.SPEEDEditField.Value = 10000;
            app.COMDropDownLabel = uilabel(app.ParametersPanel);
            app.COMDropDownLabel.Text = 'COM';
            app.COMDropDown = uidropdown(app.ParametersPanel);
            app.COMDropDown.Items = {'-Seleccionar-', 'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9'};
            app.COMDropDown.ValueChangedFcn = createCallbackFcn(app, @COMDropDownValueChanged, true);
            app.COMDropDown.Value = '-Seleccionar-';
            app.IniciarButton = uibutton(app.PaneldeControl, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar ';
            app.Button = uibutton(app.PaneldeControl, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'BotonPausa.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.PaneldeControl, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'play.png';
            app.Button_2.Text = '';
            app.EscogerEntrenamientoButton = uibutton(app.PaneldeControl, 'push');
            app.EscogerEntrenamientoButton.ButtonPushedFcn = createCallbackFcn(app, @EscogerEntrenamientoButtonPushed, true);
            app.EscogerEntrenamientoButton.Text = 'Escoger Entrenamiento';
            app.ThHjorActiviySpinnerLabel = uilabel(app.PaneldeControl);
            app.ThHjorActiviySpinnerLabel.Text = 'Th HjorActiviy';
            app.ThHjorActiviySpinner = uispinner(app.PaneldeControl);
            app.ThHjorActiviySpinner.Step = 0.1;
            app.ThHjorActiviySpinner.Limits = [0 5];
            app.SealesentiempoRealPanel = uipanel(app.UIFigure);
            app.SealesentiempoRealPanel.Title = 'Señales en tiempo Real';
            app.Canal1Label = uilabel(app.SealesentiempoRealPanel);
            app.Canal1Label.Text = 'Canal 1';
            app.HjorthActivityEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.HjorthActivityEditFieldLabel.Text = 'HjorthActivity ';
            app.HjorthActivityEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.HjorthComplexityEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.HjorthComplexityEditFieldLabel.Text = 'HjorthComplexity';
            app.HjorthComplexityEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.HjorthMobilityEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.HjorthMobilityEditFieldLabel.Text = 'HjorthMobility';
            app.HjorthMobilityEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.NormalizedFirstDifferenceEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.NormalizedFirstDifferenceEditFieldLabel.Text = 'NormalizedFirstDifference';
            app.NormalizedFirstDifferenceEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.KurtosisEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.KurtosisEditFieldLabel.Text = 'Kurtosis';
            app.KurtosisEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.MeanEnergyEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.MeanEnergyEditFieldLabel.Text = 'MeanEnergy';
            app.MeanEnergyEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.SkewnessEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.SkewnessEditFieldLabel.Text = 'Skewness';
            app.SkewnessEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.ShannonEntropyEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.ShannonEntropyEditFieldLabel.Text = 'ShannonEntropy';
            app.ShannonEntropyEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.PCALatentEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.PCALatentEditFieldLabel.Text = 'PCALatent';
            app.PCALatentEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.PCAMuEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.PCAMuEditFieldLabel.Text = 'PCAMu';
            app.PCAMuEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.figFpz = uiaxes(app.SealesentiempoRealPanel);
            title(app.figFpz, {'Canal 1 '; ''})
            xlabel(app.figFpz, 't (s)')
            ylabel(app.figFpz, 'uV')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Cabeza_Derecha.png';
            app.ClaseLabel = uilabel(app.UIFigure);
            app.ClaseLabel.Text = 'Clase';
            app.DesconectadoLampLabel = uilabel(app.UIFigure);
            app.DesconectadoLampLabel.Enable = 'off';
            app.DesconectadoLampLabel.Text = 'Desconectado';
            app.DesconectadoLamp = uilamp(app.UIFigure);
            app.DesconectadoLamp.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_EEG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end