classdef Interfaz_EMG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RegresarButton                 matlab.ui.control.Button
        BCIEMGUVGLabel                 matlab.ui.control.Label
        PaneldeControl                 matlab.ui.container.Panel
        ParametersPanel                matlab.ui.container.Panel
        SPEEDEditFieldLabel            matlab.ui.control.Label
        SPEEDEditField                 matlab.ui.control.NumericEditField
        COMDropDownLabel               matlab.ui.control.Label
        COMDropDown                    matlab.ui.control.DropDown
        ButtonsPanel                   matlab.ui.container.Panel
        STARTButton                    matlab.ui.control.Button
        STOPButton                     matlab.ui.control.Button
        LampGreen                      matlab.ui.control.Lamp
        LampRed                        matlab.ui.control.Lamp
        IniciarButton                  matlab.ui.control.Button
        TiempodemuestraEditFieldLabel  matlab.ui.control.Label
        TiempodemuestraEditField       matlab.ui.control.NumericEditField
        ThSpinnerLabel                 matlab.ui.control.Label
        ThSpinner                      matlab.ui.control.Spinner
        Lamp                           matlab.ui.control.Lamp
        Lamp_2                         matlab.ui.control.Lamp
        ConectarBitalinoSwitchLabel    matlab.ui.control.Label
        ConectarBitalinoSwitch         matlab.ui.control.Switch
        EmpezarStreamingSwitchLabel    matlab.ui.control.Label
        EmpezarStreamingSwitch         matlab.ui.control.Switch
        NombreBluetoothBitalinoEditFieldLabel  matlab.ui.control.Label
        NombreBluetoothBitalinoEditField  matlab.ui.control.EditField
        PararButton                    matlab.ui.control.Button
        EscogerEntrenamientoButton     matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        SealesentiempoRealPanel        matlab.ui.container.Panel
        Canal1Label                    matlab.ui.control.Label
        ZeroCrossingEditFieldLabel     matlab.ui.control.Label
        ZeroCrossingEditField          matlab.ui.control.NumericEditField
        MAVEditFieldLabel              matlab.ui.control.Label
        MAVEditField                   matlab.ui.control.NumericEditField
        KurtossisEditFieldLabel        matlab.ui.control.Label
        KurtossisEditField             matlab.ui.control.NumericEditField
        AverageEnergyEditFieldLabel    matlab.ui.control.Label
        AverageEnergyEditField         matlab.ui.control.NumericEditField
        VarianzaEditFieldLabel         matlab.ui.control.Label
        VarianzaEditField              matlab.ui.control.NumericEditField
        WilsonAmplitudEditFieldLabel   matlab.ui.control.Label
        WilsonAmplitudEditField        matlab.ui.control.NumericEditField
        MediaCuadraticaEditFieldLabel  matlab.ui.control.Label
        MediaCuadraticaEditField       matlab.ui.control.NumericEditField
        Lamp_3                         matlab.ui.control.Lamp
        EnpausaLabel                   matlab.ui.control.Label
        figFpz_2                       matlab.ui.control.UIAxes
        ClaseLabel                     matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        SinActividadLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        R17 = 0;
        COM = 0;
        Pausa = 0;
        SPEED = 0;
        flag_recibir = 0;
        Fs = 44100;
        Onda_Low = 7;
        Onda_High = 14;
        y1 = audioread('Click.wav');
        Bit=0;
        F_pb = filtro_pasa_banda(1000,20,450);    %Diseñar filtro pasa banda
        F_notch = filtro_rechaza_banda(1000);     %Diseñar filtro rechaza banda
        data_n=0;
        BanderaParaEmpezar=0;
        NombreBit=0;
        flag_recibir2 =0;
        T = table()
        Array_giro =[45,90,135,180,135,90,45,0,-45,-90,-135,-180,-135,-90,-45,0];
        Metros_CAREER = [0.2,0.4,0.6,0.4,0.2,0,-0.2,-0.4,-0.2,0]
        flag_recibir1=0;
        A=0;
        flag_terminar=0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LampGreen.Enable = 'off';
            app.LampRed.Enable = 'off';
            app.Lamp_2.Enable = 'off';
            app.Lamp_3.Enable = 'off';
            app.Lamp.Enable='off';
            app.STOPButton.Enable = 'off';
            app.T.HjorthActivity = zeros(0);
            app.T.NormalizedFirstDifference = zeros(0);
            app.T.HjorthComplexity = zeros(0);
            app.T.Kurtosis = zeros(0);
            app.T.MeanEnergy = zeros(0);
            app.T.Skewness = zeros(0);
            app.T.ShannonEntropy = zeros(0);
            app.T.HjorthMobility = zeros(0);
            app.T.PCALatent = zeros(0);
            app.T.PCAMu = zeros(0);
            app.T.Clase = zeros(0);
        end

        % Callback function
        function RegresarButtonPushed(app, event)
            Interfaz_Inicio
            delete(app)
        end

        % Callback function
        function ActivarStreamingSwitchValueChanged(app, event)


        end

        % Callback function
        function ButtonPushed(app, event)
            global R17
            q1 = [0; 0;90; 0; -45; 0];
            R17fKine(R17, q1);
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed2(app, event)
            clear all
            Interfaz_Inicio
            delete(app)
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            soundsc(app.y1,app.Fs);
            app.STARTButton.Enable = 'off';
            app.STOPButton.Enable ='on';
            app.LampGreen.Enable = 'on';
            app.LampRed.Enable = 'off';
            app.EnpausaLabel.Enable='off';
            app.SPEED = app.SPEEDEditField.Value
            
            %app.R17 = R17Init(app.COM, app.SPEED);
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.LampGreen.Enable = 'off';
            app.LampRed.Enable = 'on';
            app.STOPButton.Enable = 'off';
            app.STARTButton.Enable = 'on';
            
            R17Close(app.R17);
        end

        % Value changed function: COMDropDown
        function COMDropDownValueChanged(app, event)
            
            app.COM = app.COMDropDown.Value;
            % Para visualizar en el workspace
            assignin('base', 'com', app.COM);
        end

        % Callback function
        function Movement1ButtonPushed(app, event)
            
            q2 = [0; 0;deg2rad(90); 0; deg2rad(-45); 0];
            R17fKine(app.R17, q2);
        end

        % Callback function
        function Movement2ButtonPushed(app, event)
            
            q1 = [0; deg2rad(90);0; deg2rad(45); 0; 0];
            R17fKine(app.R17, q1);
        end

        % Value changed function: SPEEDEditField
        function SPEEDEditFieldValueChanged(app, event)
            
            
        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)


            %Extracción características


            app.Pausa=0;
            
            ContGiro = 0;
            giro = 0;
            
            
            ContMetros = 0;
            Metros = 0;
            
            time = 0;
            Th  = app.ThSpinner.Value;
            %ga = 0.4;
            b_act = 0;
            
            Tiempo_DMuestra = app.TiempodemuestraEditField.Value;
           
            app.flag_terminar=0;
            % Pause to acquire data for 20 seconds
            %pause(10);
            % Read the data from the device
            
            
            app.Lamp.Enable = 'on';
            
            
            while (app.flag_recibir2 ==1) && (app.flag_recibir1==1)&& (app.flag_terminar==0)
                
                while (time < Tiempo_DMuestra)      %la variable m determina el tiempoque nosotros asignaramos para llenar el buffer
                    pause(1)   % se le da una pausa de 1 segundo por cada vez que pase, para que se logre llenar el buffer en el tiempo que se requiere
                    time = time + 1 ;%variable que aumenta cada 1 segundo.
                    app.Lamp_3.Enable = 'on';
                end
                 
                data = read(app.Bit);% tomamos los ultimos valores en el buffer.
                app.Lamp_3.Enable = 'off';
                %n=n+1%contador para ingresar los datos a la tabla.
                time = 0; %se reinicia el contador para la siguiente vez que se llene el buffer
                EMG_raw = data(:,6); %se toma solo la columna de la senal EMG
                EMG = EMG_raw*(3.3/1024);%se convierte la senal a la escala de 3.3 V
                data_f = filter(app.F_pb, EMG');                          %Aplicar filtro pasa bandas
                app.data_n = filter(app.F_notch, data_f);                       %Aplicar filtro notch
                
                wt = cwt(app.data_n(300:end));
               
                Actividad = max(max(abs(wt)));
               
                
                app.ZeroCrossingEditField.Value = jZeroCrossing(app.data_n,0.34);
                app.MAVEditField.Value = jMeanAbsoluteDeviation(app.data_n);
                app.KurtossisEditField.Value = jKurtosis(app.data_n);
                app.AverageEnergyEditField.Value = jAverageEnergy(app.data_n);
                app.VarianzaEditField.Value = jVariance(app.data_n);
                app.WilsonAmplitudEditField.Value = jWillisonAmplitude(app.data_n,0.01);
                app.MediaCuadraticaEditField.Value = jRootMeanSquare(app.data_n);
                
                b_act = 0 ;
                app.T.ZC(1) = jZeroCrossing(app.data_n,0.1);
                app.T.MAV(1) = jMeanAbsoluteDeviation(app.data_n);
                app.T.Kurtosis(1) = jKurtosis(app.data_n);
                app.T.AverageE(1) = jAverageEnergy(app.data_n);
                app.T.Varianza(1) = jVariance(app.data_n);
                app.T.AmplitudWilson(1) = jWillisonAmplitude(app.data_n,0.1);
                app.T.MediaCuadratica(1) = jRootMeanSquare(app.data_n);
                
                
                if  Actividad > Th  &&  b_act == 0
                    %Activar bandera de inicio de actividad
                    b_act = 1;
                    app.SinActividadLabel.Text = "Se Detecto Actividad"
                end
                
                if (b_act ==1) && (app.Pausa==0)
                    
                    app.EnpausaLabel.Enable='off';
                    yfit = app.A.predictFcn(app.T);
                    switch yfit
                        case 'WristUp'
                            %                             ContGiro = ContGiro + 1;
                            %                             if ContGiro == 17
                            %                                 ContGiro =1 ;
                            %                             end
                            %                             giro = app.Array_giro(ContGiro) ;
                            %                             q2 = [Metros; deg2rad(giro);0; 0;0; 0];
                            %                             R17fKine(app.R17, q2);
                            
                            app.ClaseLabel.Text = "WristUp"
                            app.Image.ImageSource = imread("WristUp.jpg");
                        case 'HandUp'
                            %                             ContMetros = ContMetros +1;
                            %                             if ContMetros == 12
                            %                                 ContMetros =1;
                            %                             end
                            %                             Metros = app.Metros_CAREER(ContMetros);
                            %                             q2 = [Metros; deg2rad(giro);0; 0; 0; 0];
                            %                             R17fKine(app.R17, q2);
                            app.ClaseLabel.Text = "HandUp"
                            app.Image.ImageSource = imread("manita.jfif");
                    end
                    ylim(app.figFpz_2, [min(app.data_n) max(app.data_n)]);
                    Epocas = size(app.data_n,2) ; %se toma el tamano del buffer obtenido
                    L = Epocas; %longitud del vector epocas
                    
                    t=linspace(0, Epocas/1000, L);
                    h1 = plot(app.figFpz_2, t, app.data_n) %se plotea esa muestra
                    app.SinActividadLabel.Text = "Sin Actividad"
                end
                
                if app.Pausa ==1;
                    app.EnpausaLabel.Enable='on';
                    
                end
            end
            
        end

        % Callback function
        function ConectarBitalinoSwitchValueChanged(app, event)
    
            
        end

        % Value changed function: ConectarBitalinoSwitch
        function ConectarBitalinoSwitchValueChanged2(app, event)
            
            value = app.ConectarBitalinoSwitch.Value;
            
            if strcmp( value,'On')
                app.NombreBit = app.NombreBluetoothBitalinoEditField.Value
                app.Bit = Bitalino(char(app.NombreBit)); %Creamos la conexion con el dispositivo bitalino y esa conexion la volvemos un objeto.
                app.flag_recibir1=1;
                app.Lamp.Enable="on"
            else
                app.Lamp.Enable="off"
                app.flag_recibir1=0;
                
                
                clear all


            end
        end

        % Value changed function: EmpezarStreamingSwitch
        function EmpezarStreamingSwitchValueChanged(app, event)
            value = app.EmpezarStreamingSwitch.Value;
            
            if strcmp( value,'On')
                
                app.flag_recibir2 =1;
                startBackground(app.Bit)
                app.Lamp_2.Enable="on";
                
                
            else
                app.flag_recibir2 =0;
                stopBackground(app.Bit)
                app.Lamp_2.Enable="off";
            end


        end

        % Button pushed function: EscogerEntrenamientoButton
        function EscogerEntrenamientoButtonPushed(app, event)
            [file,folder]=uigetfile('*.mat');
            filename=fullfile(folder,file);
            s=load(filename);
            C= struct2cell(s);
            app.A= cell2mat(C);
            
        end

        % Button pushed function: PararButton
        function PararButtonPushed(app, event)
            app.flag_terminar=1;
            
            app.BanderaParaEmpezar=0;
            app.T = table();
            app.data_n=0;
        
            if app.Pausa == 1
                app.LarecoleccinestenPausaLabel.Visible = 'off';
                app.Pausa=0;
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Pausa=0;
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            app.Pausa=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RegresarButton = uibutton(app.UIFigure, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed2, true);
            app.RegresarButton.Icon = 'back.png';
            app.RegresarButton.Text = 'Regresar';
            app.BCIEMGUVGLabel = uilabel(app.UIFigure);
            app.BCIEMGUVGLabel.Text = 'BCI EMG - UVG';
            app.PaneldeControl = uipanel(app.UIFigure);
            app.PaneldeControl.Title = 'Panel de Control';
            app.ParametersPanel = uipanel(app.PaneldeControl);
            app.ParametersPanel.Title = 'Parameters Panel';
            app.SPEEDEditFieldLabel = uilabel(app.ParametersPanel);
            app.SPEEDEditFieldLabel.Text = 'SPEED';
            app.SPEEDEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SPEEDEditField.Limits = [5000 25000];
            app.SPEEDEditField.RoundFractionalValues = 'on';
            app.SPEEDEditField.ValueChangedFcn = createCallbackFcn(app, @SPEEDEditFieldValueChanged, true);
            app.SPEEDEditField.Value = 10000;
            app.COMDropDownLabel = uilabel(app.ParametersPanel);
            app.COMDropDownLabel.Text = 'COM';
            app.COMDropDown = uidropdown(app.ParametersPanel);
            app.COMDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9'};
            app.COMDropDown.ValueChangedFcn = createCallbackFcn(app, @COMDropDownValueChanged, true);
            app.COMDropDown.Value = 'COM1';
            app.ButtonsPanel = uipanel(app.PaneldeControl);
            app.ButtonsPanel.Title = 'Buttons Panel';
            app.STARTButton = uibutton(app.ButtonsPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.ButtonsPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.LampGreen = uilamp(app.ButtonsPanel);
            app.LampRed = uilamp(app.ButtonsPanel);
            app.IniciarButton = uibutton(app.PaneldeControl, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar ';
            app.TiempodemuestraEditFieldLabel = uilabel(app.PaneldeControl);
            app.TiempodemuestraEditFieldLabel.Text = 'Tiempo de muestra';
            app.TiempodemuestraEditField = uieditfield(app.PaneldeControl, 'numeric');
            app.TiempodemuestraEditField.Limits = [1 Inf];
            app.TiempodemuestraEditField.Value = 3;
            app.ThSpinnerLabel = uilabel(app.PaneldeControl);
            app.ThSpinnerLabel.Text = 'Th';
            app.ThSpinner = uispinner(app.PaneldeControl);
            app.ThSpinner.Step = 0.01;
            app.ThSpinner.Limits = [0 0.5];
            app.ThSpinner.Value = 0.2;
            app.Lamp = uilamp(app.PaneldeControl);
            app.Lamp_2 = uilamp(app.PaneldeControl);
            app.ConectarBitalinoSwitchLabel = uilabel(app.PaneldeControl);
            app.ConectarBitalinoSwitchLabel.Text = 'Conectar Bitalino';
            app.ConectarBitalinoSwitch = uiswitch(app.PaneldeControl, 'slider');
            app.ConectarBitalinoSwitch.ValueChangedFcn = createCallbackFcn(app, @ConectarBitalinoSwitchValueChanged2, true);
            app.EmpezarStreamingSwitchLabel = uilabel(app.PaneldeControl);
            app.EmpezarStreamingSwitchLabel.Text = 'Empezar Streaming';
            app.EmpezarStreamingSwitch = uiswitch(app.PaneldeControl, 'slider');
            app.EmpezarStreamingSwitch.ValueChangedFcn = createCallbackFcn(app, @EmpezarStreamingSwitchValueChanged, true);
            app.NombreBluetoothBitalinoEditFieldLabel = uilabel(app.PaneldeControl);
            app.NombreBluetoothBitalinoEditFieldLabel.Text = 'Nombre Bluetooth Bitalino';
            app.NombreBluetoothBitalinoEditField = uieditfield(app.PaneldeControl, 'text');
            app.NombreBluetoothBitalinoEditField.Value = 'BITalino-00-97';
            app.PararButton = uibutton(app.PaneldeControl, 'push');
            app.PararButton.ButtonPushedFcn = createCallbackFcn(app, @PararButtonPushed, true);
            app.PararButton.Text = 'Parar';
            app.EscogerEntrenamientoButton = uibutton(app.PaneldeControl, 'push');
            app.EscogerEntrenamientoButton.ButtonPushedFcn = createCallbackFcn(app, @EscogerEntrenamientoButtonPushed, true);
            app.EscogerEntrenamientoButton.Text = 'Escoger Entrenamiento';
            app.Button = uibutton(app.PaneldeControl, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Icon = 'BotonPausa.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.PaneldeControl, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'play.png';
            app.Button_2.Text = '';
            app.SealesentiempoRealPanel = uipanel(app.UIFigure);
            app.SealesentiempoRealPanel.Title = 'Señales en tiempo Real';
            app.Canal1Label = uilabel(app.SealesentiempoRealPanel);
            app.Canal1Label.Text = 'Canal 1';
            app.ZeroCrossingEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.ZeroCrossingEditFieldLabel.Text = 'Zero Crossing';
            app.ZeroCrossingEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.MAVEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.MAVEditFieldLabel.Text = 'MAV';
            app.MAVEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.KurtossisEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.KurtossisEditFieldLabel.Text = 'Kurtossis';
            app.KurtossisEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.AverageEnergyEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.AverageEnergyEditFieldLabel.Text = 'Average Energy';
            app.AverageEnergyEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.VarianzaEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.VarianzaEditFieldLabel.Text = 'Varianza';
            app.VarianzaEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.WilsonAmplitudEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.WilsonAmplitudEditFieldLabel.Text = 'Wilson Amplitud';
            app.WilsonAmplitudEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.MediaCuadraticaEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.MediaCuadraticaEditFieldLabel.Text = 'Media Cuadratica';
            app.MediaCuadraticaEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.Lamp_3 = uilamp(app.SealesentiempoRealPanel);
            app.EnpausaLabel = uilabel(app.SealesentiempoRealPanel);
            app.EnpausaLabel.Text = 'En pausa';
            app.figFpz_2 = uiaxes(app.SealesentiempoRealPanel);
            title(app.figFpz_2, {'Canal 1 '; ''})
            xlabel(app.figFpz_2, 't (s)')
            ylabel(app.figFpz_2, 'V')
            app.ClaseLabel = uilabel(app.UIFigure);
            app.ClaseLabel.Text = 'Clase';
            app.Image = uiimage(app.UIFigure);
            app.SinActividadLabel = uilabel(app.UIFigure);
            app.SinActividadLabel.Text = 'Sin Actividad';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_EMG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end