classdef Interfaz_EEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RegresarButton              matlab.ui.control.Button
        BCIEEGUVGLabel              matlab.ui.control.Label
        PaneldeControl              matlab.ui.container.Panel
        TiemposSliderLabel          matlab.ui.control.Label
        TiemposSlider               matlab.ui.control.Slider
        EmpezarGrabacinSwitchLabel  matlab.ui.control.Label
        EmpezarGrabacinSwitch       matlab.ui.control.Switch
        Lamp                        matlab.ui.control.Lamp
        SealesentiempoRealPanel     matlab.ui.container.Panel
        figFpz_2                    matlab.ui.control.UIAxes
        figFpz                      matlab.ui.control.UIAxes
        ClaseActualEditFieldLabel   matlab.ui.control.Label
        ClaseActualEditField        matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
            Interfaz_Inicio
            delete(app)
        end

        % Value changed function: EmpezarGrabacinSwitch
        function EmpezarGrabacinSwitchValueChanged(app, event)
            value = app.EmpezarGrabacinSwitch.Value;
            %movegui('northwest');
            %load('data_mod')%load('data_1')
            %load('trainedModelSVM3.mat')
            %load('robotData.mat')
            %load('ur5Data.mat')
            %load('pumaData.mat')
            largoBuffer = 1;
            seleccion = app.TiemposSlider.Value;
            Epocas = round(seleccion)*100;%3000
            L = largoBuffer*Epocas;
            data = zeros(4,L);
            etiquetasSVM = zeros(757,1);
            contEpocs = 1;
            t=linspace(0, Epocas/100, L);
            canal_1 = zeros(1,L);                   %Almacenar todas las corridas canal 1
            canal_2 = zeros(1,L);                   %Almacenar todas las corridas canal 2
                
            flag=0;
            
            i=0;
          
          
            if strcmp( value,'On')
            %%Serial****************************************************
            lec=1;
                 %if ~isempty(instrfind);
                  %fclose(instrfind);
                  %delete(instrfind);
                  %clear s
                 %end
                 
                %s = serial('COM5', 'BaudRate',115200);
                %fopen(s);
                app.Lamp.Color = "g"
            %***********************************************************  
            
                flag =1;
                
                h1 = plot(app.figFpz, t, zeros(1,L))
                h2 = plot(app.figFpz_2, t, zeros(1,L))
  
                
                ylim(app.figFpz, [min(canal_1) max(canal_1)]);
                ylim(app.figPz, [min(canal_2) max(canal_2)]);
                
                while flag==1
                    %lec = fscanf(s,'%d');
                    if lec==1
                     
                        
                        if strcmp( value,'Off')
                            break
                        end
                        
                        i = i+1;
                        n = mod(i,Epocas);
                        
                        if n == 0
                            n = Epocas;
                        end
                        
                        data(:,n)=[canal_1(i);
                                    canal_2(i);];
                                
                        %*****************Figuras
                   
                        h1.YData(n) = canal_1(i);
                        h2.YData(n) = canal_2(i);
                        drawnow limitrate
                        
                        if n == Epocas
                        
                            media = [mean(abs(data(1,:))), metricas(data(1,:),0,0), MMD(data(1,:),Epocas), bandpower(data(1,:)), kurtosis(data(1,:)),...
                                     mean(abs(data(2,:))), metricas(data(2,:),0,0), MMD(data(2,:),Epocas), bandpower(data(2,:)), kurtosis(data(2,:)),...
                                     mean(abs(data(3,:))), metricas(data(3,:),0,0), MMD(data(1,:),Epocas), bandpower(data(3,:)), kurtosis(data(3,:)),...
                                     mean(abs(data(4,:)))];
                            %etiquetasSVM(contEpocs)= trainedModelSVM3.predictFcn(media);
                            
                            %etiquetasSVM(contEpocs)
%                             app.MAVEditField.Value = media(1,1);
%                             app.ZCEditField.Value = media(1,2);
%                             app.MMDEditField.Value = media(1,3);
%                             app.BPEditField.Value = media(1,4);
%                             app.KURTEditField.Value = media(1,5);
%                             
%                             app.MAVEditField_2.Value = media(1,6);
%                             app.ZCEditField_2.Value = media(1,7);
%                             app.MMDEditField_2.Value = media(1,8);
%                             app.BPEditField_2.Value = media(1,9);
%                             app.KURTEditField_2.Value = media(1,10);
%                             
%                             app.MAVEditField_3.Value = media(1,11);
%                             app.ZCEditField_3.Value = media(1,12);
%                             app.MMDEditField_3.Value = media(1,13);
%                             app.BPEditField_3.Value = media(1,14);
%                             app.KURTEditField_3.Value = media(1,15);
%                             
%                             app.MAVEditField_4.Value = media(1,16);
                            
                            %Robot
%                             if media(1,1)>5 &&  media(1,6)>5
%                                 app.ClaseActualEditField.Value = etiquetasSVM(contEpocs);
%                                 graficar(app, etiquetasSVM(contEpocs),1)
%                                 
%                             else
%                                app.ClaseActualEditField.Value = 0;
%                             end
                            
                           % r17.plot(Qtot{1,etiquetasSVM(contEpocs)})
       
                            contEpocs = contEpocs+1;
                            
                        end
                    end
                end
                   
            else
                flag = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RegresarButton = uibutton(app.UIFigure, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Icon = 'back.png';
            app.RegresarButton.Text = 'Regresar';
            app.BCIEEGUVGLabel = uilabel(app.UIFigure);
            app.BCIEEGUVGLabel.Text = 'BCI EEG - UVG';
            app.PaneldeControl = uipanel(app.UIFigure);
            app.PaneldeControl.Title = 'Panel de Control';
            app.TiemposSliderLabel = uilabel(app.PaneldeControl);
            app.TiemposSliderLabel.Text = 'Tiempo (s)';
            app.TiemposSlider = uislider(app.PaneldeControl);
            app.TiemposSlider.Limits = [1 30];
            app.TiemposSlider.MajorTicks = [1 5 10 15 20 25 30];
            app.TiemposSlider.Value = 1;
            app.EmpezarGrabacinSwitchLabel = uilabel(app.PaneldeControl);
            app.EmpezarGrabacinSwitchLabel.Text = 'Empezar Grabación';
            app.EmpezarGrabacinSwitch = uiswitch(app.PaneldeControl, 'slider');
            app.EmpezarGrabacinSwitch.ValueChangedFcn = createCallbackFcn(app, @EmpezarGrabacinSwitchValueChanged, true);
            app.Lamp = uilamp(app.PaneldeControl);
            app.SealesentiempoRealPanel = uipanel(app.UIFigure);
            app.SealesentiempoRealPanel.Title = 'Señales en tiempo Real';
            app.figFpz_2 = uiaxes(app.SealesentiempoRealPanel);
            title(app.figFpz_2, {'Canal 2 '; ''})
            xlabel(app.figFpz_2, 't (s)')
            ylabel(app.figFpz_2, 'uV')
            app.figFpz = uiaxes(app.SealesentiempoRealPanel);
            title(app.figFpz, {'Canal 1 '; ''})
            xlabel(app.figFpz, 't (s)')
            ylabel(app.figFpz, 'uV')
            app.ClaseActualEditFieldLabel = uilabel(app.UIFigure);
            app.ClaseActualEditFieldLabel.Text = 'Clase Actual';
            app.ClaseActualEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_EEG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end