classdef Interfaz_EMG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RegresarButton                  matlab.ui.control.Button
        BCIEMGUVGLabel                  matlab.ui.control.Label
        PaneldeControl                  matlab.ui.container.Panel
        ActivarStreamingSwitchLabel     matlab.ui.control.Label
        ActivarStreamingSwitch          matlab.ui.control.Switch
        ParametersPanel                 matlab.ui.container.Panel
        SPEEDEditFieldLabel             matlab.ui.control.Label
        SPEEDEditField                  matlab.ui.control.NumericEditField
        COMDropDownLabel                matlab.ui.control.Label
        COMDropDown                     matlab.ui.control.DropDown
        ButtonsPanel                    matlab.ui.container.Panel
        STARTButton                     matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        LampGreen                       matlab.ui.control.Lamp
        LampRed                         matlab.ui.control.Lamp
        IniciarButton                   matlab.ui.control.Button
        TiempodemuestraEditFieldLabel   matlab.ui.control.Label
        TiempodemuestraEditField        matlab.ui.control.NumericEditField
        FechaLabel                      matlab.ui.control.Label
        FechaEditField                  matlab.ui.control.EditField
        HoraLabel                       matlab.ui.control.Label
        HoraEditField                   matlab.ui.control.EditField
        ConectarBitalinoSwitchLabel     matlab.ui.control.Label
        ConectarBitalinoSwitch          matlab.ui.control.Switch
        ThSpinnerLabel                  matlab.ui.control.Label
        ThSpinner                       matlab.ui.control.Spinner
        Lamp_2                          matlab.ui.control.Lamp
        SealesentiempoRealPanel         matlab.ui.container.Panel
        TiempoEditFieldLabel            matlab.ui.control.Label
        TiempoEditField                 matlab.ui.control.EditField
        Lamp                            matlab.ui.control.Lamp
        ActividadLabel                  matlab.ui.control.Label
        figFpz                          matlab.ui.control.UIAxes
        figFpz_2                        matlab.ui.control.UIAxes
        DesplieguedeCaractersticasentiemporealPanel  matlab.ui.container.Panel
        Canal1Label                     matlab.ui.control.Label
        ZeroCrossingEditFieldLabel      matlab.ui.control.Label
        ZeroCrossingEditField           matlab.ui.control.NumericEditField
        MAVEditFieldLabel               matlab.ui.control.Label
        MAVEditField                    matlab.ui.control.NumericEditField
        KurtossisEditFieldLabel         matlab.ui.control.Label
        KurtossisEditField              matlab.ui.control.NumericEditField
        AverageEnergyEditFieldLabel     matlab.ui.control.Label
        AverageEnergyEditField          matlab.ui.control.NumericEditField
        Canal2Label                     matlab.ui.control.Label
        VarianzaEditFieldLabel          matlab.ui.control.Label
        VarianzaEditField               matlab.ui.control.NumericEditField
        WilsonAmplitudEditFieldLabel    matlab.ui.control.Label
        WilsonAmplitudEditField         matlab.ui.control.NumericEditField
        MediaCuadraticaEditFieldLabel   matlab.ui.control.Label
        MediaCuadraticaEditField        matlab.ui.control.NumericEditField
        ZeroCrossingEditField_2Label    matlab.ui.control.Label
        ZeroCrossingEditField_2         matlab.ui.control.NumericEditField
        MAVEditField_2Label             matlab.ui.control.Label
        MAVEditField_2                  matlab.ui.control.NumericEditField
        KurtossisEditField_2Label       matlab.ui.control.Label
        KurtossisEditField_2            matlab.ui.control.NumericEditField
        AverageEnergyEditField_2Label   matlab.ui.control.Label
        AverageEnergyEditField_2        matlab.ui.control.NumericEditField
        VarianzaEditField_2Label        matlab.ui.control.Label
        VarianzaEditField_2             matlab.ui.control.NumericEditField
        WilsonAmplitudEditField_2Label  matlab.ui.control.Label
        WilsonAmplitudEditField_2       matlab.ui.control.NumericEditField
        MediaCuadraticaEditField_2Label  matlab.ui.control.Label
        MediaCuadraticaEditField_2      matlab.ui.control.NumericEditField
        ClaseLabel                      matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        SinActividadLabel               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function RegresarButtonPushed(app, event)
            Interfaz_Inicio
            delete(app)
        end

        % Value changed function: ActivarStreamingSwitch
        function ActivarStreamingSwitchValueChanged(app, event)
            global value
            global flag
            value = app.ActivarStreamingSwitch.Value
            global b
            global F_pb
            global F_notch
            global Epocas
            global Tiempo_DMuestra
            global data_n
            global h1
            global c
            
            
            if strcmp( value,'On')
                
                flag =1;
                c = clock;
                app.FechaEditField.Value =  string(c(3))+"/" + string(c(2)) + "/" +  string(c(1))
                app.HoraEditField.Value  =  string(c(4))+":" + string(c(5))
                F_pb = filtro_pasa_banda(1000,20,450);    %Diseñar filtro pasa banda
                F_notch = filtro_rechaza_banda(1000);     %Diseñar filtro rechaza banda


            else
                flag = 0;
                b.stopBackground
            end


        end

        % Callback function
        function ButtonPushed(app, event)
            global R17
            q1 = [0; 0;90; 0; -45; 0];
            R17fKine(R17, q1);
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed2(app, event)
            clear all
            Interfaz_Inicio
            delete(app)
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            global R17
            global COM
            global SPEED
            SPEED = app.SPEEDEditField.Value
            app.LampGreen.Enable = 'on';
            app.LampRed.Enable = 'off';
            app.STOPButton.Enable = 'on';
            app.STARTButton.Enable = 'off';
            R17 = R17Init(COM, SPEED);
            assignin('base', 'R17', R17);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.LampGreen.Enable = 'off';
            app.LampRed.Enable = 'on';
            app.STOPButton.Enable = 'off';
            app.STARTButton.Enable = 'on';
            global R17
            %             q = [0; 0; 0; 0; 0; 0];
            %             app.fK = R17fKine(app.R17, q);
            %             R17Command(app.R17, 'HOME');
            %             pause(10);
            R17Close(R17);
        end

        % Value changed function: COMDropDown
        function COMDropDownValueChanged(app, event)
            global COM
            COM = app.COMDropDown.Value;
            % Para visualizar en el workspace
            assignin('base', 'com', COM);
        end

        % Callback function
        function Movement1ButtonPushed(app, event)
            global R17
            q2 = [0; 0;deg2rad(90); 0; deg2rad(-45); 0];
            R17fKine(R17, q2);
        end

        % Callback function
        function Movement2ButtonPushed(app, event)
            global R17
            q1 = [0; deg2rad(90);0; deg2rad(45); 0; 0];
            R17fKine(R17, q1);
        end

        % Value changed function: SPEEDEditField
        function SPEEDEditFieldValueChanged(app, event)
            
            
        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)


            %Extracción características
            global Data
            global data_n
            global Tiempo_DMuestra
            global h1
            global time
            global value
            global b
            global flag
            global F_pb
            global F_notch
            global giro 
            global Metros
            global R17
            Array_giro =[45,90,135,180,135,90,45,0,-45,-90,-135,-180,-135,-90,-45,0];
            ContGiro = 0; 
            giro = 0; 
            
            Metros_CAREER = [0.2,0.4,0.6,0.4,0.2,0,-0.2,-0.4,-0.2,0] 
            ContMetros = 0;
            Metros = 0;
            
            time = 0;
            Th  = app.ThSpinner.Value;
            %ga = 0.4;
            b_act = 0;
            
            Tiempo_DMuestra = app.TiempodemuestraEditField.Value;
            volt = 0;
            
            % Pause to acquire data for 20 seconds
            %pause(10);
            % Read the data from the device
            
            load('MyTraining.mat')
            load('Data_Mientrenamiento.mat')
            app.Lamp.Enable = 'on';
            
            b.startBackground;
            while flag ==1
                
                while (time < Tiempo_DMuestra)      %la variable m determina el tiempoque nosotros asignaramos para llenar el buffer
                    pause(0.98)   % se le da una pausa de 1 segundo por cada vez que pase, para que se logre llenar el buffer en el tiempo que se requiere
                    time = time + 1 %variable que aumenta cada 1 segundo.
                    app.TiempoEditField.Value = string(time)
                end
                app.TiempoEditField.Value = string(0)
                data = b.read;% tomamos los ultimos valores en el buffer.
                %n=n+1%contador para ingresar los datos a la tabla.
                time = 0; %se reinicia el contador para la siguiente vez que se llene el buffer
                EMG_raw = data(:,6); %se toma solo la columna de la senal EMG
                EMG = EMG_raw*(3.3/1024);%se convierte la senal a la escala de 3.3 V
                data_f = filter(F_pb, EMG');                          %Aplicar filtro pasa bandas
                data_n = filter(F_notch, data_f);                       %Aplicar filtro notch
                volt = max(data_n)
                if strcmp( value,'Off')
                    b.stopBackground
                    
                    break
                end
                wt = cwt(data_n(300:end));
                volt = max(data_n);
                Actividad = max(max(abs(wt)));
                app.ActividadLabel.Text = string(Actividad);
                
                app.ZeroCrossingEditField.Value = jZeroCrossing(data_n,0.34);
                app.MAVEditField.Value = jMeanAbsoluteDeviation(data_n);
                app.KurtossisEditField.Value = jKurtosis(data_n);
                app.AverageEnergyEditField.Value = jAverageEnergy(data_n);
                app.VarianzaEditField.Value = jVariance(data_n);
                app.WilsonAmplitudEditField.Value = jWillisonAmplitude(data_n,0.01);
                app.MediaCuadraticaEditField.Value = jRootMeanSquare(data_n);
                
                b_act = 0 ;
                Data.ZC(1) = jZeroCrossing(data_n,0.34);
                Data.MAV(1) = jMeanAbsoluteDeviation(data_n);
                Data.Kurtosis(1) = jKurtosis(data_n);
                Data.AverageE(1) = jAverageEnergy(data_n);
                Data.Varianza(1) = jVariance(data_n);
                Data.AmplitudWilson(1) = jWillisonAmplitude(data_n,0.01);
                Data.MediaCuadratica(1) = jRootMeanSquare(data_n);
                
                
                if  Actividad > Th  &&  b_act == 0   
                    %Activar bandera de inicio de actividad
                    b_act = 1;
                    app.SinActividadLabel.Text = "Se Detecto Actividad"
                end
                
                if b_act ==1
                    

                    yfit = MyTraining.predictFcn(Data)
                    switch yfit
                        case 'WristUp'
                            ContGiro = ContGiro + 1;
                            if ContGiro == 17
                                ContGiro =1 ;
                            end
                            giro = Array_giro(ContGiro) ;
                            q2 = [Metros; deg2rad(giro);0; 0;0; 0];
                            R17fKine(R17, q2);
                            
                            app.ClaseLabel.Text = "WristUp"
                            app.Image.ImageSource = imread("WristUp.jpg");
                        case 'HandUp'
                            ContMetros = ContMetros +1;
                            if ContMetros == 12
                                ContMetros =1;
                            end
                            Metros = Metros_CAREER(ContMetros);
                            q2 = [Metros; deg2rad(giro);0; 0; 0; 0];
                            R17fKine(R17, q2);
                            app.ClaseLabel.Text = "HandUp"
                            app.Image.ImageSource = imread("manita.jfif");
                    end
                    ylim(app.figFpz_2, [min(data_n) max(data_n)]);
                Epocas = size(data_n,2) ; %se toma el tamano del buffer obtenido
                L = Epocas; %longitud del vector epocas
                
                t=linspace(0, Epocas/1000, L);
                h1 = plot(app.figFpz_2, t, data_n) %se plotea esa muestra
                app.SinActividadLabel.Text = "Sin Actividad"
                end
                
                
            end
            
        end

        % Value changed function: ConectarBitalinoSwitch
        function ConectarBitalinoSwitchValueChanged(app, event)
            value = app.ConectarBitalinoSwitch.Value;
            global b
            app.Lamp.Enable = 'off';
            
            if strcmp( value,'On')
                
                
                b = Bitalino;
                app.Lamp_2.Enable = 'on';


            else
                flag = 0;
                delete(b);
                
                app.Lamp_2.Enable = 'off';
                
                
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RegresarButton = uibutton(app.UIFigure, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed2, true);
            app.RegresarButton.Icon = 'back.png';
            app.RegresarButton.Text = 'Regresar';
            app.BCIEMGUVGLabel = uilabel(app.UIFigure);
            app.BCIEMGUVGLabel.Text = 'BCI EMG - UVG';
            app.PaneldeControl = uipanel(app.UIFigure);
            app.PaneldeControl.Title = 'Panel de Control';
            app.ActivarStreamingSwitchLabel = uilabel(app.PaneldeControl);
            app.ActivarStreamingSwitchLabel.Text = 'Activar Streaming';
            app.ActivarStreamingSwitch = uiswitch(app.PaneldeControl, 'slider');
            app.ActivarStreamingSwitch.ValueChangedFcn = createCallbackFcn(app, @ActivarStreamingSwitchValueChanged, true);
            app.ParametersPanel = uipanel(app.PaneldeControl);
            app.ParametersPanel.Title = 'Parameters Panel';
            app.SPEEDEditFieldLabel = uilabel(app.ParametersPanel);
            app.SPEEDEditFieldLabel.Text = 'SPEED';
            app.SPEEDEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SPEEDEditField.Limits = [5000 25000];
            app.SPEEDEditField.RoundFractionalValues = 'on';
            app.SPEEDEditField.ValueChangedFcn = createCallbackFcn(app, @SPEEDEditFieldValueChanged, true);
            app.SPEEDEditField.Value = 10000;
            app.COMDropDownLabel = uilabel(app.ParametersPanel);
            app.COMDropDownLabel.Text = 'COM';
            app.COMDropDown = uidropdown(app.ParametersPanel);
            app.COMDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9'};
            app.COMDropDown.ValueChangedFcn = createCallbackFcn(app, @COMDropDownValueChanged, true);
            app.COMDropDown.Value = 'COM1';
            app.ButtonsPanel = uipanel(app.PaneldeControl);
            app.ButtonsPanel.Title = 'Buttons Panel';
            app.STARTButton = uibutton(app.ButtonsPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.ButtonsPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.LampGreen = uilamp(app.ButtonsPanel);
            app.LampRed = uilamp(app.ButtonsPanel);
            app.IniciarButton = uibutton(app.PaneldeControl, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar ';
            app.TiempodemuestraEditFieldLabel = uilabel(app.PaneldeControl);
            app.TiempodemuestraEditFieldLabel.Text = 'Tiempo de muestra';
            app.TiempodemuestraEditField = uieditfield(app.PaneldeControl, 'numeric');
            app.TiempodemuestraEditField.Limits = [1 Inf];
            app.TiempodemuestraEditField.Value = 5;
            app.FechaLabel = uilabel(app.PaneldeControl);
            app.FechaLabel.Text = 'Fecha:';
            app.FechaEditField = uieditfield(app.PaneldeControl, 'text');
            app.HoraLabel = uilabel(app.PaneldeControl);
            app.HoraLabel.Text = 'Hora:';
            app.HoraEditField = uieditfield(app.PaneldeControl, 'text');
            app.ConectarBitalinoSwitchLabel = uilabel(app.PaneldeControl);
            app.ConectarBitalinoSwitchLabel.Text = 'Conectar Bitalino';
            app.ConectarBitalinoSwitch = uiswitch(app.PaneldeControl, 'slider');
            app.ConectarBitalinoSwitch.ValueChangedFcn = createCallbackFcn(app, @ConectarBitalinoSwitchValueChanged, true);
            app.ThSpinnerLabel = uilabel(app.PaneldeControl);
            app.ThSpinnerLabel.Text = 'Th';
            app.ThSpinner = uispinner(app.PaneldeControl);
            app.ThSpinner.Step = 0.01;
            app.ThSpinner.Limits = [0 0.5];
            app.ThSpinner.Value = 0.3;
            app.Lamp_2 = uilamp(app.PaneldeControl);
            app.Lamp_2.Enable = 'off';
            app.SealesentiempoRealPanel = uipanel(app.UIFigure);
            app.SealesentiempoRealPanel.Title = 'Señales en tiempo Real';
            app.TiempoEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.TiempoEditFieldLabel.Text = 'Tiempo';
            app.TiempoEditField = uieditfield(app.SealesentiempoRealPanel, 'text');
            app.Lamp = uilamp(app.SealesentiempoRealPanel);
            app.ActividadLabel = uilabel(app.SealesentiempoRealPanel);
            app.ActividadLabel.Text = 'Actividad ';
            app.figFpz = uiaxes(app.SealesentiempoRealPanel);
            title(app.figFpz, {'Canal 2 '; ''})
            xlabel(app.figFpz, 't (s)')
            ylabel(app.figFpz, 'V')
            app.figFpz_2 = uiaxes(app.SealesentiempoRealPanel);
            title(app.figFpz_2, {'Canal 1 '; ''})
            xlabel(app.figFpz_2, 't (s)')
            ylabel(app.figFpz_2, 'V')
            app.DesplieguedeCaractersticasentiemporealPanel = uipanel(app.UIFigure);
            app.DesplieguedeCaractersticasentiemporealPanel.Title = 'Despliegue de Características en tiempo real ';
            app.Canal1Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.Canal1Label.Text = 'Canal 1';
            app.ZeroCrossingEditFieldLabel = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.ZeroCrossingEditFieldLabel.Text = 'Zero Crossing';
            app.ZeroCrossingEditField = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.MAVEditFieldLabel = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.MAVEditFieldLabel.Text = 'MAV';
            app.MAVEditField = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.KurtossisEditFieldLabel = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.KurtossisEditFieldLabel.Text = 'Kurtossis';
            app.KurtossisEditField = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.AverageEnergyEditFieldLabel = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.AverageEnergyEditFieldLabel.Text = 'Average Energy';
            app.AverageEnergyEditField = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.Canal2Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.Canal2Label.Text = 'Canal 2';
            app.VarianzaEditFieldLabel = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.VarianzaEditFieldLabel.Text = 'Varianza';
            app.VarianzaEditField = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.WilsonAmplitudEditFieldLabel = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.WilsonAmplitudEditFieldLabel.Text = 'Wilson Amplitud';
            app.WilsonAmplitudEditField = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.MediaCuadraticaEditFieldLabel = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.MediaCuadraticaEditFieldLabel.Text = 'Media Cuadratica';
            app.MediaCuadraticaEditField = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.ZeroCrossingEditField_2Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.ZeroCrossingEditField_2Label.Text = 'Zero Crossing';
            app.ZeroCrossingEditField_2 = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.MAVEditField_2Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.MAVEditField_2Label.Text = 'MAV';
            app.MAVEditField_2 = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.KurtossisEditField_2Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.KurtossisEditField_2Label.Text = 'Kurtossis';
            app.KurtossisEditField_2 = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.AverageEnergyEditField_2Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.AverageEnergyEditField_2Label.Text = 'Average Energy';
            app.AverageEnergyEditField_2 = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.VarianzaEditField_2Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.VarianzaEditField_2Label.Text = 'Varianza';
            app.VarianzaEditField_2 = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.WilsonAmplitudEditField_2Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.WilsonAmplitudEditField_2Label.Text = 'Wilson Amplitud';
            app.WilsonAmplitudEditField_2 = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.MediaCuadraticaEditField_2Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.MediaCuadraticaEditField_2Label.Text = 'Media Cuadratica';
            app.MediaCuadraticaEditField_2 = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.ClaseLabel = uilabel(app.UIFigure);
            app.ClaseLabel.Text = 'Clase';
            app.Image = uiimage(app.UIFigure);
            app.SinActividadLabel = uilabel(app.UIFigure);
            app.SinActividadLabel.Text = 'Sin Actividad';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_EMG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end