classdef R17FK_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        ForwardKinematicsTab       matlab.ui.container.Tab
        JointsPanel                matlab.ui.container.Panel
        CAREERSliderLabel          matlab.ui.control.Label
        CAREERSlider               matlab.ui.control.Slider
        WAISTSliderLabel           matlab.ui.control.Label
        WAISTSlider                matlab.ui.control.Slider
        SHOULDERSliderLabel        matlab.ui.control.Label
        SHOULDERSlider             matlab.ui.control.Slider
        ELBOWSliderLabel           matlab.ui.control.Label
        ELBOWSlider                matlab.ui.control.Slider
        HANDSliderLabel            matlab.ui.control.Label
        HANDSlider                 matlab.ui.control.Slider
        WRISTSliderLabel           matlab.ui.control.Label
        WRISTSlider                matlab.ui.control.Slider
        CAREEREditField            matlab.ui.control.NumericEditField
        WAISTEditField             matlab.ui.control.NumericEditField
        SHOULDEREditField          matlab.ui.control.NumericEditField
        ELBOWEditField             matlab.ui.control.NumericEditField
        HANDEditField              matlab.ui.control.NumericEditField
        WRISTEditField             matlab.ui.control.NumericEditField
        ParametersPanel            matlab.ui.container.Panel
        SPEEDEditFieldLabel        matlab.ui.control.Label
        SPEEDEditField             matlab.ui.control.NumericEditField
        COMDropDownLabel           matlab.ui.control.Label
        COMDropDown                matlab.ui.control.DropDown
        ButtonsPanel               matlab.ui.container.Panel
        STARTButton                matlab.ui.control.Button
        STOPButton                 matlab.ui.control.Button
        SEND_FK_Button             matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        LampGreen                  matlab.ui.control.Lamp
        Label_2                    matlab.ui.control.Label
        LampRed                    matlab.ui.control.Lamp
        InverseKinematicsTab       matlab.ui.container.Tab
        CartesianCoordinatesPanel  matlab.ui.container.Panel
        XEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        ZEditField                 matlab.ui.control.NumericEditField
        mmLabel                    matlab.ui.control.Label
        mmLabel_2                  matlab.ui.control.Label
        mmLabel_3                  matlab.ui.control.Label
        gradosLabel                matlab.ui.control.Label
        ROLLEditFieldLabel         matlab.ui.control.Label
        ROLLEditField              matlab.ui.control.NumericEditField
        gradosLabel_2              matlab.ui.control.Label
        PITCHEditFieldLabel        matlab.ui.control.Label
        PITCHEditField             matlab.ui.control.NumericEditField
        gradosLabel_3              matlab.ui.control.Label
        YAWEditFieldLabel          matlab.ui.control.Label
        YAWEditField               matlab.ui.control.NumericEditField
        SEND_IK_Button             matlab.ui.control.Button
        UIAxesWorkspaceEF          matlab.ui.control.UIAxes
        RoutesTab                  matlab.ui.container.Tab
        RoutetablepanelPanel       matlab.ui.container.Panel
        UITableRoutes              matlab.ui.control.Table
        ResettableButton           matlab.ui.control.Button
        RouteoptionspanelPanel     matlab.ui.container.Panel
        RoutelinesSpinnerLabel     matlab.ui.control.Label
        RoutelinesSpinner          matlab.ui.control.Spinner
        CreaterouteButton          matlab.ui.control.Button
        EraserouteButton           matlab.ui.control.Button
        PlayroutepanelPanel        matlab.ui.container.Panel
        RunrouteButton             matlab.ui.control.Button
        GotolineButton             matlab.ui.control.Button
        RoutelineDropDownLabel     matlab.ui.control.Label
        RoutelineDropDown          matlab.ui.control.DropDown
        RouterunmodeButtonGroup    matlab.ui.container.ButtonGroup
        SegmentedButton            matlab.ui.control.RadioButton
        ContinuousButton           matlab.ui.control.RadioButton
        SmoothButton               matlab.ui.control.RadioButton
        RegresarButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        % Variables globales        
        
        %------SerialLink-----
        R17_PC % R17 SerialLink
        
        %----Configuración----
        q1 = 0; % Career
        q2 = 0; % Waist
        q3 = 0; % Shoulder
        q4 = 0; % Elbow
        q5 = 0; % Hand 
        q6 = 0; % Wrist
        
        workspace % Workspace del plot para SerialLink
        
        %----Comunicación serial con R17----
        COM % Puerto serial en uso
        SPEED % Velocidad del R17
        R17 % Objeto serial
        fK % Respuesta de R17fKine
        iK % Respuesta de R17iKine
        
        %----Coordenadas cartesianas----
        x = 0;% Coordenada en x
        y = 0; % Coordenada en y
        z = 750; % Coordenada en z
        ROLL = 0;
        PITCH = -90;
        YAW = 0;
        
        %------Rutinas-------
        RouteMatrix; % Matriz con el contenido de la tabla de la ruta 
        RouteLines; % Número de lineas en la ruta
        RouteMode; % Puede ser segmented, continuous o smooth
        DropDownLineItems; % Array string para lo items del drop down
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Modelado del R17 como un SerialLink de Peter Corke.
            % Matriz de DH
            q = [0 0 0 0 0 0];
            DH = [           0,   q(1),     0, -pi/2;
                q(2) + pi/2, -0.355,     0, -pi/2;
                q(3) + pi/2,      0, 0.375,     0;
                q(4),      0, 0.375,     0;
                q(5) - pi/2,      0,     0, -pi/2;
                q(6) + pi/2,      0,     0,     0];
            
            T_base = [ 1,  0, 0,      0;
                0,  0, 1,      0;
                0, -1, 0, -0.355;
                0,  0, 0,      1 ];
            
            % Parámetros de los eslabones
            offset1 = 0;
            theta1 = DH(1,1);
            a1 = DH(1,3);
            alpha1 = DH(1,4);
            qlim1 = [0, 1.2];
            L1 = Prismatic('theta', theta1, 'a', a1, 'alpha', alpha1, 'offset', offset1, 'qlim', qlim1);
            
            offset2 = DH(2,1);
            d2 = DH(2,2);
            a2 = DH(2,3);
            alpha2 = DH(2,4);
            L2 = Revolute('d', d2, 'a', a2, 'alpha', alpha2, 'offset', offset2);
            
            offset3 = DH(3,1);
            d3 = DH(3,2);
            a3 = DH(3,3);
            alpha3 = DH(3,4);
            L3 = Revolute('d', d3, 'a', a3, 'alpha', alpha3, 'offset', offset3);
            
            offset4 = DH(4,1);
            d4 = DH(4,2);
            a4 = DH(4,3);
            alpha4 = DH(4,4);
            L4 = Revolute('d', d4, 'a', a4, 'alpha', alpha4, 'offset', offset4);
            
            offset5 = DH(5,1);
            d5 = DH(5,2);
            a5 = DH(5,3);
            alpha5 = DH(5,4);
            L5 = Revolute('d', d5, 'a', a5, 'alpha', alpha5, 'offset', offset5);
            
            offset6 = DH(6,1);
            d6 = DH(6,2);
            a6 = DH(6,3);
            alpha6 = DH(6,4);
            L6 = Revolute('d', d6, 'a', a6, 'alpha', alpha6, 'offset', offset6);
            
            % Creación del SerialLink
            app.R17_PC = SerialLink([L1, L2, L3, L4, L5, L6], 'name', 'R17', 'base', T_base);
            q0 = [0.6, 0, 0, 0, 0, 0];
            %figure(1);
            app.workspace = 1.2*[-1, 1, -1, 2, -1, 1];
            app.R17_PC.plot(q0, 'zoom', 2, 'workspace', app.workspace, 'nobase', 'notiles',...
                            'noname', 'noshadow', 'nojaxes');
            
            % Condiciones iniciales de la interfaz
            % Tab Forward Kinematics
            app.LampGreen.Enable = 'off';
            app.LampRed.Enable = 'off';
            app.COM = app.COMDropDown.Value;
            app.SPEED = app.SPEEDEditField.Value;
            app.STOPButton.Enable = 'off';
            app.SEND_FK_Button.Enable = 'off';
            
            % Tab Inverse Kinematics
            app.UIAxesWorkspaceEF.XLim = [-750, 750];
            app.UIAxesWorkspaceEF.YLim = [-750, 750];
            app.UIAxesWorkspaceEF.ZLim = [0, 750];
            plot3(app.UIAxesWorkspaceEF,app.x,app.y,app.z,'o','MarkerSize',4,'Color','r');
            hold(app.UIAxesWorkspaceEF, 'on');
            line(app.UIAxesWorkspaceEF,2*app.UIAxesWorkspaceEF.XLim, [0,0], [0,0], 'LineWidth', 1, 'Color', 'k');
            line(app.UIAxesWorkspaceEF,[0,0], 2*app.UIAxesWorkspaceEF.YLim, [0,0], 'LineWidth', 1, 'Color', 'k');
%             line(app.UIAxesWorkspaceEF,[0,0], [0,0], 2*app.UIAxesWorkspaceEF.ZLim, 'LineWidth', 1, 'Color', 'k');
            grid(app.UIAxesWorkspaceEF,'on'); 
            hold(app.UIAxesWorkspaceEF, 'off');
            
            % Para visualizar en el workspace
            assignin('base', 'com', app.COM);
            assignin('base', 'speed', app.SPEED);
            
            
            % Tab Routes
            app.EraserouteButton.Enable = 'off';
            app.RunrouteButton.Enable = 'off';
            app.GotolineButton.Enable = 'off';
            app.RouteLines = app.RoutelinesSpinner.Value;
            app.UITableRoutes.RowName = 'numbered';
            app.UITableRoutes.Data = zeros(app.RouteLines,numel(app.UITableRoutes.ColumnName));
            app.RouteMatrix = app.UITableRoutes.Data;
            app.DropDownLineItems = string(1:app.RouteLines);
            app.RoutelineDropDown.Items = app.DropDownLineItems;
            assignin('base', 'RouteMatrix', app.RouteMatrix);
            assignin('base', 'DropDownLineItems', app.DropDownLineItems);
                       
        end

        % Value changing function: CAREERSlider
        function CAREERSliderValueChanging(app, event)
            slider = event.Value;
            app.CAREEREditField.Value = event.Value;
            app.q1 = slider;
            q = [(app.q1 + 0.6), app.q2, app.q3, app.q4, app.q5, app.q6];

            app.R17_PC.plot(q, 'zoom', 2, 'workspace', app.workspace, 'nobase', 'notiles', 'noname', 'noshadow', 'nojaxes');
            
        end

        % Value changing function: WAISTSlider
        function WAISTSliderValueChanging(app, event)
            slider = event.Value;
            app.WAISTEditField.Value = event.Value;
            app.q2 = deg2rad(slider);
            q = [(app.q1 + 0.6), app.q2, app.q3, app.q4, app.q5, app.q6];

            app.R17_PC.plot(q, 'zoom', 2, 'workspace', app.workspace, 'nobase', 'notiles', 'noname', 'noshadow', 'nojaxes');
            
        end

        % Value changing function: SHOULDERSlider
        function SHOULDERSliderValueChanging(app, event)
            slider = event.Value;
            app.SHOULDEREditField.Value = event.Value;
            app.q3 = deg2rad(slider);
            q = [(app.q1 + 0.6), app.q2, app.q3, app.q4, app.q5, app.q6];

            app.R17_PC.plot(q, 'zoom', 2, 'workspace', app.workspace, 'nobase', 'notiles', 'noname', 'noshadow', 'nojaxes');
        end

        % Value changing function: ELBOWSlider
        function ELBOWSliderValueChanging(app, event)
            slider = event.Value;
            app.ELBOWEditField.Value = event.Value;
            app.q4 = deg2rad(slider);
            q = [(app.q1 + 0.6), app.q2, app.q3, app.q4, app.q5, app.q6];

            app.R17_PC.plot(q, 'zoom', 2, 'workspace', app.workspace, 'nobase', 'notiles', 'noname', 'noshadow', 'nojaxes');
        end

        % Value changing function: HANDSlider
        function HANDSliderValueChanging(app, event)
            slider = event.Value;
            app.HANDEditField.Value = event.Value;
            app.q5 = deg2rad(slider);
            q = [(app.q1 + 0.6), app.q2, app.q3, app.q4, app.q5, app.q6];

            app.R17_PC.plot(q, 'zoom', 2, 'workspace', app.workspace, 'nobase', 'notiles', 'noname', 'noshadow', 'nojaxes');
        end

        % Value changing function: WRISTSlider
        function WRISTSliderValueChanging(app, event)
            slider = event.Value;
            app.WRISTEditField.Value = event.Value;
            app.q6 = deg2rad(slider);
            q = [(app.q1 + 0.6), app.q2, app.q3, app.q4, app.q5, app.q6];

            app.R17_PC.plot(q, 'zoom', 2, 'workspace', app.workspace, 'nobase', 'notiles', 'noname', 'noshadow', 'nojaxes');
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.LampGreen.Enable = 'on';
            app.LampRed.Enable = 'off';
            app.STOPButton.Enable = 'on';
            app.SEND_FK_Button.Enable = 'on';
            app.STARTButton.Enable = 'off';
            app.R17 = R17Init(app.COM, app.SPEED);
            assignin('base', 'R17', app.R17);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.LampGreen.Enable = 'off';
            app.LampRed.Enable = 'on';
            app.STOPButton.Enable = 'off';
            app.SEND_FK_Button.Enable = 'off';
            app.STARTButton.Enable = 'on';
            %             q = [0; 0; 0; 0; 0; 0];
            %             app.fK = R17fKine(app.R17, q);
%             R17Command(app.R17, 'HOME');
            %             pause(10);
            R17Close(app.R17);
        end

        % Value changed function: COMDropDown
        function COMDropDownValueChanged(app, event)
            app.COM = app.COMDropDown.Value;
            % Para visualizar en el workspace
            assignin('base', 'com', app.COM);
            
        end

        % Value changed function: SPEEDEditField
        function SPEEDEditFieldValueChanged(app, event)
            app.SPEED = app.SPEEDEditField.Value;
            % Para visualizar en el workspace
            assignin('base', 'speed', app.SPEED);
            
        end

        % Button pushed function: SEND_FK_Button
        function SEND_FK_ButtonPushed(app, event)
            q = [app.q1; app.q2; app.q3; app.q4; app.q5; app.q6];
            % Para visualizar en el workspace
            assignin('base', 'q', q);
            app.fK = R17fKine(app.R17, q);
            
        end

        % Value changed function: WAISTEditField
        function WAISTEditFieldValueChanged(app, event)
            app.WAISTSlider.Value = app.WAISTEditField.Value;
            field = app.WAISTEditField.Value;
            app.q2 = deg2rad(field);
            
            q = [(app.q1 + 0.6), app.q2, app.q3, app.q4, app.q5, app.q6];
            app.R17_PC.plot(q, 'zoom', 2, 'workspace', app.workspace, 'nobase', 'notiles', 'noname', 'noshadow', 'nojaxes');

        end

        % Value changed function: SHOULDEREditField
        function SHOULDEREditFieldValueChanged(app, event)
            app.SHOULDERSlider.Value = app.SHOULDEREditField.Value;
            field = app.SHOULDEREditField.Value;
            app.q3 = deg2rad(field);
            
            q = [(app.q1 + 0.6), app.q2, app.q3, app.q4, app.q5, app.q6];
            app.R17_PC.plot(q, 'zoom', 2, 'workspace', app.workspace, 'nobase', 'notiles', 'noname', 'noshadow', 'nojaxes');
            
        end

        % Value changed function: ELBOWEditField
        function ELBOWEditFieldValueChanged(app, event)
            app.ELBOWSlider.Value = app.ELBOWEditField.Value;
            field = app.ELBOWEditField.Value;
            app.q4 = deg2rad(field);
            
            q = [(app.q1 + 0.6), app.q2, app.q3, app.q4, app.q5, app.q6];
            app.R17_PC.plot(q, 'zoom', 2, 'workspace', app.workspace, 'nobase', 'notiles', 'noname', 'noshadow', 'nojaxes');
            
        end

        % Value changed function: HANDEditField
        function HANDEditFieldValueChanged(app, event)
            app.HANDSlider.Value = app.HANDEditField.Value;
            field = app.HANDEditField.Value;
            app.q5 = deg2rad(field);
            
            q = [(app.q1 + 0.6), app.q2, app.q3, app.q4, app.q5, app.q6];
            app.R17_PC.plot(q, 'zoom', 2, 'workspace', app.workspace, 'nobase', 'notiles', 'noname', 'noshadow', 'nojaxes');
            
        end

        % Value changed function: WRISTEditField
        function WRISTEditFieldValueChanged(app, event)
            app.WRISTSlider.Value = app.WRISTEditField.Value;
            field = app.WRISTEditField.Value;
            app.q6 = deg2rad(field);
            
            q = [(app.q1 + 0.6), app.q2, app.q3, app.q4, app.q5, app.q6];
            app.R17_PC.plot(q, 'zoom', 2, 'workspace', app.workspace, 'nobase', 'notiles', 'noname', 'noshadow', 'nojaxes');
            
        end

        % Value changed function: CAREEREditField
        function CAREEREditFieldValueChanged(app, event)
            app.CAREERSlider.Value = app.CAREEREditField.Value;
            field = app.CAREEREditField.Value;
            app.q1 = field;
            
            q = [(app.q1 + 0.6), app.q2, app.q3, app.q4, app.q5, app.q6];
            app.R17_PC.plot(q, 'zoom', 2, 'workspace', app.workspace, 'nobase', 'notiles', 'noname', 'noshadow', 'nojaxes');
            
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            app.x = app.XEditField.Value;
            plot3(app.UIAxesWorkspaceEF,app.x,app.y,app.z,'o','MarkerSize',4,'Color','r');
            hold(app.UIAxesWorkspaceEF, 'on');
            line(app.UIAxesWorkspaceEF,2*app.UIAxesWorkspaceEF.XLim, [0,0], [0,0], 'LineWidth', 1, 'Color', 'k');
            line(app.UIAxesWorkspaceEF,[0,0], 2*app.UIAxesWorkspaceEF.YLim, [0,0], 'LineWidth', 1, 'Color', 'k');
            hold(app.UIAxesWorkspaceEF, 'off');
            
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            app.y = app.YEditField.Value;
            plot3(app.UIAxesWorkspaceEF,app.x,app.y,app.z,'o','MarkerSize',4,'Color','r');
            hold(app.UIAxesWorkspaceEF, 'on');
            line(app.UIAxesWorkspaceEF,2*app.UIAxesWorkspaceEF.XLim, [0,0], [0,0], 'LineWidth', 1, 'Color', 'k');
            line(app.UIAxesWorkspaceEF,[0,0], 2*app.UIAxesWorkspaceEF.YLim, [0,0], 'LineWidth', 1, 'Color', 'k');
            hold(app.UIAxesWorkspaceEF, 'off');
            
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            app.z = app.ZEditField.Value;
            plot3(app.UIAxesWorkspaceEF,app.x,app.y,app.z,'o','MarkerSize',4,'Color','r');
            hold(app.UIAxesWorkspaceEF, 'on');
            line(app.UIAxesWorkspaceEF,2*app.UIAxesWorkspaceEF.XLim, [0,0], [0,0], 'LineWidth', 1, 'Color', 'k');
            line(app.UIAxesWorkspaceEF,[0,0], 2*app.UIAxesWorkspaceEF.YLim, [0,0], 'LineWidth', 1, 'Color', 'k');
            hold(app.UIAxesWorkspaceEF, 'off');
            
        end

        % Button pushed function: SEND_IK_Button
        function SEND_IK_ButtonPushed(app, event)
            % Se multiplica por 10, porque ROBOFORTH trabaja en pasos de 0.1 mm
            p = 10*[app.x; app.y; app.z; app.ROLL; app.PITCH; app.YAW];
            % Para visualizar en el workspace
            assignin('base', 'p', p);
            app.iK = R17iKine(app.R17, p);
        end

        % Value changed function: ROLLEditField
        function ROLLEditFieldValueChanged(app, event)
            app.ROLL = app.ROLLEditField.Value;            
            
        end

        % Value changed function: PITCHEditField
        function PITCHEditFieldValueChanged(app, event)
            app.PITCH = app.PITCHEditField.Value;
            
        end

        % Value changed function: YAWEditField
        function YAWEditFieldValueChanged(app, event)
            app.YAW = app.YAWEditField.Value;
            
        end

        % Value changed function: RoutelinesSpinner
        function RoutelinesSpinnerValueChanged(app, event)
            app.RouteLines = app.RoutelinesSpinner.Value;
            app.DropDownLineItems = string(1:app.RouteLines);
            app.RoutelineDropDown.Items = app.DropDownLineItems;
            
            if (app.RouteLines < size(app.RouteMatrix,1))
                app.RouteMatrix = app.RouteMatrix(1:app.RouteLines, :);
            else
                newLines = app.RouteLines-size(app.RouteMatrix,1);
                app.RouteMatrix = [app.RouteMatrix; zeros(newLines,3)];
            end
            
%             app.UITableRoutes.Data = array2table(app.RouteMatrix);
            app.UITableRoutes.Data = app.RouteMatrix;
            assignin('base', 'RouteMatrix', app.RouteMatrix);
        end

        % Cell edit callback: UITableRoutes
        function UITableRoutesCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.RouteMatrix = app.UITableRoutes.Data;
%             app.RoutineMatrix = table2array(app.RouteMatrix);
            assignin('base', 'RouteMatrix', app.RouteMatrix);
        end

        % Button pushed function: ResettableButton
        function ResettableButtonPushed(app, event)
            app.UITableRoutes.Data = zeros(app.RouteLines,numel(app.UITableRoutes.ColumnName));
            app.RouteMatrix = app.UITableRoutes.Data;
            assignin('base', 'RouteMatrix', app.RouteMatrix);
        end

        % Button pushed function: CreaterouteButton
        function CreaterouteButtonPushed(app, event)
            app.CreaterouteButton.Enable = 'off';
            app.EraserouteButton.Enable = 'on';
            app.RunrouteButton.Enable = 'on';
            app.GotolineButton.Enable = 'on';
%             R17CreateRoute(app.R17, app.RouteMatrix);
        end

        % Button pushed function: EraserouteButton
        function EraserouteButtonPushed(app, event)
            app.CreaterouteButton.Enable = 'on';
            app.EraserouteButton.Enable = 'off';
            app.RunrouteButton.Enable = 'off';
            app.GotolineButton.Enable = 'off';
%             R17EraseRoute(app.R17);
        end

        % Selection changed function: RouterunmodeButtonGroup
        function RouterunmodeButtonGroupSelectionChanged(app, event)
            app.RouteMode = app.RouterunmodeButtonGroup.SelectedObject;
            assignin('base', 'RouteMode', app.RouteMode);            
        end

        % Button pushed function: RunrouteButton
        function RunrouteButtonPushed(app, event)
            % Depend on the mode selected, use different command.
%             if (strcmp(app.RouteMode.Text,'Segmented'))
%                 R17Command(app.R17, "SEGMENTED R1 RUN");
%             elseif (strcmp(app.RouteMode.Text,'Continuous'))
%                 R17Command(app.R17, "CONTINUOUS R1 RUN");
%             elseif (strcmp(app.RouteMode.Text,'Smooth'))
%                 R17Command(app.R17, "SMOOTH R1 RUN");
%             else
%                 app.RouteMode = app.RouteMode; % Do nothing
%             end
        end

        % Button pushed function: GotolineButton
        function GotolineButtonPushed(app, event)
%             RouteLine = app.RoutelineDropDown.Value;
%             R17Command(app.R17, "R1 " + string(RouteLine) + " GOTO");
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
             Interfaz_Inicio
             delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ForwardKinematicsTab = uitab(app.TabGroup);
            app.ForwardKinematicsTab.Title = 'Forward Kinematics';
            app.JointsPanel = uipanel(app.ForwardKinematicsTab);
            app.JointsPanel.Title = 'Joints Panel';
            app.CAREERSliderLabel = uilabel(app.JointsPanel);
            app.CAREERSliderLabel.Text = 'CAREER';
            app.CAREERSlider = uislider(app.JointsPanel);
            app.CAREERSlider.Limits = [-0.6 0.6];
            app.CAREERSlider.ValueChangingFcn = createCallbackFcn(app, @CAREERSliderValueChanging, true);
            app.WAISTSliderLabel = uilabel(app.JointsPanel);
            app.WAISTSliderLabel.Text = 'WAIST';
            app.WAISTSlider = uislider(app.JointsPanel);
            app.WAISTSlider.Limits = [-180 180];
            app.WAISTSlider.ValueChangingFcn = createCallbackFcn(app, @WAISTSliderValueChanging, true);
            app.SHOULDERSliderLabel = uilabel(app.JointsPanel);
            app.SHOULDERSliderLabel.Text = 'SHOULDER';
            app.SHOULDERSlider = uislider(app.JointsPanel);
            app.SHOULDERSlider.Limits = [-142 142];
            app.SHOULDERSlider.ValueChangingFcn = createCallbackFcn(app, @SHOULDERSliderValueChanging, true);
            app.ELBOWSliderLabel = uilabel(app.JointsPanel);
            app.ELBOWSliderLabel.Text = 'ELBOW';
            app.ELBOWSlider = uislider(app.JointsPanel);
            app.ELBOWSlider.Limits = [-163 163];
            app.ELBOWSlider.ValueChangingFcn = createCallbackFcn(app, @ELBOWSliderValueChanging, true);
            app.HANDSliderLabel = uilabel(app.JointsPanel);
            app.HANDSliderLabel.Text = 'HAND';
            app.HANDSlider = uislider(app.JointsPanel);
            app.HANDSlider.Limits = [-180 180];
            app.HANDSlider.ValueChangingFcn = createCallbackFcn(app, @HANDSliderValueChanging, true);
            app.WRISTSliderLabel = uilabel(app.JointsPanel);
            app.WRISTSliderLabel.Text = 'WRIST';
            app.WRISTSlider = uislider(app.JointsPanel);
            app.WRISTSlider.Limits = [-180 180];
            app.WRISTSlider.ValueChangingFcn = createCallbackFcn(app, @WRISTSliderValueChanging, true);
            app.CAREEREditField = uieditfield(app.JointsPanel, 'numeric');
            app.CAREEREditField.Limits = [-0.6 0.6];
            app.CAREEREditField.ValueDisplayFormat = '%.2f';
            app.CAREEREditField.ValueChangedFcn = createCallbackFcn(app, @CAREEREditFieldValueChanged, true);
            app.WAISTEditField = uieditfield(app.JointsPanel, 'numeric');
            app.WAISTEditField.Limits = [-180 180];
            app.WAISTEditField.RoundFractionalValues = 'on';
            app.WAISTEditField.ValueChangedFcn = createCallbackFcn(app, @WAISTEditFieldValueChanged, true);
            app.SHOULDEREditField = uieditfield(app.JointsPanel, 'numeric');
            app.SHOULDEREditField.Limits = [-142 142];
            app.SHOULDEREditField.RoundFractionalValues = 'on';
            app.SHOULDEREditField.ValueChangedFcn = createCallbackFcn(app, @SHOULDEREditFieldValueChanged, true);
            app.ELBOWEditField = uieditfield(app.JointsPanel, 'numeric');
            app.ELBOWEditField.Limits = [-163 163];
            app.ELBOWEditField.RoundFractionalValues = 'on';
            app.ELBOWEditField.ValueChangedFcn = createCallbackFcn(app, @ELBOWEditFieldValueChanged, true);
            app.HANDEditField = uieditfield(app.JointsPanel, 'numeric');
            app.HANDEditField.Limits = [-180 180];
            app.HANDEditField.RoundFractionalValues = 'on';
            app.HANDEditField.ValueChangedFcn = createCallbackFcn(app, @HANDEditFieldValueChanged, true);
            app.WRISTEditField = uieditfield(app.JointsPanel, 'numeric');
            app.WRISTEditField.Limits = [-180 180];
            app.WRISTEditField.RoundFractionalValues = 'on';
            app.WRISTEditField.ValueChangedFcn = createCallbackFcn(app, @WRISTEditFieldValueChanged, true);
            app.ParametersPanel = uipanel(app.ForwardKinematicsTab);
            app.ParametersPanel.Title = 'Parameters Panel';
            app.SPEEDEditFieldLabel = uilabel(app.ParametersPanel);
            app.SPEEDEditFieldLabel.Text = 'SPEED';
            app.SPEEDEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SPEEDEditField.Limits = [5000 25000];
            app.SPEEDEditField.RoundFractionalValues = 'on';
            app.SPEEDEditField.ValueChangedFcn = createCallbackFcn(app, @SPEEDEditFieldValueChanged, true);
            app.SPEEDEditField.Value = 10000;
            app.COMDropDownLabel = uilabel(app.ParametersPanel);
            app.COMDropDownLabel.Text = 'COM';
            app.COMDropDown = uidropdown(app.ParametersPanel);
            app.COMDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM9'};
            app.COMDropDown.ValueChangedFcn = createCallbackFcn(app, @COMDropDownValueChanged, true);
            app.COMDropDown.Value = 'COM1';
            app.ButtonsPanel = uipanel(app.ForwardKinematicsTab);
            app.ButtonsPanel.Title = 'Buttons Panel';
            app.STARTButton = uibutton(app.ButtonsPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.ButtonsPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.SEND_FK_Button = uibutton(app.ButtonsPanel, 'push');
            app.SEND_FK_Button.ButtonPushedFcn = createCallbackFcn(app, @SEND_FK_ButtonPushed, true);
            app.SEND_FK_Button.Text = 'TAKE POSITION';
            app.Label = uilabel(app.ButtonsPanel);
            app.Label.Text = '';
            app.LampGreen = uilamp(app.ButtonsPanel);
            app.Label_2 = uilabel(app.ButtonsPanel);
            app.Label_2.Text = '';
            app.LampRed = uilamp(app.ButtonsPanel);
            app.InverseKinematicsTab = uitab(app.TabGroup);
            app.InverseKinematicsTab.Title = 'Inverse Kinematics';
            app.CartesianCoordinatesPanel = uipanel(app.InverseKinematicsTab);
            app.CartesianCoordinatesPanel.Title = 'Cartesian Coordinates Panel';
            app.XEditFieldLabel = uilabel(app.CartesianCoordinatesPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.CartesianCoordinatesPanel, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.CartesianCoordinatesPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.CartesianCoordinatesPanel, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.CartesianCoordinatesPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.CartesianCoordinatesPanel, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.ZEditField.Value = 750;
            app.mmLabel = uilabel(app.CartesianCoordinatesPanel);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.CartesianCoordinatesPanel);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_3 = uilabel(app.CartesianCoordinatesPanel);
            app.mmLabel_3.Text = 'mm';
            app.gradosLabel = uilabel(app.CartesianCoordinatesPanel);
            app.gradosLabel.Text = 'grados';
            app.ROLLEditFieldLabel = uilabel(app.CartesianCoordinatesPanel);
            app.ROLLEditFieldLabel.Text = 'ROLL';
            app.ROLLEditField = uieditfield(app.CartesianCoordinatesPanel, 'numeric');
            app.ROLLEditField.ValueChangedFcn = createCallbackFcn(app, @ROLLEditFieldValueChanged, true);
            app.gradosLabel_2 = uilabel(app.CartesianCoordinatesPanel);
            app.gradosLabel_2.Text = 'grados';
            app.PITCHEditFieldLabel = uilabel(app.CartesianCoordinatesPanel);
            app.PITCHEditFieldLabel.Text = 'PITCH';
            app.PITCHEditField = uieditfield(app.CartesianCoordinatesPanel, 'numeric');
            app.PITCHEditField.ValueChangedFcn = createCallbackFcn(app, @PITCHEditFieldValueChanged, true);
            app.gradosLabel_3 = uilabel(app.CartesianCoordinatesPanel);
            app.gradosLabel_3.Text = 'grados';
            app.YAWEditFieldLabel = uilabel(app.CartesianCoordinatesPanel);
            app.YAWEditFieldLabel.Text = 'YAW';
            app.YAWEditField = uieditfield(app.CartesianCoordinatesPanel, 'numeric');
            app.YAWEditField.ValueChangedFcn = createCallbackFcn(app, @YAWEditFieldValueChanged, true);
            app.SEND_IK_Button = uibutton(app.InverseKinematicsTab, 'push');
            app.SEND_IK_Button.ButtonPushedFcn = createCallbackFcn(app, @SEND_IK_ButtonPushed, true);
            app.SEND_IK_Button.Text = 'TAKE POSITION';
            app.UIAxesWorkspaceEF = uiaxes(app.InverseKinematicsTab);
            title(app.UIAxesWorkspaceEF, 'End effector workspace position')
            xlabel(app.UIAxesWorkspaceEF, 'X')
            ylabel(app.UIAxesWorkspaceEF, 'Y')
            app.RoutesTab = uitab(app.TabGroup);
            app.RoutesTab.Tooltip = {''};
            app.RoutesTab.Title = 'Routes';
            app.RoutetablepanelPanel = uipanel(app.RoutesTab);
            app.RoutetablepanelPanel.Title = 'Route table panel';
            app.UITableRoutes = uitable(app.RoutetablepanelPanel);
            app.UITableRoutes.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITableRoutes.RowName = {''};
            app.UITableRoutes.ColumnEditable = true;
            app.UITableRoutes.CellEditCallback = createCallbackFcn(app, @UITableRoutesCellEdit, true);
            app.UITableRoutes.Tooltip = {''};
            app.ResettableButton = uibutton(app.RoutetablepanelPanel, 'push');
            app.ResettableButton.ButtonPushedFcn = createCallbackFcn(app, @ResettableButtonPushed, true);
            app.ResettableButton.Text = 'Reset table';
            app.RouteoptionspanelPanel = uipanel(app.RoutesTab);
            app.RouteoptionspanelPanel.Title = 'Route options panel';
            app.RoutelinesSpinnerLabel = uilabel(app.RouteoptionspanelPanel);
            app.RoutelinesSpinnerLabel.Text = 'Route lines';
            app.RoutelinesSpinner = uispinner(app.RouteoptionspanelPanel);
            app.RoutelinesSpinner.Limits = [1 10];
            app.RoutelinesSpinner.RoundFractionalValues = 'on';
            app.RoutelinesSpinner.ValueChangedFcn = createCallbackFcn(app, @RoutelinesSpinnerValueChanged, true);
            app.RoutelinesSpinner.Tooltip = {'Choose an integer number.'};
            app.RoutelinesSpinner.Value = 10;
            app.CreaterouteButton = uibutton(app.RouteoptionspanelPanel, 'push');
            app.CreaterouteButton.ButtonPushedFcn = createCallbackFcn(app, @CreaterouteButtonPushed, true);
            app.CreaterouteButton.Text = 'Create route';
            app.EraserouteButton = uibutton(app.RouteoptionspanelPanel, 'push');
            app.EraserouteButton.ButtonPushedFcn = createCallbackFcn(app, @EraserouteButtonPushed, true);
            app.EraserouteButton.Text = 'Erase route';
            app.PlayroutepanelPanel = uipanel(app.RoutesTab);
            app.PlayroutepanelPanel.Title = 'Play route panel';
            app.RunrouteButton = uibutton(app.PlayroutepanelPanel, 'push');
            app.RunrouteButton.ButtonPushedFcn = createCallbackFcn(app, @RunrouteButtonPushed, true);
            app.RunrouteButton.Text = 'Run route';
            app.GotolineButton = uibutton(app.PlayroutepanelPanel, 'push');
            app.GotolineButton.ButtonPushedFcn = createCallbackFcn(app, @GotolineButtonPushed, true);
            app.GotolineButton.Text = 'Go to line:';
            app.RoutelineDropDownLabel = uilabel(app.PlayroutepanelPanel);
            app.RoutelineDropDownLabel.Text = 'Route line:';
            app.RoutelineDropDown = uidropdown(app.PlayroutepanelPanel);
            app.RoutelineDropDown.Items = {'1', '2', '3', '4'};
            app.RoutelineDropDown.Value = '1';
            app.RouterunmodeButtonGroup = uibuttongroup(app.PlayroutepanelPanel);
            app.RouterunmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RouterunmodeButtonGroupSelectionChanged, true);
            app.RouterunmodeButtonGroup.Title = 'Route run mode';
            app.SegmentedButton = uiradiobutton(app.RouterunmodeButtonGroup);
            app.SegmentedButton.Text = 'Segmented';
            app.SegmentedButton.Value = true;
            app.ContinuousButton = uiradiobutton(app.RouterunmodeButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.SmoothButton = uiradiobutton(app.RouterunmodeButtonGroup);
            app.SmoothButton.Text = 'Smooth';
            app.RegresarButton = uibutton(app.UIFigure, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Icon = 'back.png';
            app.RegresarButton.Text = 'Regresar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = R17FK_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end