classdef RecoleccionDeDatosEEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        RecoleccindeDatosEEGUVGLabel    matlab.ui.control.Label
        DesconectadoLampLabel           matlab.ui.control.Label
        DesconectadoLamp                matlab.ui.control.Lamp
        RegresarButton                  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        InstruccionesTab                matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        InstruccionesparaelusodelaInterfazLabel  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        PaneldecontrolTab               matlab.ui.container.Tab
        CaracteristicasSeleccionadasPanel  matlab.ui.container.Panel
        ArithmeticMeanCheckBox          matlab.ui.control.CheckBox
        HjorthActivityCheckBox          matlab.ui.control.CheckBox
        NormalizedFirstDifferenceCheckBox  matlab.ui.control.CheckBox
        HjorthComplexityCheckBox        matlab.ui.control.CheckBox
        KurtosisCheckBox                matlab.ui.control.CheckBox
        VarianzaCheckBox                matlab.ui.control.CheckBox
        MeanEnergyCheckBox              matlab.ui.control.CheckBox
        CrearTablaButton                matlab.ui.control.Button
        ConectaraElectrocapSwitchLabel  matlab.ui.control.Label
        ConectaraElectrocapSwitch       matlab.ui.control.Switch
        SkewnessCheckBox                matlab.ui.control.CheckBox
        ShannonEntropyCheckBox          matlab.ui.control.CheckBox
        HjorthMobilityCheckBox          matlab.ui.control.CheckBox
        SeleccionartodoCheckBox         matlab.ui.control.CheckBox
        GrabacinPanel                   matlab.ui.container.Panel
        Lamp                            matlab.ui.control.Lamp
        GuardarArchivoButton            matlab.ui.control.Button
        TiempodemuestraatomarEditFieldLabel  matlab.ui.control.Label
        TiempodemuestraatomarEditField  matlab.ui.control.NumericEditField
        NombreDelArchivoEditFieldLabel  matlab.ui.control.Label
        NombreDelArchivoEditField       matlab.ui.control.EditField
        ClaseagrabarDropDownLabel       matlab.ui.control.Label
        ClaseagrabarDropDown            matlab.ui.control.DropDown
        Label_8                         matlab.ui.control.Label
        TiempoLabel                     matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        EmpezaraGrabarButton            matlab.ui.control.Button
        NmerodemuestrastomadasLabel     matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        LarecoleccinestenPausaLabel     matlab.ui.control.Label
        figFpz                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CrearTablaButton
        function CrearTablaButtonPushed(app, event)
            global T
            global n
            global DataEnBruto
            app.Lamp.Enable = 'off';
            n= 0;
            F_pb = filtro_pasa_banda(1000,10,40);    %Diseñar filtro pasa banda
            F_notch = filtro_rechaza_banda(1000);     %Diseñar filtro rechaza banda
            DataEnBruto = cell(0,0)
            T = table()
            
            if app.ArithmeticMeanCheckBox.Value == 1
                T.ArithmeticMean = zeros(0)
            end
            if app.HjorthActivityCheckBox.Value == 1
                T.HjorthActivity = zeros(0)
            end
            if app.NormalizedFirstDifferenceCheckBox.Value == 1
                T.NormalizedFirstDifference = zeros(0)
            end
            if app.HjorthComplexityCheckBox.Value == 1
                T.HjorthComplexity = zeros(0)
            end
            
            if app.KurtosisCheckBox.Value == 1
                T.Kurtosis = zeros(0)
            end
            if app.VarianzaCheckBox.Value == 1
                T.Varianza = zeros(0)
            end
            if app.MeanEnergyCheckBox.Value == 1
                T.MeanEnergy = zeros(0)
            end
            if app.SkewnessCheckBox.Value == 1
                T.Skewness = zeros(0)
            end
            if app.ShannonEntropyCheckBox.Value == 1
                T.ShannonEntropy = zeros(0)
            end
            if app.HjorthMobilityCheckBox.Value == 1
                T.HjorthMobility = zeros(0)
            end
            
            
        end

        % Button pushed function: GuardarArchivoButton
        function GuardarArchivoButtonPushed(app, event)
            global T
            global NombreA
            global DataEnBruto
            save('PruebaT.mat', 'T')
            save('PruebaT2.mat', 'DataEnBruto')
            NombreA = app.NombreDelArchivoEditField.Value
            writetable(T,string(NombreA)+"."+'xls')
            writecell(DataEnBruto,string(NombreA)+"EnBruto"+"."+'xls')
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
            global b
            %b.stopBackground
            clear all
            Interfaz_Inicio
            delete(app)
        end

        % Value changed function: ConectaraElectrocapSwitch
        function ConectaraElectrocapSwitchValueChanged(app, event)
            value = app.ConectaraElectrocapSwitch.Value;
            
            app.Lamp.Enable = 'off';
            global flag_recibir
            
            if strcmp( value,'On')
                
                
                flag_recibir =1;
                
                app.DesconectadoLamp.Enable = 'on';
                app.DesconectadoLampLabel.Text= 'Conectado';
                
                
            else
                
                flag_recibir = 0;
                
                app.DesconectadoLamp.Enable = 'off';
                app.DesconectadoLampLabel.Text= 'Desconectado';
            end
        end

        % Value changed function: ClaseagrabarDropDown
        function ClaseagrabarDropDownValueChanged(app, event)
            value = app.ClaseagrabarDropDown.Value;
            
            switch value
                case 'Wrist-Up'
                    app.Image.ImageSource = imread("WristUp.jpg");
                case 'Hand-Up'
                    app.Image.ImageSource = imread("manita.jfif");
                    
            end
        end

        % Button pushed function: EmpezaraGrabarButton
        function EmpezaraGrabarButtonPushed(app, event)
            %%  Establecer la longitud de las epocas que se desea e inicializacion de variables
            global flag_recibir
            global Pausa
            global DataEnBruto
            global T
            Pausa = 0;
            Epocas_Length = app.TiempodemuestraatomarEditField.Value; % Duracion en segundos de cada epoca
            Canales = 8;        % Numero de canales que se desea usar (1 a 8)
            itr = 1;
            t=1:1000;
            vproyecto = zeros(1000,8);
            i = 1;
            ctrl = 0;
            ctrl2 = 0;
            fs =250;
            M = 2;
            %% Se definen los parametros para los filtros
            fs_Hz = 250;
            little_buff = zeros(1,10);
            display_buff = zeros(1,5000);
            cont = 1;
            
            bpf = [7, 40];
            [b,a] = butter(2,bpf/(fs_Hz / 2), 'bandpass');
            
            notch = [59, 61];
            [b2, a2] = butter(2,notch/(fs_Hz / 2), 'stop');
            
            %%  Reservar memoria para vector de datos raw y para filtrar los datos
            Col = 8;
            Row = 50000000;
            Offset = zeros(1,8);
            Live_Data1 = zeros(Row,Col);
            Live_Data_test = [];
            Feature1_Vec = zeros(M,8);
            Feature2_Vec = zeros(M,8);
            Feature3_Vec = zeros(M,8);
            Feature4_Vec = zeros(M,8);
            Feature5_Vec = zeros(M,8);
            Live_Data_test2 = zeros(Epocas_Length*250,8);
            Fixed_Vector = zeros(Epocas_Length*250,8);
            Full_Fill_Vec = zeros(2*Epocas_Length*250,8);
            Temp_Raw_Vector = zeros(2*Epocas_Length*100,8);
            index1 = 1;
            cont = 1;
            cont2 = Epocas_Length*250;
            cont3 = Epocas_Length*250;
            tiempos = zeros(Epocas_Length*250,1);
            
            mitimer  = tic;
            n = 1;
            Epocas = Epocas_Length*250  ; %se toma el tamano del buffer obtenido
            L = Epocas; %longitud del vector epocas
            t=linspace(0, Epocas/250, L);
            
            %% instantiate the library
                disp('Loading the library...');
                lib = lsl_loadlib();
                % resolve a stream...
                disp('Resolving an EEG stream...');
                result = {};
                while isempty(result)
                    result = lsl_resolve_byprop(lib,'type','EEG');
                end
                
                % create a new inlet
                disp('Opening an inlet...');
                inlet = lsl_inlet(result{1});
                disp('Now receiving data...');
            
            h1= plot(app.figFpz,  t, tiempos)
            title('Ch1')
            ylabel('uV')
            xlabel('tiempo (s)')
            
            FactorEscalador = (4500000)/24/(2^23-1);
            
            while flag_recibir ==1
                
                [vec,ts] = inlet.pull_sample();                         % Se realiza la lectura de los 8 canales
                tiempos(n) = toc(mitimer);
                mitimer  = tic;
                
                Live_Data_test2(index1,:) =  (vec)*FactorEscalador;                       % Se ingresa los datos leidos a un vector temporal
                
                %     h1.YData(i) = (vec(1, 1))/1000;                       %Actualizar gráfica
                %
                %     drawnow limitrate               
                n = n +1;
               
                i=i+1;
                if(mod(i,Epocas_Length*250)==0)                       % Cada vez que pase cierta cantidad de tiempo se hace la separacion de epocas
                    i=1;
                    if (min(Live_Data_test2)==0)
                        [M,I] = find(Live_Data_test2 == 0);
                        Live_Data_test2(M,I) = Offset;
                        fprintf('/');
                    end
                    Fixed_Vector = (Live_Data_test2 - (Offset));
                    %         Fixed_Vector = Live_Data_test2 - Live_Data_test2(1,:);
                    display_buff_filt = filter(b2,a2,Fixed_Vector);  % Se realiza el friltrado del los datos
                    display_buff_filt = filter(b,a,display_buff_filt);
                    Offset = [mean(Live_Data_test2(:,1)),mean(Live_Data_test2(:,2)),mean(Live_Data_test2(:,3)),mean(Live_Data_test2(:,4)),mean(Live_Data_test2(:,5)),mean(Live_Data_test2(:,6)),mean(Live_Data_test2(:,7)),mean(Live_Data_test2(:,8))];
                    %         Offseet = mean(Live_Data_test2,1);
                    Full_Fill_Vec(cont:cont3 ,:) = Live_Data_test2;     % Se almacenan los datos de cada epoca de los valores en bruto
                    Temp_Raw_Vector(cont:cont3,:) = display_buff_filt;  % Se almacenan los datos de cada epoca de los valores filtrados
                    cont = cont + cont2;                                % Contador para la primera posicion de la proxima epoca a guardar
                    cont3 = cont3 + cont2;                              % Contador de la ultima posicion de la proxima epoca a guardar
                    index1 = 1;
                    flag = true;                                        % cuando se cumpla esta condicion se levanta una bandera para poder sacarle las caracterisiticas a dicha epoca
                else
                    flag = false;
                    index1 = index1 + 1;
                end


                if flag == true
                    %Extraer caracteristicas
                    Chn1 = display_buff_filt(: , 1);


                    h1 = plot( app.figFpz,t, Chn1)
                    ylim( app.figFpz, [min(Chn1) max(Chn1)]);
                    
                    drawnow limitrate
                    %h1 = ylim([min(Chn1) max(Chn1)]);
                    if Pausa == 0
                        
                        ctrl2 = ctrl2 + 1;
                        itr = 0;
                        fprintf('Done - %d \n',ctrl);
                        ctrl = ctrl + 1; % variable de control de duracion de la simulacion
                        app.Label_9.Text = string(ctrl);
                        DataEnBruto(:,ctrl2) = num2cell(Chn1)
                        if app.ArithmeticMeanCheckBox.Value == 1
                            T.BandPowerBeta(ctrl2) = jArithmeticMean(Chn1)
                        end
                        if app.HjorthActivityCheckBox.Value == 1
                            T.HjorthActivity(ctrl2) = jHjorthActivity(Chn1)
                        end
                        if app.NormalizedFirstDifferenceCheckBox.Value == 1
                            T.NormalizedFirstDifference(ctrl2) = jNormalizedFirstDifference(Chn1)
                        end
                        if app.HjorthComplexityCheckBox.Value == 1
                            T.HjorthComplexity(ctrl2) = jHjorthComplexity(Chn1)
                        end
                        
                        if app.KurtosisCheckBox.Value == 1
                            T.Kurtosis(ctrl2) = jKurtosis(Chn1)
                        end
                        if app.VarianzaCheckBox.Value == 1
                            T.Varianza(ctrl2) = jVariance(Chn1)
                        end
                        if app.MeanEnergyCheckBox.Value == 1
                            T.MeanEnergy(ctrl2) = jMeanTeagerEnergy(Chn1)
                        end
                        if app.SkewnessCheckBox.Value == 1
                            T.Skewness(ctrl2) = jSkewness(Chn1)
                        end
                        if app.ShannonEntropyCheckBox.Value == 1
                            T.ShannonEntropy(ctrl2) = jShannonEntropy(Chn1)
                        end
                        if app.HjorthMobilityCheckBox.Value == 1
                            T.HjorthMobility(ctrl2) = jHjorthMobility(Chn1)
                        end
                    end
                     if Pausa == 1
                    app.LarecoleccinestenPausaLabel.Visible = 'on';
                end
                    
                    
                end
            end
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global Pausa
            Pausa = 1;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            global Pausa
            Pausa = 0;
        end

        % Value changed function: SeleccionartodoCheckBox
        function SeleccionartodoCheckBoxValueChanged(app, event)
            value = app.SeleccionartodoCheckBox.Value;
            if app.SeleccionartodoCheckBox.Value ==1
                app.ArithmeticMeanCheckBox.Value = 1;
                app.HjorthActivityCheckBox.Value =1;
                app.NormalizedFirstDifferenceCheckBox.Value =1;
                app.HjorthComplexityCheckBox.Value =1;
                app.KurtosisCheckBox.Value =1;
                app.VarianzaCheckBox.Value=1;
                app.MeanEnergyCheckBox.Value = 1;
                app.SkewnessCheckBox.Value=1;
                app.ShannonEntropyCheckBox.Value=1;
                app.HjorthMobilityCheckBox.Value=1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.RecoleccindeDatosEEGUVGLabel = uilabel(app.Panel);
            app.RecoleccindeDatosEEGUVGLabel.Text = 'Recolección de Datos  EEG - UVG';
            app.DesconectadoLampLabel = uilabel(app.Panel);
            app.DesconectadoLampLabel.Enable = 'off';
            app.DesconectadoLampLabel.Text = 'Desconectado';
            app.DesconectadoLamp = uilamp(app.Panel);
            app.DesconectadoLamp.Enable = 'off';
            app.RegresarButton = uibutton(app.UIFigure, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Icon = 'back.png';
            app.RegresarButton.Text = 'Regresar';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InstruccionesTab = uitab(app.TabGroup);
            app.InstruccionesTab.Title = 'Instrucciones';
            app.Label = uilabel(app.InstruccionesTab);
            app.Label.Text = '1. Se tienen que seleccionar las caracteristicas que se quieren incluir en los vectores de caracteristicas. ';
            app.Label_2 = uilabel(app.InstruccionesTab);
            app.Label_2.Text = '2. Al tener las características seleccionadas se debe crear la tabla donde se guardaran estos vectores. ';
            app.InstruccionesparaelusodelaInterfazLabel = uilabel(app.InstruccionesTab);
            app.InstruccionesparaelusodelaInterfazLabel.Text = 'Instrucciones para el uso de la Interfaz';
            app.Label_3 = uilabel(app.InstruccionesTab);
            app.Label_3.Text = '5. Se debe de conectar al Bitalino y luego empezar a recolectar datos empezando el streaming.  ';
            app.Label_4 = uilabel(app.InstruccionesTab);
            app.Label_4.Text = '3. Se debe de seleccionar la ventana de tiempo de las muestras que se tomarán.';
            app.Label_5 = uilabel(app.InstruccionesTab);
            app.Label_5.Text = '6. Para parar de recolectar data, se debe de apagar el streaming. ';
            app.Label_6 = uilabel(app.InstruccionesTab);
            app.Label_6.Text = '4. Se debe de seleccionar que clase se grabará. ';
            app.Label_7 = uilabel(app.InstruccionesTab);
            app.Label_7.Text = '7. Guardar la tabla, se recomienda colocar el nombre que identifique la clase y datos sobre el ambiente. ';
            app.Button = uibutton(app.InstruccionesTab, 'push');
            app.PaneldecontrolTab = uitab(app.TabGroup);
            app.PaneldecontrolTab.Title = 'Panel de control ';
            app.CaracteristicasSeleccionadasPanel = uipanel(app.PaneldecontrolTab);
            app.CaracteristicasSeleccionadasPanel.Title = 'Caracteristicas Seleccionadas ';
            app.ArithmeticMeanCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.ArithmeticMeanCheckBox.Text = 'Arithmetic Mean';
            app.HjorthActivityCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.HjorthActivityCheckBox.Text = 'Hjorth Activity';
            app.NormalizedFirstDifferenceCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.NormalizedFirstDifferenceCheckBox.Text = 'Normalized First Difference';
            app.HjorthComplexityCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.HjorthComplexityCheckBox.Text = 'Hjorth Complexity';
            app.KurtosisCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.KurtosisCheckBox.Text = 'Kurtosis';
            app.VarianzaCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.VarianzaCheckBox.Text = 'Varianza';
            app.MeanEnergyCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.MeanEnergyCheckBox.Text = 'Mean Energy';
            app.CrearTablaButton = uibutton(app.CaracteristicasSeleccionadasPanel, 'push');
            app.CrearTablaButton.ButtonPushedFcn = createCallbackFcn(app, @CrearTablaButtonPushed, true);
            app.CrearTablaButton.Text = 'Crear Tabla';
            app.ConectaraElectrocapSwitchLabel = uilabel(app.CaracteristicasSeleccionadasPanel);
            app.ConectaraElectrocapSwitchLabel.Text = 'Conectar a Electrocap';
            app.ConectaraElectrocapSwitch = uiswitch(app.CaracteristicasSeleccionadasPanel, 'slider');
            app.ConectaraElectrocapSwitch.ValueChangedFcn = createCallbackFcn(app, @ConectaraElectrocapSwitchValueChanged, true);
            app.SkewnessCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.SkewnessCheckBox.Text = 'Skewness';
            app.ShannonEntropyCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.ShannonEntropyCheckBox.Text = 'Shannon Entropy';
            app.HjorthMobilityCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.HjorthMobilityCheckBox.Text = 'Hjorth Mobility';
            app.SeleccionartodoCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.SeleccionartodoCheckBox.ValueChangedFcn = createCallbackFcn(app, @SeleccionartodoCheckBoxValueChanged, true);
            app.SeleccionartodoCheckBox.Text = 'Seleccionar todo';
            app.GrabacinPanel = uipanel(app.PaneldecontrolTab);
            app.GrabacinPanel.Title = 'Grabación ';
            app.Lamp = uilamp(app.GrabacinPanel);
            app.Lamp.Enable = 'off';
            app.GuardarArchivoButton = uibutton(app.GrabacinPanel, 'push');
            app.GuardarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarArchivoButtonPushed, true);
            app.GuardarArchivoButton.Text = 'Guardar Archivo';
            app.TiempodemuestraatomarEditFieldLabel = uilabel(app.GrabacinPanel);
            app.TiempodemuestraatomarEditFieldLabel.Text = 'Tiempo de muestra a tomar';
            app.TiempodemuestraatomarEditField = uieditfield(app.GrabacinPanel, 'numeric');
            app.TiempodemuestraatomarEditField.Limits = [0 25];
            app.TiempodemuestraatomarEditField.RoundFractionalValues = 'on';
            app.TiempodemuestraatomarEditField.Value = 5;
            app.NombreDelArchivoEditFieldLabel = uilabel(app.GrabacinPanel);
            app.NombreDelArchivoEditFieldLabel.Text = 'Nombre Del Archivo';
            app.NombreDelArchivoEditField = uieditfield(app.GrabacinPanel, 'text');
            app.NombreDelArchivoEditField.Value = 'Sesion_n_Clase';
            app.ClaseagrabarDropDownLabel = uilabel(app.GrabacinPanel);
            app.ClaseagrabarDropDownLabel.Text = 'Clase a grabar';
            app.ClaseagrabarDropDown = uidropdown(app.GrabacinPanel);
            app.ClaseagrabarDropDown.Items = {'Wrist-Up', 'Wrist-Down', ''};
            app.ClaseagrabarDropDown.ValueChangedFcn = createCallbackFcn(app, @ClaseagrabarDropDownValueChanged, true);
            app.ClaseagrabarDropDown.Value = 'Wrist-Up';
            app.Label_8 = uilabel(app.GrabacinPanel);
            app.Label_8.Text = '0';
            app.TiempoLabel = uilabel(app.GrabacinPanel);
            app.TiempoLabel.Text = 'Tiempo ';
            app.Image = uiimage(app.GrabacinPanel);
            app.Image.ImageSource = 'WristUp.jpg';
            app.EmpezaraGrabarButton = uibutton(app.GrabacinPanel, 'push');
            app.EmpezaraGrabarButton.ButtonPushedFcn = createCallbackFcn(app, @EmpezaraGrabarButtonPushed, true);
            app.EmpezaraGrabarButton.Text = 'Empezar a Grabar';
            app.NmerodemuestrastomadasLabel = uilabel(app.GrabacinPanel);
            app.NmerodemuestrastomadasLabel.Text = 'Número de muestras tomadas:';
            app.Label_9 = uilabel(app.GrabacinPanel);
            app.Label_9.Text = '0';
            app.Button_2 = uibutton(app.GrabacinPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'BotonPausa.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GrabacinPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'play.png';
            app.Button_3.Text = '';
            app.LarecoleccinestenPausaLabel = uilabel(app.UIFigure);
            app.LarecoleccinestenPausaLabel.Visible = 'off';
            app.LarecoleccinestenPausaLabel.Text = 'La recolección está en Pausa';
            app.figFpz = uiaxes(app.UIFigure);
            title(app.figFpz, 'Muestra Tomada')
            xlabel(app.figFpz, 't (s)')
            ylabel(app.figFpz, 'V')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RecoleccionDeDatosEEG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end