classdef RecoleccionDeDatosEMG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        RecoleccindeDatosEMGUVGLabel    matlab.ui.control.Label
        DesconectadoLampLabel           matlab.ui.control.Label
        DesconectadoLamp                matlab.ui.control.Lamp
        RegresarButton                  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        InstruccionesTab                matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        InstruccionesparaelusodelaInterfazLabel  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Button_3                        matlab.ui.control.Button
        PaneldecontrolTab               matlab.ui.container.Tab
        CaracteristicasSeleccionadasPanel  matlab.ui.container.Panel
        ZeroCrossingCheckBox            matlab.ui.control.CheckBox
        MAVCheckBox                     matlab.ui.control.CheckBox
        KurtosisCheckBox                matlab.ui.control.CheckBox
        PromediodeEnergaCheckBox        matlab.ui.control.CheckBox
        VarianzaCheckBox                matlab.ui.control.CheckBox
        AmplituddeWilsonCheckBox        matlab.ui.control.CheckBox
        MediaCuadraticaCheckBox         matlab.ui.control.CheckBox
        CrearTablaButton                matlab.ui.control.Button
        ConectarBitalinoSwitchLabel     matlab.ui.control.Label
        ConectarBitalinoSwitch          matlab.ui.control.Switch
        EmpezarStreamingSwitchLabel     matlab.ui.control.Label
        EmpezarStreamingSwitch          matlab.ui.control.Switch
        GrabacinPanel                   matlab.ui.container.Panel
        Lamp                            matlab.ui.control.Lamp
        GuardarArchivoButton            matlab.ui.control.Button
        TiempodemuestraatomarEditFieldLabel  matlab.ui.control.Label
        TiempodemuestraatomarEditField  matlab.ui.control.NumericEditField
        NombreDelArchivoEditFieldLabel  matlab.ui.control.Label
        NombreDelArchivoEditField       matlab.ui.control.EditField
        ClaseagrabarDropDownLabel       matlab.ui.control.Label
        ClaseagrabarDropDown            matlab.ui.control.DropDown
        Label_8                         matlab.ui.control.Label
        TiempoLabel                     matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        EmpezaraGrabarButton            matlab.ui.control.Button
        NmerodemuestrastomadasLabel     matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        LarecoleccinestenPausaLabel     matlab.ui.control.Label
        figFpz                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CrearTablaButton
        function CrearTablaButtonPushed(app, event)
            
            global T
            global n
            app.Lamp.Enable = 'off';
            n= 0;
            F_pb = filtro_pasa_banda(1000,20,450);    %Diseñar filtro pasa banda
            F_notch = filtro_rechaza_banda(1000);     %Diseñar filtro rechaza banda
            
            T = table()
            if app.ZeroCrossingCheckBox.Value == 1
                T.ZC = zeros(0)
            end
            if app.MAVCheckBox.Value == 1
                T.MAV = zeros(0)
            end
            if app.KurtosisCheckBox.Value == 1
                T.Kurtosis = zeros(0)
            end
            if app.PromediodeEnergaCheckBox.Value == 1
                T.AverageE = zeros(0)
            end
            
            if app.VarianzaCheckBox.Value == 1
                T.Varianza = zeros(0)
            end
            if app.AmplituddeWilsonCheckBox.Value == 1
                T.AmplitudWilson = zeros(0)
            end
            if app.MediaCuadraticaCheckBox.Value == 1
                T.MediaCuadratica = zeros(0)
            end
        end

        % Callback function
        function TomarMuestraButtonPushed(app, event)


        end

        % Button pushed function: GuardarArchivoButton
        function GuardarArchivoButtonPushed(app, event)
            global T
            global NombreA
            save('PruebaT.mat', 'T')
            NombreA = app.NombreDelArchivoEditField.Value
            writetable(T,string(NombreA)+"."+'xls')
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
            global b
            %b.stopBackground
            clear all
            Interfaz_Inicio
            delete(app)
        end

        % Value changed function: EmpezarStreamingSwitch
        function EmpezarStreamingSwitchValueChanged(app, event)
            value1 = app.EmpezarStreamingSwitch.Value;
            global value1
            
            global flag
            global b
            global F_pb
            global F_notch
            global Epocas
            global Tiempo_DMuestra
            global data_n
            global h1
            global c
            
            
            if strcmp( value1,'On')
                
                flag =1;


                F_pb = filtro_pasa_banda(1000,20,450);    %Diseñar filtro pasa banda
                F_notch = filtro_rechaza_banda(1000);     %Diseñar filtro rechaza banda


           else 
                flag = 0;
                b.stopBackground
            end


        end

        % Value changed function: ConectarBitalinoSwitch
        function ConectarBitalinoSwitchValueChanged(app, event)
            value = app.ConectarBitalinoSwitch.Value;
            global b
            app.Lamp.Enable = 'off';
            
            if strcmp( value,'On')
                
                
                b = Bitalino;
                app.DesconectadoLamp.Enable = 'on';
                app.DesconectadoLampLabel.Text= 'Conectado';
                
               
            else
                b.stopBackground
                flag = 0;
                delete(b)
                app.DesconectadoLamp.Enable = 'off';
                app.DesconectadoLampLabel.Text= 'Desconectado';
            end
        end

        % Button pushed function: EmpezaraGrabarButton
        function EmpezaraGrabarButtonPushed(app, event)
            %Extracción características
            global Data
            global data_n
            global Tiempo_DMuestra
            global h1
            global time
            global value
            global b
            global flag
            global F_pb
            global F_notch
            global NMuestras
            global Pausa
            Pausa = 0; 
            time = 0;
            th  = 0.3;
            ga = 0.4;
            b_act = 0;
            NMuestras=0;
            n=0;
            
            Tiempo_DMuestra = app.TiempodemuestraatomarEditField.Value;
            volt = 0;
            
            % Pause to acquire data for 20 seconds
            %pause(10);
            % Read the data from the device
            
            
            b.startBackground


            while flag ==1
                
                
                while (time < Tiempo_DMuestra)      %la variable m determina el tiempoque nosotros asignaramos para llenar el buffer
                    pause(0.98)   % se le da una pausa de 1 segundo por cada vez que pase, para que se logre llenar el buffer en el tiempo que se requiere
                    time = time + 1; %variable que aumenta cada 1 segundo.
                    if time == 3
                        app.Lamp.Enable = 'on';
                        
                    end
                    app.Label_8.Text = string(time);
                end
                
                app.LarecoleccinestenPausaLabel.Visible = 'off';
                app.Lamp.Enable = 'off';
%                 while Pausa == 1
%                     app.LarecoleccinestenPausaLabel.Visible = 'on';
%                     if Pausa ==0
%                         
%                         break
%                     end
%                 end
                
                app.Label_8.Text = string(0);
                data = b.read;% tomamos los ultimos valores en el buffer.
                %n=n+1%contador para ingresar los datos a la tabla.
                time = 0; %se reinicia el contador para la siguiente vez que se llene el buffer
                EMG_raw = data(:,6); %se toma solo la columna de la senal EMG
                EMG = EMG_raw*(3.3/1024);%se convierte la senal a la escala de 3.3 V
                data_f = filter(F_pb, EMG');                          %Aplicar filtro pasa bandas
                data_n = filter(F_notch, data_f);                       %Aplicar filtro notch
                
                if strcmp( value,'Off')
                    b.stopBackground
                    app.Label_9.Text = string(0);
                    break
                end


                ylim(app.figFpz, [min(data_n) max(data_n)]);
                Epocas = size(data_n,2) ; %se toma el tamano del buffer obtenido
                L = Epocas; %longitud del vector epocas
                
                t=linspace(0, Epocas/1000, L);
                h1 = plot(app.figFpz, t, data_n, 'Color','r') %se plotea esa muestra


                global T
                if Pausa == 0
                NMuestras = NMuestras +1;
                app.Label_9.Text = string(NMuestras);
                n = n+1
                if app.ZeroCrossingCheckBox.Value == 1
                    T.ZC(n) = jZeroCrossing(data_n,0.34);
                    
                end
                if app.MAVCheckBox.Value == 1
                    T.MAV(n) = jMeanAbsoluteDeviation(data_n);
                    
                end
                if app.KurtosisCheckBox.Value == 1
                    T.Kurtosis(n) = jKurtosis(data_n);
                    
                end
                if app.PromediodeEnergaCheckBox.Value == 1
                    T.AverageE(n) = jAverageEnergy(data_n);
                    
                end
                
                if app.VarianzaCheckBox.Value == 1
                    T.Varianza(n) = jVariance(data_n);
                    
                end
                if app.AmplituddeWilsonCheckBox.Value == 1
                    T.AmplitudWilson(n) = jWillisonAmplitude(data_n,0.01);
                    
                end
                if app.MediaCuadraticaCheckBox.Value == 1
                    T.MediaCuadratica(n) = jRootMeanSquare(data_n);
                    
                end
                app.LarecoleccinestenPausaLabel.Visible = 'off';
                end
                if Pausa == 1
                    app.LarecoleccinestenPausaLabel.Visible = 'on';
                end
                
            end
        end

        % Value changed function: ClaseagrabarDropDown
        function ClaseagrabarDropDownValueChanged(app, event)
            value = app.ClaseagrabarDropDown.Value;
            switch value
                case 'Wrist-Up'
                    app.Image.ImageSource = imread("WristUp.jpg");
                case 'Wrist-Down'
                    app.Image.ImageSource = imread("manita.jfif");    
                
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global Pausa
            Pausa = 1;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global Pausa
            Pausa =0;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.LarecoleccinestenPausaLabel.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.RecoleccindeDatosEMGUVGLabel = uilabel(app.Panel);
            app.RecoleccindeDatosEMGUVGLabel.Text = 'Recolección de Datos  EMG - UVG';
            app.DesconectadoLampLabel = uilabel(app.Panel);
            app.DesconectadoLampLabel.Enable = 'off';
            app.DesconectadoLampLabel.Text = 'Desconectado';
            app.DesconectadoLamp = uilamp(app.Panel);
            app.DesconectadoLamp.Enable = 'off';
            app.RegresarButton = uibutton(app.UIFigure, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Icon = 'back.png';
            app.RegresarButton.Text = 'Regresar';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InstruccionesTab = uitab(app.TabGroup);
            app.InstruccionesTab.Title = 'Instrucciones';
            app.Label = uilabel(app.InstruccionesTab);
            app.Label.Text = '1. Se tienen que seleccionar las caracteristicas que se quieren incluir en los vectores de caracteristicas. ';
            app.Label_2 = uilabel(app.InstruccionesTab);
            app.Label_2.Text = '2. Al tener las características seleccionadas se debe crear la tabla donde se guardaran estos vectores. ';
            app.InstruccionesparaelusodelaInterfazLabel = uilabel(app.InstruccionesTab);
            app.InstruccionesparaelusodelaInterfazLabel.Text = 'Instrucciones para el uso de la Interfaz';
            app.Label_3 = uilabel(app.InstruccionesTab);
            app.Label_3.Text = '5. Se debe de conectar al Bitalino y luego empezar a recolectar datos empezando el streaming.  ';
            app.Label_4 = uilabel(app.InstruccionesTab);
            app.Label_4.Text = '3. Se debe de seleccionar la ventana de tiempo de las muestras que se tomarán.';
            app.Label_5 = uilabel(app.InstruccionesTab);
            app.Label_5.Text = '6. Para parar de recolectar data, se debe de apagar el streaming. ';
            app.Label_6 = uilabel(app.InstruccionesTab);
            app.Label_6.Text = '4. Se debe de seleccionar que clase se grabará. ';
            app.Label_7 = uilabel(app.InstruccionesTab);
            app.Label_7.Text = '7. Guardar la tabla, se recomienda colocar el nombre que identifique la clase y datos sobre el ambiente. ';
            app.Button_3 = uibutton(app.InstruccionesTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.PaneldecontrolTab = uitab(app.TabGroup);
            app.PaneldecontrolTab.Title = 'Panel de control ';
            app.CaracteristicasSeleccionadasPanel = uipanel(app.PaneldecontrolTab);
            app.CaracteristicasSeleccionadasPanel.Title = 'Caracteristicas Seleccionadas ';
            app.ZeroCrossingCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.ZeroCrossingCheckBox.Text = 'Zero Crossing';
            app.MAVCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.MAVCheckBox.Text = 'MAV';
            app.KurtosisCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.KurtosisCheckBox.Text = 'Kurtosis';
            app.PromediodeEnergaCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.PromediodeEnergaCheckBox.Text = 'Promedio de Energía';
            app.VarianzaCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.VarianzaCheckBox.Text = 'Varianza';
            app.AmplituddeWilsonCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.AmplituddeWilsonCheckBox.Text = 'Amplitud de Wilson';
            app.MediaCuadraticaCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.MediaCuadraticaCheckBox.Text = 'Media Cuadratica ';
            app.CrearTablaButton = uibutton(app.CaracteristicasSeleccionadasPanel, 'push');
            app.CrearTablaButton.ButtonPushedFcn = createCallbackFcn(app, @CrearTablaButtonPushed, true);
            app.CrearTablaButton.Text = 'Crear Tabla';
            app.ConectarBitalinoSwitchLabel = uilabel(app.CaracteristicasSeleccionadasPanel);
            app.ConectarBitalinoSwitchLabel.Text = 'Conectar Bitalino';
            app.ConectarBitalinoSwitch = uiswitch(app.CaracteristicasSeleccionadasPanel, 'slider');
            app.ConectarBitalinoSwitch.ValueChangedFcn = createCallbackFcn(app, @ConectarBitalinoSwitchValueChanged, true);
            app.EmpezarStreamingSwitchLabel = uilabel(app.CaracteristicasSeleccionadasPanel);
            app.EmpezarStreamingSwitchLabel.Text = 'Empezar Streaming';
            app.EmpezarStreamingSwitch = uiswitch(app.CaracteristicasSeleccionadasPanel, 'slider');
            app.EmpezarStreamingSwitch.ValueChangedFcn = createCallbackFcn(app, @EmpezarStreamingSwitchValueChanged, true);
            app.GrabacinPanel = uipanel(app.PaneldecontrolTab);
            app.GrabacinPanel.Title = 'Grabación ';
            app.Lamp = uilamp(app.GrabacinPanel);
            app.Lamp.Enable = 'off';
            app.GuardarArchivoButton = uibutton(app.GrabacinPanel, 'push');
            app.GuardarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarArchivoButtonPushed, true);
            app.GuardarArchivoButton.Text = 'Guardar Archivo';
            app.TiempodemuestraatomarEditFieldLabel = uilabel(app.GrabacinPanel);
            app.TiempodemuestraatomarEditFieldLabel.Text = 'Tiempo de muestra a tomar';
            app.TiempodemuestraatomarEditField = uieditfield(app.GrabacinPanel, 'numeric');
            app.TiempodemuestraatomarEditField.Limits = [0 25];
            app.TiempodemuestraatomarEditField.RoundFractionalValues = 'on';
            app.TiempodemuestraatomarEditField.Value = 1;
            app.NombreDelArchivoEditFieldLabel = uilabel(app.GrabacinPanel);
            app.NombreDelArchivoEditFieldLabel.Text = 'Nombre Del Archivo';
            app.NombreDelArchivoEditField = uieditfield(app.GrabacinPanel, 'text');
            app.NombreDelArchivoEditField.Value = 'NuevaTabla';
            app.ClaseagrabarDropDownLabel = uilabel(app.GrabacinPanel);
            app.ClaseagrabarDropDownLabel.Text = 'Clase a grabar';
            app.ClaseagrabarDropDown = uidropdown(app.GrabacinPanel);
            app.ClaseagrabarDropDown.Items = {'Wrist-Up', 'Wrist-Down', ''};
            app.ClaseagrabarDropDown.ValueChangedFcn = createCallbackFcn(app, @ClaseagrabarDropDownValueChanged, true);
            app.ClaseagrabarDropDown.Value = 'Wrist-Up';
            app.Label_8 = uilabel(app.GrabacinPanel);
            app.Label_8.Text = '0';
            app.TiempoLabel = uilabel(app.GrabacinPanel);
            app.TiempoLabel.Text = 'Tiempo ';
            app.Image = uiimage(app.GrabacinPanel);
            app.Image.ImageSource = 'WristUp.jpg';
            app.EmpezaraGrabarButton = uibutton(app.GrabacinPanel, 'push');
            app.EmpezaraGrabarButton.ButtonPushedFcn = createCallbackFcn(app, @EmpezaraGrabarButtonPushed, true);
            app.EmpezaraGrabarButton.Text = 'Empezar a Grabar';
            app.NmerodemuestrastomadasLabel = uilabel(app.GrabacinPanel);
            app.NmerodemuestrastomadasLabel.Text = 'Número de muestras tomadas:';
            app.Label_9 = uilabel(app.GrabacinPanel);
            app.Label_9.Text = '0';
            app.Button = uibutton(app.GrabacinPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'BotonPausa.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GrabacinPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'play.png';
            app.Button_2.Text = '';
            app.LarecoleccinestenPausaLabel = uilabel(app.UIFigure);
            app.LarecoleccinestenPausaLabel.Visible = 'off';
            app.LarecoleccinestenPausaLabel.Text = 'La recolección está en Pausa';
            app.figFpz = uiaxes(app.UIFigure);
            title(app.figFpz, 'Muestra Tomada')
            xlabel(app.figFpz, 't (s)')
            ylabel(app.figFpz, 'V')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RecoleccionDeDatosEMG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end