classdef PruebasBitalino < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        EmpezarSwitchLabel  matlab.ui.control.Label
        EmpezarSwitch       matlab.ui.control.Switch
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EmpezarSwitch
        function EmpezarSwitchValueChanged(app, event)
            value = app.EmpezarSwitch.Value;
            
              largoBuffer = 1;
            %seleccion = app.TiemposSlider.Value;
            Epocas = 10*100;%3000
            L = largoBuffer*Epocas;
            contEpocs = 1;
            t=linspace(0, Epocas/100, L);
             flag=0;
            
            i=0;
            
             if strcmp( value,'On')        
                flag =1;               
                h1 = plot(app.UIAxes, t, zeros(1,L))
                b = Bitalino;
                
                while flag==1
                        b.startBackground;
                        value = app.EmpezarSwitch.Value;
                        if strcmp( value,'Off')
                            break
                        end
                        
                        i = i+1;
                        n = mod(i,Epocas);
                        
                        if n == 0
                            n = Epocas;
                        end
                        data = readCurrentValues(b, 1000)*5/1024;
                        h1.YData = data(i,2);                          %Actualizar gráfica
                        drawnow limitrate

                        if n == Epocas
                                           
                            contEpocs = contEpocs+1;
                            b.stopBackground;
                            
                        end
                   
                end
                   
             else
                delete(b)
                flag = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EmpezarSwitchLabel = uilabel(app.UIFigure);
            app.EmpezarSwitchLabel.Text = 'Empezar';
            app.EmpezarSwitch = uiswitch(app.UIFigure, 'slider');
            app.EmpezarSwitch.ValueChangedFcn = createCallbackFcn(app, @EmpezarSwitchValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafica en tiempo real')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PruebasBitalino

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end